classdef Adsorption_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AIMBreakLabUIFigure             matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        OutputOptionsPanel              matlab.ui.container.Panel
        ExportPlotButton                matlab.ui.control.Button
        SelectVariabletoPlotDropDown    matlab.ui.control.DropDown
        SelectVariabletoPlotDropDownLabel  matlab.ui.control.Label
        ChoosePlotTypeDropDown          matlab.ui.control.DropDown
        ChoosePlotTypeDropDownLabel     matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        ExportDataButton                matlab.ui.control.Button
        GeneratePlotButton              matlab.ui.control.Button
        ChooseTimeValuessSlider         matlab.ui.control.Slider
        ChooseTimeValuessSliderLabel    matlab.ui.control.Label
        InputParametersPanel_2          matlab.ui.container.Panel
        MoreInputParametersButton       matlab.ui.control.Button
        NoofNodesDropDown               matlab.ui.control.DropDown
        NoofNodesDropDownLabel          matlab.ui.control.Label
        TimesEditField                  matlab.ui.control.NumericEditField
        TimeofSorptionReactionstepsLabel  matlab.ui.control.Label
        LengthofColumnmathrmmEditField  matlab.ui.control.NumericEditField
        LengthofColumnmathrmmEditFieldLabel  matlab.ui.control.Label
        FeedflowEditField               matlab.ui.control.NumericEditField
        FeedSuperficialvelocitymsLabel  matlab.ui.control.Label
        FeedTemperaturemathrmKEditField  matlab.ui.control.NumericEditField
        FeedTemperaturemathrmKEditFieldLabel  matlab.ui.control.Label
        ColumnPressuremathrmPaEditField  matlab.ui.control.NumericEditField
        ColumnPressurekPaLabel          matlab.ui.control.Label
        SimulateButton                  matlab.ui.control.Button
        MessagePanel                    matlab.ui.container.Panel
        OutputText                      matlab.ui.control.TextArea
        PlotsPanel                      matlab.ui.container.Panel
        UIAxes1                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         main_app                   % Main app handle
         Input_Params_DialogueApp   % App handle for saving and viewing Parameters 
         Data_Save_DialogApp        % App handle for saving data
         t                          % Time of Simulation
         L                          % Length of column
    end
    
    properties (Access = public)
        solution                % Solution obtained from Simulation
        N                       % Number of Nodes 
        parameters;             % Structure for Storing All the Parameters used
        progress_bar            % handle of progress_bar
        feed_flow_type          % Feed flow type flag
    end
    
    methods (Access = private)
        function [] = simulation_start_func(app)
            app.SimulateButton.Enable = "off";
            cla(app.UIAxes1)
            app.update_textbox_func(app.OutputText, sprintf('Simulation started...'));
        end

        function dialogue = progress(app)
            dialogue = uiprogressdlg(app.AIMBreakLabUIFigure, "Title", "Please Wait",...
                  "Message","Gathering Input Data",...
                  "Cancelable","on","CancelText","Stop", "Indeterminate","on");
                drawnow   
        end

        %% Function to adjust the size of figure
        function [] = adjust_size(app)
            app.AIMBreakLabUIFigure.Units = "normalized";
            app.AIMBreakLabUIFigure.Position(1) = 0.1;
            app.AIMBreakLabUIFigure.Position(2) = 0.05;
            app.AIMBreakLabUIFigure.Units = "pixels";
        end
        %
    end
    methods (Access = public)
        %% Function for populating main params in their respective fields
        function [] = setting_main_params(app, parameter_data)
            
            Col_Bed_params = parameter_data.ColBed;
            Feed_Gas_params = parameter_data.FeedGas;

            app.NoofNodesDropDown.Value = num2str(parameter_data.NumPar(1));
            app.TimesEditField.Value = parameter_data.NumPar(2);
            app.ColumnPressuremathrmPaEditField.Value = Col_Bed_params(1);
            app.FeedTemperaturemathrmKEditField.Value = Feed_Gas_params(1);
            app.LengthofColumnmathrmmEditField.Value = Col_Bed_params(2);
            app.FeedflowEditField.Value = Feed_Gas_params(5);
        end
        %
        %% Function for updating text box and data logging
        function [] = update_textbox_func(~, app_comp, message)
            if strcmpi(app_comp.Value, '')
                updated_message = message;
            else
                updated_message = [app_comp.Value(:)', {''}, {message}];
            end
            app_comp.Value = updated_message;
            scroll(app_comp, 'bottom');
        end
        %
        %% Function to update the uifigure for different devices
        function [] = adjust_uifig(app)
            % Get the device resolution
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            
            default_position = app.AIMBreakLabUIFigure.Position;
            
            if default_position(3) > screenWidth || default_position(4) > screenHeight
                left = screenWidth*0.1;
                bottom = screenHeight*0.1;
                width = screenWidth*0.85;
                height = screenHeight*0.85;
                drawnow;
                app.AIMBreakLabUIFigure.Position = [left bottom width height];
            end            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.adjust_size();
            app.adjust_uifig();
            % Loading Default Parameters Set
            % app.main_app = main_app_handle;
            app.parameters = Default_Input_Parameters();
            app.setting_main_params(app.parameters)
            app.feed_flow_type = 0;     % Default m/s
            app.SelectVariabletoPlotDropDown.Value = 'Mole Fractions';

            % Printing welcome message
            intro_string = get_intro_string("BreakLab");
            app.update_textbox_func(app.OutputText, intro_string);
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            simulation_start_func(app);
            drawnow;
            app.progress_bar = progress(app);
            app.L = app.parameters.ColBed(2);
            app.N = app.parameters.NumPar(1);
            app.t = app.parameters.NumPar(2);
            
            app.progress_bar.Message = "Reading Isotherm Parameters...";
            pause(1);
            
            % Settting warning due to stiffness failure to error for
            % catching exceptions
            warn = warning('error', 'MATLAB:ode15s:IntegrationTolNotMet');
            warning('error', 'MATLAB:ode15s:IntegrationTolNotMet');

            % Solution Starts
            try 
                app.progress_bar.Message = "Starting Simulation...";
                pause(1);
                app.progress_bar.Message = "Running Simulation...";
                tic;
                app.solution = Adsorption_Breakthrough_case(app.parameters, app.progress_bar);
                timeTaken = toc;
                if ~(isempty(app.solution))

                    app.progress_bar.Message = "Simulation Finished. Compiling Output...";
                    app.update_textbox_func(app.OutputText, sprintf('Simulation finished successfully.\nYou can now generate plots and save data.'));
                    app.SelectVariabletoPlotDropDown.Enable = "on";
                    app.ExportDataButton.Enable = "on";
                    app.ExportPlotButton.Enable = "on";
                    app.ResetButton.Enable = "on";
                    app.ChoosePlotTypeDropDown.Enable = 'on';
                    app.ChooseTimeValuessSlider.Limits = [0 app.t];
                    app.GeneratePlotButton.ButtonPushedFcn(app, event);
                    close(app.progress_bar);
                end
            catch ME1
                timeTaken = toc;
                switch ME1.identifier
                    case 'MATLAB:ode15s:IntegrationTolNotMet'
                        app.progress_bar.Message = "Error occured. Check the Message Panel for detailed information.";
                        pause(2);
                        close(app.progress_bar);
                         app.update_textbox_func(app.OutputText, ...
                                            sprintf(['Simulation failed!\nProblem Too Stiff for me to handle.\n' ...
                                            'Try changing Absolute and Relative tolerances but let us hope you know what you are doing.\n' ...
                                            'Error: %s'], ME1.message'));
                    otherwise
                        app.progress_bar.Message = "Error occured. Check the Message Panel for detailed information.";
                        pause(2);
                        close(app.progress_bar);
                        app.update_textbox_func(app.OutputText, ...
                                            sprintf(['Simulation failed! I tried so hard to solve it!\n' ...
                                            'Refer below to see what is casuing the trouble.\nError: %s'], ME1.message));  
                      
                end
            end
            if ~isempty(app.progress_bar)
                close(app.progress_bar);
            end
            app.SimulateButton.Enable = "on";
            warning(warn);  % Restoring Waning State
            % Printing time on message panel
            app.update_textbox_func(app.OutputText, sprintf('Total elapsed time: %.4f seconds', timeTaken));

        end

        % Button pushed function: GeneratePlotButton
        function GeneratePlotButtonPushed(app, event)
            value = app.SelectVariabletoPlotDropDown.Value;
            plot_type = app.ChoosePlotTypeDropDown.Value;
            time_value = app.ChooseTimeValuessSlider.Value;
            app.UIAxes1.reset
            try
                plots(app.UIAxes1, app.L, app.N, value, app.solution, plot_type, time_value,...
                        app.parameters);
                app.UIAxes1.XGrid = "on";
                app.UIAxes1.YGrid = "on";
                app.UIAxes1.GridAlpha = 0.2;
            catch err
                app.update_textbox_func(app.OutputText, ...
                                            sprintf(['ERROR Occured in plotting!\n' ...
                                            'Error is: %s'],err.message));
            end
        end

        % Button pushed function: ExportPlotButton
        function ExportPlotButtonPushed(app, event)
            filter = {'*.png'; '*.jpeg'; '*.pdf'};
            [file, path, indx] = uiputfile(filter, 'Select Plot for Save As', ...
                'my_plot.png');
            
            % Command for refocusing parent app
            figure(app.AIMBreakLabUIFigure);

            if indx
                filename = fullfile(path, file); 
                if ~isempty(get(app.UIAxes1, 'Children'))
                    try
                        exportgraphics(app.UIAxes1, filename);
                    catch err
                        app.update_textbox_func(app.OutputText, ...
                                            sprintf(['ERROR Occured in saving plot\n' ...
                                            'Error is: %s'],err.message));
                    end
                else
                    app.update_textbox_func(app.OutputText, ...
                                            sprintf('No plot found. First generate plot then save'));
                end
            end
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            app.ExportDataButton.Enable = "off";
            app.Data_Save_DialogApp = data_saving_app(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            YN = uiconfirm(app.AIMBreakLabUIFigure,strcat('This operation will clear all the solution data from the memory.', ...
                {' '},'Do you want to continue?'), 'Clear Data!', 'Icon','warning');
            if strcmpi(YN,'OK')
                app.solution = struct([]);
                cla(app.UIAxes1)
                app.UIAxes1.reset()
                app.OutputText.Value = '';
                app.SelectVariabletoPlotDropDown.Enable = "off";
                app.ExportDataButton.Enable = "off";
                app.GeneratePlotButton.Enable = "off";
                app.ExportPlotButton.Enable = "off";
                app.ResetButton.Enable = "off";
                app.ChoosePlotTypeDropDown.Enable = "off";
            end
        end

        % Value changed function: ChoosePlotTypeDropDown
        function ChoosePlotTypeDropDownValueChanged(app, event)
            value = app.ChoosePlotTypeDropDown.Value;
            if strcmpi(value, 'Across the column')
                app.ChooseTimeValuessSlider.Visible = "on";
                app.ChooseTimeValuessSliderLabel.Visible = "on";           
            else
                app.ChooseTimeValuessSlider.Visible ="off";
                app.ChooseTimeValuessSliderLabel.Visible = "off";
            end
            app.GeneratePlotButton.ButtonPushedFcn(app, event);
        end

        % Value changed function: ChooseTimeValuessSlider
        function ChooseTimeValuessSliderValueChanged(app, event)
            app.GeneratePlotButton.ButtonPushedFcn(app, event);
        end

        % Value changed function: SelectVariabletoPlotDropDown
        function SelectVariabletoPlotDropDownValueChanged(app, event)
            app.GeneratePlotButton.ButtonPushedFcn(app, event);
        end

        % Close request function: AIMBreakLabUIFigure
        function AIMBreakLabUIFigureCloseRequest(app, event)
            delete(app.Data_Save_DialogApp)
            delete(app.Input_Params_DialogueApp)
            YN = uiconfirm(app.AIMBreakLabUIFigure,'Do you want to close the app?', 'Close request');
            if strcmpi(YN,'OK')
                delete(app)
            end
        end

        % Button pushed function: MoreInputParametersButton
        function MoreInputParametersButtonPushed(app, event)
            app.MoreInputParametersButton.Enable = "off";
            % app.Input_Params_DialogueApp = Input_Params_mod(app);
            app.Input_Params_DialogueApp = Input_Params(app);
            
        end

        % Value changed function: LengthofColumnmathrmmEditField
        function LengthofColumnmathrmmEditFieldValueChanged(app, event)
            value = app.LengthofColumnmathrmmEditField.Value;
            app.parameters.ColBed(2) = value;
        end

        % Value changed function: ColumnPressuremathrmPaEditField
        function ColumnPressuremathrmPaEditFieldValueChanged(app, event)
            value = app.ColumnPressuremathrmPaEditField.Value;
            app.parameters.ColBed(1) = value;
        end

        % Value changed function: FeedTemperaturemathrmKEditField
        function FeedTemperaturemathrmKEditFieldValueChanged(app, event)
            value = app.FeedTemperaturemathrmKEditField.Value;
            app.parameters.FeedGas(1) = value;
        end

        % Value changed function: FeedflowEditField
        function FeedflowEditFieldValueChanged(app, event)
            value = app.FeedflowEditField.Value;
            app.parameters.FeedGas(5) = value;
        end

        % Value changed function: TimesEditField
        function TimesEditFieldValueChanged(app, event)
            value = app.TimesEditField.Value;
            app.parameters.NumPar(2) = value;
        end

        % Value changed function: NoofNodesDropDown
        function NoofNodesDropDownValueChanged(app, event)
            value = app.NoofNodesDropDown.Value;
            app.parameters.NumPar(1) = str2double(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AIMBreakLabUIFigure = uifigure('Visible', 'off');
            app.AIMBreakLabUIFigure.Name = 'AIM BreakLab';
            app.AIMBreakLabUIFigure.Icon = fullfile(pathToMLAPP, 'AIM_logo.png');
            app.AIMBreakLabUIFigure.CloseRequestFcn = createCallbackFcn(app, @AIMBreakLabUIFigureCloseRequest, true);
            app.Panel = uipanel(app.AIMBreakLabUIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.PlotsPanel = uipanel(app.GridLayout);
            app.PlotsPanel.Title = 'Plots';
            app.UIAxes1 = uiaxes(app.PlotsPanel);
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.MessagePanel = uipanel(app.GridLayout);
            app.MessagePanel.Title = 'Message Panel';
            app.OutputText = uitextarea(app.MessagePanel);
            app.InputParametersPanel_2 = uipanel(app.GridLayout);
            app.InputParametersPanel_2.Title = 'Input Parameters';
            app.SimulateButton = uibutton(app.InputParametersPanel_2, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.ColumnPressurekPaLabel = uilabel(app.InputParametersPanel_2);
            app.ColumnPressurekPaLabel.Interpreter = 'latex';
            app.ColumnPressurekPaLabel.Text = 'Column Pressure $\mathrm{(Pa)}$';
            app.ColumnPressuremathrmPaEditField = uieditfield(app.InputParametersPanel_2, 'numeric');
            app.ColumnPressuremathrmPaEditField.Limits = [0.001 Inf];
            app.ColumnPressuremathrmPaEditField.ValueChangedFcn = createCallbackFcn(app, @ColumnPressuremathrmPaEditFieldValueChanged, true);
            app.ColumnPressuremathrmPaEditField.Value = 100;
            app.FeedTemperaturemathrmKEditFieldLabel = uilabel(app.InputParametersPanel_2);
            app.FeedTemperaturemathrmKEditFieldLabel.Interpreter = 'latex';
            app.FeedTemperaturemathrmKEditFieldLabel.Text = 'Feed Temperature $\mathrm{(K)}$';
            app.FeedTemperaturemathrmKEditField = uieditfield(app.InputParametersPanel_2, 'numeric');
            app.FeedTemperaturemathrmKEditField.Limits = [-273.15 Inf];
            app.FeedTemperaturemathrmKEditField.ValueChangedFcn = createCallbackFcn(app, @FeedTemperaturemathrmKEditFieldValueChanged, true);
            app.FeedTemperaturemathrmKEditField.Value = 300;
            app.FeedSuperficialvelocitymsLabel = uilabel(app.InputParametersPanel_2);
            app.FeedSuperficialvelocitymsLabel.Visible = 'off';
            app.FeedSuperficialvelocitymsLabel.Interpreter = 'latex';
            app.FeedSuperficialvelocitymsLabel.Text = 'Feed Superficial Velocity $\mathrm{(m/s)}$';
            app.FeedflowEditField = uieditfield(app.InputParametersPanel_2, 'numeric');
            app.FeedflowEditField.Limits = [0 Inf];
            app.FeedflowEditField.ValueChangedFcn = createCallbackFcn(app, @FeedflowEditFieldValueChanged, true);
            app.FeedflowEditField.Visible = 'off';
            app.LengthofColumnmathrmmEditFieldLabel = uilabel(app.InputParametersPanel_2);
            app.LengthofColumnmathrmmEditFieldLabel.Interpreter = 'latex';
            app.LengthofColumnmathrmmEditFieldLabel.Text = 'Length of Column $\mathrm{(m)}$';
            app.LengthofColumnmathrmmEditField = uieditfield(app.InputParametersPanel_2, 'numeric');
            app.LengthofColumnmathrmmEditField.Limits = [0 Inf];
            app.LengthofColumnmathrmmEditField.ValueChangedFcn = createCallbackFcn(app, @LengthofColumnmathrmmEditFieldValueChanged, true);
            app.LengthofColumnmathrmmEditField.Value = 0.01;
            app.TimeofSorptionReactionstepsLabel = uilabel(app.InputParametersPanel_2);
            app.TimeofSorptionReactionstepsLabel.Interpreter = 'latex';
            app.TimeofSorptionReactionstepsLabel.Text = 'Time (s)';
            app.TimesEditField = uieditfield(app.InputParametersPanel_2, 'numeric');
            app.TimesEditField.Limits = [1 Inf];
            app.TimesEditField.ValueChangedFcn = createCallbackFcn(app, @TimesEditFieldValueChanged, true);
            app.TimesEditField.Value = 1;
            app.NoofNodesDropDownLabel = uilabel(app.InputParametersPanel_2);
            app.NoofNodesDropDownLabel.Interpreter = 'latex';
            app.NoofNodesDropDownLabel.Text = 'No. of Nodes';
            app.NoofNodesDropDown = uidropdown(app.InputParametersPanel_2);
            app.NoofNodesDropDown.Items = {'20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.NoofNodesDropDown.ValueChangedFcn = createCallbackFcn(app, @NoofNodesDropDownValueChanged, true);
            app.NoofNodesDropDown.Value = '20';
            app.MoreInputParametersButton = uibutton(app.InputParametersPanel_2, 'push');
            app.MoreInputParametersButton.ButtonPushedFcn = createCallbackFcn(app, @MoreInputParametersButtonPushed, true);
            app.MoreInputParametersButton.Text = 'More Input Parameters';
            app.OutputOptionsPanel = uipanel(app.GridLayout);
            app.OutputOptionsPanel.Title = 'Output Options';
            app.ChooseTimeValuessSliderLabel = uilabel(app.OutputOptionsPanel);
            app.ChooseTimeValuessSliderLabel.Visible = 'off';
            app.ChooseTimeValuessSliderLabel.Text = 'Choose Time Values (s)';
            app.ChooseTimeValuessSlider = uislider(app.OutputOptionsPanel);
            app.ChooseTimeValuessSlider.ValueChangedFcn = createCallbackFcn(app, @ChooseTimeValuessSliderValueChanged, true);
            app.ChooseTimeValuessSlider.Visible = 'off';
            app.GeneratePlotButton = uibutton(app.OutputOptionsPanel, 'push');
            app.GeneratePlotButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotButtonPushed, true);
            app.GeneratePlotButton.Visible = 'off';
            app.GeneratePlotButton.Text = 'Generate Plot';
            app.ExportDataButton = uibutton(app.OutputOptionsPanel, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Enable = 'off';
            app.ExportDataButton.Text = 'Export Data';
            app.ResetButton = uibutton(app.OutputOptionsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.ChoosePlotTypeDropDownLabel = uilabel(app.OutputOptionsPanel);
            app.ChoosePlotTypeDropDownLabel.Text = 'Choose Plot Type';
            app.ChoosePlotTypeDropDown = uidropdown(app.OutputOptionsPanel);
            app.ChoosePlotTypeDropDown.Items = {'At the outlet', 'Across the column'};
            app.ChoosePlotTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosePlotTypeDropDownValueChanged, true);
            app.ChoosePlotTypeDropDown.Enable = 'off';
            app.ChoosePlotTypeDropDown.Value = 'At the outlet';
            app.SelectVariabletoPlotDropDownLabel = uilabel(app.OutputOptionsPanel);
            app.SelectVariabletoPlotDropDownLabel.Text = 'Select Variable to Plot';
            app.SelectVariabletoPlotDropDown = uidropdown(app.OutputOptionsPanel);
            app.SelectVariabletoPlotDropDown.Items = {'Pressure', 'Normalized Mole Fractions', 'Temperature', 'Wall Temperature', 'Mole Fractions', 'Molar Loadings'};
            app.SelectVariabletoPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectVariabletoPlotDropDownValueChanged, true);
            app.SelectVariabletoPlotDropDown.Enable = 'off';
            app.SelectVariabletoPlotDropDown.Placeholder = 'Choose the varibale to plot';
            app.SelectVariabletoPlotDropDown.Value = 'Pressure';
            app.ExportPlotButton = uibutton(app.OutputOptionsPanel, 'push');
            app.ExportPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButtonPushed, true);
            app.ExportPlotButton.Enable = 'off';
            app.ExportPlotButton.Text = 'Export Plot';
            % Show the figure after all components are created
            app.AIMBreakLabUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Adsorption_GUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AIMBreakLabUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.AIMBreakLabUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AIMBreakLabUIFigure)
        end
    end
end