classdef Input_Params < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdditionalSettingsUIFigure      matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        RestoreButton                   matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        FeedGasTab                      matlab.ui.container.Tab
        FeedCompositionPanel            matlab.ui.container.Panel
        ComponentTable                  matlab.ui.control.Table
        NoofComponentsEditField         matlab.ui.control.NumericEditField
        NoofComponentsEditFieldLabel    matlab.ui.control.Label
        FeedGasParametersPanel          matlab.ui.container.Panel
        FeedFlowDropDown                matlab.ui.control.DropDown
        FeedflowEditField_2             matlab.ui.control.NumericEditField
        FeedSuperficialvelocitymsEditField_2Label_2  matlab.ui.control.Label
        GasViscositymathrmPasEditField  matlab.ui.control.NumericEditField
        GasViscosityPasLabel            matlab.ui.control.Label
        MolecularDiffusivitymathrmm2sEditField  matlab.ui.control.NumericEditField
        MolecularDiffusivitym2sLabel    matlab.ui.control.Label
        ThermalConductivitymathrmWmKEditField  matlab.ui.control.NumericEditField
        ThermalConductivityWmKLabel     matlab.ui.control.Label
        SpecificHeatCapacitymathrmJmolKEditField  matlab.ui.control.NumericEditField
        SpecificHeatcapacityJmolKLabel  matlab.ui.control.Label
        FeedTemperaturemathrmKEditField  matlab.ui.control.NumericEditField
        FeedTemperatureoCLabel          matlab.ui.control.Label
        AdsorptionIsothermTab           matlab.ui.container.Tab
        IsothermModelPanel_2            matlab.ui.container.Panel
        iso_model_text_2                matlab.ui.control.Label
        MTCTable                        matlab.ui.control.Table
        MassTransferCoefficientsLabel   matlab.ui.control.Label
        IsothermTable                   matlab.ui.control.Table
        Image                           matlab.ui.control.Image
        IsothermParametersLabel_3       matlab.ui.control.Label
        ImportIsothermButton            matlab.ui.control.Button
        CompListBox                     matlab.ui.control.ListBox
        ComponentsLabel                 matlab.ui.control.Label
        MixturePrediction               matlab.ui.control.DropDown
        MixturePredictionLabel          matlab.ui.control.Label
        CalcTypeDropDown                matlab.ui.control.DropDown
        CalculationTypeLabel            matlab.ui.control.Label
        ColumnParametersTab             matlab.ui.container.Tab
        AdsorberColumnPanel             matlab.ui.container.Panel
        OuterDiametermathrmmEditFieldLabel  matlab.ui.control.Label
        WallTemperaturemathrmKEditField  matlab.ui.control.NumericEditField
        WallSpecificHeatCapacitymathrmJkgKEditFieldLabel  matlab.ui.control.Label
        WallDensitymathrmkgm3EditFieldLabel  matlab.ui.control.Label
        WallThermalConductivitymathrmWmKEditFieldLabel  matlab.ui.control.Label
        AmbientTemperaturemathrmKEditField  matlab.ui.control.NumericEditField
        AmbientTemperaturemathrmKEditFieldLabel  matlab.ui.control.Label
        OutsideHeatTransferCoefficientmathrmWm2KEditField  matlab.ui.control.NumericEditField
        OutsideHeatTransferCoefficientmathrmWm2KEditFieldLabel  matlab.ui.control.Label
        InsideHeatTransferCoefficientmathrmWm2KEditField  matlab.ui.control.NumericEditField
        WallHeatTransferCoeeficientWm2KLabel  matlab.ui.control.Label
        WallTemperatureoCLabel          matlab.ui.control.Label
        WallSpecificHeatCapacitymathrmJkgKEditField  matlab.ui.control.NumericEditField
        WallDensitymathrmkgm3EditField  matlab.ui.control.NumericEditField
        WallThermalConductivitymathrmWmKEditField  matlab.ui.control.NumericEditField
        OuterDiametermathrmmEditField   matlab.ui.control.NumericEditField
        InnerDiametermathrmmEditField   matlab.ui.control.NumericEditField
        DiametermLabel                  matlab.ui.control.Label
        LengthmathrmmEditField          matlab.ui.control.NumericEditField
        LengthmLabel                    matlab.ui.control.Label
        PressuremathrmPaEditField       matlab.ui.control.NumericEditField
        PressurekPaLabel                matlab.ui.control.Label
        ColumnInternalsTab              matlab.ui.container.Tab
        SolidSpecificHeatCapacitymathrmJkgKEditField  matlab.ui.control.NumericEditField
        SolidSpecificHeatCapacitymathrmJkgKEditFieldLabel  matlab.ui.control.Label
        BedPorosityEditField            matlab.ui.control.NumericEditField
        BedPorosityLabel                matlab.ui.control.Label
        ParticlePorosityEditField       matlab.ui.control.NumericEditField
        ParticlePorosityLabel           matlab.ui.control.Label
        ParticleDiametermathrmmEditField  matlab.ui.control.NumericEditField
        ParticleDiametermathrmmEditFieldLabel  matlab.ui.control.Label
        BulkDensitymathrmkgm3EditField  matlab.ui.control.NumericEditField
        BulkDensitymathrmkgm3EditFieldLabel  matlab.ui.control.Label
        AdsorbentPropertiesPanel        matlab.ui.container.Panel
        NumericalTab                    matlab.ui.container.Tab
        NumericalSimulationParametersPanel  matlab.ui.container.Panel
        InitMFTable                     matlab.ui.control.Table
        RelativeToleranceEditField      matlab.ui.control.NumericEditField
        RelativeToleranceEditFieldLabel  matlab.ui.control.Label
        AbsoluteToleranceEditField      matlab.ui.control.NumericEditField
        AbsoluteToleranceEditFieldLabel  matlab.ui.control.Label
        SimulationTimesEditField        matlab.ui.control.NumericEditField
        TimeofSorptionReactionstepsLabel  matlab.ui.control.Label
        NoofNodesDropDown               matlab.ui.control.DropDown
        NoofNodesDropDownLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        caller                      % Main App Handle
        IsothermParamsHelpApp       % Isotherm params expressions and unit
        feed_flow_type              % Feed flow type
        molefrac                    % Feed Molefraction vector
        molarmass                   % Molar mass vector
        initmolefrac                % Initial Molefraction vector
        name_iso_model_read         % Name of isotherm models read for isotherm table
        
        % Types and names of isotherm model available
        iso_models_avai = {'SS-Langmuir', 'DS-Langmuir',...
                           'SS-Langmuir-Freundlich', 'DS-Langmuir-Freundlich',...
                           'Quadratic', 'Temkin', 'BET', 'Sips', 'Toth'};
        
        % Cell array containing structures of default params and their names
        % for avialbale isotherm models
        iso_models_info           
        
        % num max params is based on max number of params in available isotherm models model
        % + isotherm model key + dH + Reference temperature
        num_max_params = 9;
        num_max_comp = 6;           % Max number of component

        isotherm_params             % Main matrix containing isotherm parasm of loaded components 
        mixture_mode                % Mixture prediction model (EDSL(0) ot IAST(1))
        T_flag                      % Whether the isothermal(0)/nonisothermal(1) calculation are desired ot 

        MTC
        params_all                  % Parameter set comprising of all parameters used in the simulation
        Modified_parameters         % Final parameter set which is passed to caller app
    end
    
    properties (Access = public)
        num_comp                    % Number of components
        name_comp                   % Name of components
    end
    
    methods (Access = private)
        %% Function to run at app startup or reset
        function [] = startup_func(app)
            % Setting the format of component and MTC tables
            app.ComponentTable.ColumnFormat = {[], 'numeric', 'numeric'};
            app.ComponentTable.ColumnEditable = true;
            app.MTCTable.ColumnFormat = {[], 'numeric'};
            app.MTCTable.ColumnEditable = [false, true];
            app.InitMFTable.ColumnFormat = {[], 'numeric'};
            app.InitMFTable.ColumnEditable = [false, true];
            
            % Setting app properties and populating parameters in app fields
            app.set_params();

            % Initializing miscellneous properties
            app.Modified_parameters = app.params_all;
        end
        %
        %% Function to update Component information table
        function [] = update_component_table(app)
            table_data = cell(app.num_comp, 3);

            table_data(:, 1) = app.name_comp';
            table_data(:, 2) = num2cell(app.molefrac');
            table_data(:, 3) = num2cell(app.molarmass');
            
            app.ComponentTable.Data = table_data; 
        end
        %
        %% Function to update Component List box
        function [] = update_comp_list_box(app)
            app.CompListBox.Items = app.name_comp(2:end);       % Removing Carrier gas
        end
        %% Function to update MTC table
        function [] = update_MTC_table(app)
            table_data = cell(app.num_comp-1, 2);       % Removing Carrier gas

            table_data(:, 1) = app.name_comp(2:end)';   % Removing Carrier gas
            table_data(:, 2) = num2cell(app.MTC');

            app.MTCTable.Data = table_data;
        end
        %
        %% Function to hide/unhide labels for isothermal/nonisothermal calculations
        function [] = update_labels(app)
            if app.T_flag
                app.SpecificHeatCapacitymathrmJmolKEditField.Enable = "on";
                app.ThermalConductivitymathrmWmKEditField.Enable = "on";
                app.SolidSpecificHeatCapacitymathrmJkgKEditField.Enable = "on";
                app.InsideHeatTransferCoefficientmathrmWm2KEditField.Enable = "on";
                % app.WallTemperaturemathrmKEditField.Enable = "on";
                app.WallThermalConductivitymathrmWmKEditField.Enable = "on";
                app.WallDensitymathrmkgm3EditField.Enable = "on";
                app.WallSpecificHeatCapacitymathrmJkgKEditField.Enable = "on";
                app.OuterDiametermathrmmEditField.Enable = "on";
                app.OutsideHeatTransferCoefficientmathrmWm2KEditField.Enable = "on";
                app.AmbientTemperaturemathrmKEditField.Enable = "on";

            else
                app.SpecificHeatCapacitymathrmJmolKEditField.Enable = "off";
                app.ThermalConductivitymathrmWmKEditField.Enable = "off";
                app.SolidSpecificHeatCapacitymathrmJkgKEditField.Enable = "off";
                app.InsideHeatTransferCoefficientmathrmWm2KEditField.Enable = "off";
                % app.WallTemperaturemathrmKEditField.Enable = "off";
                app.WallThermalConductivitymathrmWmKEditField.Enable = "off";
                app.WallDensitymathrmkgm3EditField.Enable = "off";
                app.WallSpecificHeatCapacitymathrmJkgKEditField.Enable = "off";
                app.OuterDiametermathrmmEditField.Enable = "off";
                app.OutsideHeatTransferCoefficientmathrmWm2KEditField.Enable = "off";
                app.AmbientTemperaturemathrmKEditField.Enable = "off";
            end
        end
        %% Function to update Isotherm data table
        function [] = update_isotherm_table(app)
            
            % Column Names in Isotherm table
            init_col_names = {'Isotherm Model'};
            % temp_col_names = {'Parameter 1', 'Parameter 2', 'Parameter 3'};
            temp_col_names = {};

            % Initialize data variable
            table_data = cell(app.num_comp-1, 1+app.num_max_params);
            num_par_all_comp = zeros(1, app.num_comp-1);

            % loop over all the components
            for i=1:app.num_comp-1

                % Populating Isotherm Model for current component
                table_data(i, 1) = app.name_iso_model_read(i);

                % Getting the number of columns in the updated isotherm table
                current_iso_key = app.isotherm_params(1, i);
                current_iso_info = app.iso_models_info{current_iso_key}; 
                current_iso_num_par = current_iso_info.num_params;
                
                num_par_all_comp(i) = current_iso_num_par;
            end
            
            % Parameter Column names
            num_columns_iso_params = max(num_par_all_comp);         % Columns containing isotherm params
            for j = 1:num_columns_iso_params
                temp_col_names(end+1) = {sprintf('Parameter %d', j)};
            end
            
            % For nonisothermal calculation, additional columns for deltaH
            % and Reference Temperature
            if app.T_flag
                temp_col_names = [temp_col_names, {'Delta H', 'T_ref'}];
            end
            
            % Final Column Names
            final_col_names = [init_col_names, temp_col_names];
            table_width = numel(final_col_names);
            params_width = numel(temp_col_names);

            % Populating isotherm params and changing all cells containing zeros to empty cell
            if app.T_flag
                iso_params_array = app.isotherm_params([2:num_columns_iso_params+1, end-1:end],...
                                                        1:app.num_comp-1)';
                % % Conversion of dH from J/mol to kJ/mol just for displaying
                % % purpose
                % iso_params_array(:, end-1) = iso_params_array(:, end-1)./1e03;
            else
                iso_params_array = app.isotherm_params(2:num_columns_iso_params+1, 1:app.num_comp-1)';
            end

            iso_params_data_cell = num2cell(iso_params_array);
            % iso_params_data_cell(iso_params_array == 0) = {[]};
            iso_params_data_cell(isnan(iso_params_array)) = {[]};
            table_data(:, 2:table_width) = iso_params_data_cell;
            
            % Ensuring proper format of columns in Isotherm Table
            % app.IsothermTable.ColumnFormat = [{[]}, repmat({'numeric'}, 1, params_width)];
            app.IsothermTable.ColumnFormat = [{app.iso_models_avai}, repmat({'numeric'}, 1, params_width)];
            app.IsothermTable.ColumnEditable = true(1, 1+params_width);
            app.IsothermTable.ColumnName = final_col_names;
            app.IsothermTable.RowName = app.name_comp(2:end);
            
            % Displaying data to Isotherm table
            app.IsothermTable.Data = table_data(:, 1:table_width);   
        end
        %
        %% Function to update Init Molefraction table
        function [] = update_init_MF_table(app)
            table_data = cell(app.num_comp, 2);       

            table_data(:, 1) = app.name_comp(1:end)';
            table_data(:, 2) = num2cell(app.initmolefrac');

            app.InitMFTable.Data = table_data;
        end
        %% Function to set and display parameters in their respective fields
        function [] = set_params(app)
            % This function sets the respective data fields according to
            % existing data.
            
            % Unpacking
            Feed_Gas_params = app.params_all.FeedGas;
            Column_Bed_params = app.params_all.ColBed;
            Numerical_params = app.params_all.NumPar;
            
            app.num_comp = app.params_all.CompNum;
            app.name_comp = app.params_all.CompNames;
            app.molefrac = app.params_all.FeedMF;
            app.molarmass = app.params_all.FeedMM;
            app.initmolefrac = app.params_all.InitMF;
            app.isotherm_params = app.params_all.IsothermParams;
            app.MTC = app.params_all.MTC;
            app.mixture_mode = app.params_all.MixtureMode;
            app.feed_flow_type = app.params_all.FeedFlowType;

            % Initializing the dictionary to store struct containing default number of
            % parameters and name of params corresponding to isotherm model key
            keys = zeros(1, numel(app.iso_models_avai));
            values = repmat({struct}, 1, numel(app.iso_models_avai));           
            for k=1:numel(app.iso_models_avai)
                % Assigning isotherm index keys
                keys(k) = k;
                % Calling isotherm params parsing function for structure
                values(k) = {iso_params_parsing(char(app.iso_models_avai(k)), [])};
            end
            app.iso_models_info = dictionary(keys, values);

            isotherm_index_values = app.isotherm_params(1, :);
            app.name_iso_model_read = app.iso_models_avai(isotherm_index_values);

            if app.params_all.MixtureMode == 1
                app.MixturePrediction.Value = 'IAST';
            else
                app.MixturePrediction.Value = 'Extended Langmuir';
            end
            
            if app.params_all.CalcMode == 0
                app.CalcTypeDropDown.Value = 'Isothermal';
                app.T_flag = 0;
            else
                app.CalcTypeDropDown.Value = 'Non-Isothermal';
                app.T_flag = 1;
            end

            % Displaying the respective params on Feed Gas Parameters Tab
            app.FeedTemperaturemathrmKEditField.Value = Feed_Gas_params(1);
            app.SpecificHeatCapacitymathrmJmolKEditField.Value = Feed_Gas_params(3);
            app.ThermalConductivitymathrmWmKEditField.Value = Feed_Gas_params(4);
            app.FeedflowEditField_2.Value = Feed_Gas_params(5);
            app.GasViscositymathrmPasEditField.Value = Feed_Gas_params(6);
            app.MolecularDiffusivitymathrmm2sEditField.Value = Feed_Gas_params(7);
            
            if app.feed_flow_type == 0
                app.FeedFlowDropDown.Value = 'm/s';
            else
                app.FeedFlowDropDown.Value = 'SCCM';
            end
            app.NoofComponentsEditField.Value = app.num_comp;
            
            % Displaying the respective params on Column Parameters and Column Internals Tab
            app.PressuremathrmPaEditField.Value = Column_Bed_params(1);
            app.LengthmathrmmEditField.Value = Column_Bed_params(2);
            app.InnerDiametermathrmmEditField.Value = Column_Bed_params(3);
            app.WallTemperaturemathrmKEditField.Value = Column_Bed_params(4);            
            app.BulkDensitymathrmkgm3EditField.Value = Column_Bed_params(6);
            app.ParticleDiametermathrmmEditField.Value = Column_Bed_params(7);           
            app.BedPorosityEditField.Value = Column_Bed_params(9);
            app.ParticlePorosityEditField.Value = Column_Bed_params(10);
            app.SolidSpecificHeatCapacitymathrmJkgKEditField.Value = Column_Bed_params(11);
            app.InsideHeatTransferCoefficientmathrmWm2KEditField.Value = Column_Bed_params(12);
            
            app.WallThermalConductivitymathrmWmKEditField.Value = Column_Bed_params(13);
            app.WallDensitymathrmkgm3EditField.Value = Column_Bed_params(14);
            app.WallSpecificHeatCapacitymathrmJkgKEditField.Value = Column_Bed_params(15);
            app.OuterDiametermathrmmEditField.Value = Column_Bed_params(16);
            app.OutsideHeatTransferCoefficientmathrmWm2KEditField.Value = Column_Bed_params(17);
            app.AmbientTemperaturemathrmKEditField.Value = Column_Bed_params(18);

            % Displaying the respective params on Numerical Parameters Tab
            app.NoofNodesDropDown.Value = num2str(Numerical_params(1));
            app.SimulationTimesEditField.Value = Numerical_params(2);
            app.AbsoluteToleranceEditField.Value = Numerical_params(3);
            app.RelativeToleranceEditField.Value = Numerical_params(4);
            
            % Updating the Tables and List box
            app.update_component_table();
            app.update_comp_list_box();
            app.update_MTC_table();
            app.update_isotherm_table();
            app.update_init_MF_table();
            app.update_labels();
        end
        %
        %% Function to import isotherms from .bliso files
        function [] = import_isotherm(app, data_filename)
            
            % Check the file extension
            extension_pat = ".bliso";

            if ~(endsWith(data_filename, extension_pat, "IgnoreCase",false))
                error('Please import valid file with *.bliso extension...')
            end

            % Read the file
            fileID = fopen(data_filename, 'r');
            
            model_read = 'None';
            params_lines = 0;
            params_read = {};
            Ref_T = NaN;
            dH = NaN;
            
            match_exp = '(?<=\s+)\-?\d*\.?\d+([eE][+\-]?\d+)?';
            
            while ~feof(fileID)
                current_line = fgetl(fileID);
                
                % Ignoring lines starting with * 
                if ~isempty(regexp(current_line, '^\*', "match")) || isempty(current_line)
                    % skip the empty or lines starting with *
                    continue
                else                  
                    % Search for isotherm model
                    if contains(current_line, 'Isotherm Model')
                        model_read = strip(extractAfter(current_line, ':'));
                        continue
                    end

                    % Search of Reference temperature value
                    if app.T_flag && contains(current_line, 'Reference Temperature')
                        try
                            Ref_T = regexp(current_line, match_exp, "match", "once");
                            Ref_T = double(string(Ref_T));
                        catch
                            continue
                        end
                        continue
                    end
                    
                    % Search for isotherm parameters
                    if contains(current_line, 'Parameters')
                        % Setting the params line flag on and passing control
                        % to next line
                        params_lines = 1;
                        continue
                    end
                    
                    % Finding numerical values in the line
                    if params_lines
                        % Only first match is recorded and standard errors
                        % associated with parameters values are ignored.
                        current_param = regexp(current_line, match_exp, "match", "once"); 
                        if ~isempty(current_param)
                            params_read(end+1) = {current_param};
                        end
                    end
                end
            end
            
            % Closing the file
            fclose(fileID);
            
            % Checking whether the read isotherm model is valid or not.
            if strcmpi(model_read, 'None') || isempty(model_read)
                error(sprintf('No isotherm model found in the given file. Choose valid input file'));
            elseif isempty(params_read)
                error(sprintf('No isotherm model parameters found in the given file. Choose valid input file'));
            end
            
            model_key = find(strcmpi(app.iso_models_avai, model_read));
            if isempty(model_key)
                error(sprintf('Invalid isotherm model. Please import isotherm parameter file with valid isotherm models...'));
            end
            
            
            % Converting params read to double type and transporsing to get
            % column vector
            try
                params_read = double(string((params_read)))';
            catch ME1
                error(sprintf('Non-numeric parameters values found in the data file'));
            end

            % Calling pars function to get params name check params read
            % array for consistency
            pars_params = iso_params_parsing(model_read, []);
            num_params = pars_params.num_params;
            
            if num_params > size(params_read, 1)
                error(sprintf(['Some of the parameter values for the isotherm model read, ', ...
                                'are not found in the file.\n']));

            elseif app.T_flag && num_params < size(params_read, 1)
                dH = params_read(num_params+1);
            end

            params_read = params_read(1:num_params);
            
            % Temporary matrix to hold the read isotherm params
            temp_params = NaN(app.num_max_params, 1);

            temp_params(1) = model_key;
            temp_params(2:length(params_read)+1) = params_read;
            
            % Updating the Reference temperature value
            temp_params(end-1) = dH;
            temp_params(end) = Ref_T;

            % Getting Component index
            comp_index = app.CompListBox.ValueIndex;

            % Updating the  main isotherm params matrix
            app.isotherm_params(:, comp_index) = temp_params;
            
            % Updating the name of isotherms model for table
            app.name_iso_model_read(comp_index) = {model_read};
        end
        %
        %% Function to import isotherm params data
        function [] = import_iso_data(app)
            filter = {'*.bliso', 'AIM Isotherm Parameters Files'};
            % filter = {'*.csv'; '*.txt'; '*.xlsx'; '.dat'};
            [file, path, indx] = uigetfile(filter, 'Select file containing isotherm parameters', ...
                                            'my_iso_params.bliso', 'MultiSelect','off');
            
            % Command for refocusing parent app
            figure(app.AdditionalSettingsUIFigure);

            try
                if indx
                    filename = fullfile(path, file);

                    % Importing Isotherm Params
                    app.import_isotherm(filename);
                    
                    % Updating the Read Isotherm Table
                    app.update_isotherm_table();
                end
            catch ME1
                % throw(ME1);
                % app.update_textbox_func(app.OutputText, ...
                %                         sprintf('Error occured while reading the file...!\nError message: %s', ME1.message))
            end
        end
        %
        %% Function to normalize the feed molefraction
        function x_norm = normalize_molefrac(~, x)
            x_norm = x ./ sum(x);
        end
        %
        %% Function to adjust the size of figure
        function [] = adjust_size(app)
            app.AdditionalSettingsUIFigure.Units = "normalized";
            app.AdditionalSettingsUIFigure.Position(1) = 0.1;
            app.AdditionalSettingsUIFigure.Position(2) = 0.05;
            app.AdditionalSettingsUIFigure.Units = "pixels";
        end
        %
        %% Function to update the uifigure for different devices
        function [] = adjust_uifig(app)
            % Get the device resolution
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            
            default_position = app.AdditionalSettingsUIFigure.Position;
            
            if default_position(3) > screenWidth || default_position(4) > screenHeight
                left = screenWidth*0.1;
                bottom = screenHeight*0.1;
                width = screenWidth*0.85;
                height = screenHeight*0.85;
                drawnow;
                app.AdditionalSettingsUIFigure.Position = [left bottom width height];
            end            
        end
        %
        %% Function to collect parameter values from their respective fields
        % used when the user saves the parameters or when press OK button.
        function [] = collect_params(app)
            % Normalizing Feed and initial Mole Fraction
            app.molefrac = app.normalize_molefrac(app.molefrac);
            app.initmolefrac = app.normalize_molefrac(app.initmolefrac);

            %% Feed Gas Parameters
            T_0 = app.FeedTemperaturemathrmKEditField.Value;
            C_pg = app.SpecificHeatCapacitymathrmJmolKEditField.Value;
            K_z = app.ThermalConductivitymathrmWmKEditField.Value;
            v_0 = app.FeedflowEditField_2.Value;
            mu = app.GasViscositymathrmPasEditField.Value;
            D_m = app.MolecularDiffusivitymathrmm2sEditField.Value;
            
            Feed_Gas_params = [T_0, 0, C_pg, K_z, v_0, mu, D_m];

            % Updating the feed flow type
            app.caller.feed_flow_type = app.feed_flow_type;    
            %
            %% Column and Bed Parameters
            P_0 = app.PressuremathrmPaEditField.Value;
            L = app.LengthmathrmmEditField.Value;
            dia_in = app.InnerDiametermathrmmEditField.Value;
            T_wall = app.WallTemperaturemathrmKEditField.Value;

            ro_total = app.BulkDensitymathrmkgm3EditField.Value;
            dia_p = app.ParticleDiametermathrmmEditField.Value;
            epsilon_p = app.ParticlePorosityEditField.Value;
            epsilon_b = app.BedPorosityEditField.Value;
            C_psolid = app.SolidSpecificHeatCapacitymathrmJkgKEditField.Value;
            h_in = app.InsideHeatTransferCoefficientmathrmWm2KEditField.Value;
            
            K_wall = app.WallThermalConductivitymathrmWmKEditField.Value;
            ro_wall = app.WallDensitymathrmkgm3EditField.Value;
            C_pwall = app.WallSpecificHeatCapacitymathrmJkgKEditField.Value;
            dia_out = app.OuterDiametermathrmmEditField.Value;
            h_out = app.OutsideHeatTransferCoefficientmathrmWm2KEditField.Value;
            T_amb = app.AmbientTemperaturemathrmKEditField.Value;

            Column_Bed_params = [P_0, L, dia_in, T_wall, 0, ro_total,...
                    dia_p, 0, epsilon_b, epsilon_p, C_psolid, h_in,...
                    K_wall, ro_wall, C_pwall, dia_out, h_out, T_amb];
            %
            %% Numerical Simulation Parameters
            N = str2double(app.NoofNodesDropDown.Value);
            t = app.SimulationTimesEditField.Value;
            AbsTol = app.AbsoluteToleranceEditField.Value;
            RelTol = app.RelativeToleranceEditField.Value;

            Numerical_params = [N, t, AbsTol, RelTol];
            %
            %% Update modified params struc
            app.Modified_parameters.FeedGas = Feed_Gas_params;
            app.Modified_parameters.FeedFlowType = app.feed_flow_type;
            app.Modified_parameters.ColBed = Column_Bed_params;
            app.Modified_parameters.NumPar = Numerical_params;

            app.Modified_parameters.CompNum = app.num_comp;
            app.Modified_parameters.CompNames = app.name_comp;
            app.Modified_parameters.FeedMF = app.molefrac;
            app.Modified_parameters.FeedMM = app.molarmass;
            app.Modified_parameters.InitMF = app.initmolefrac;
            app.Modified_parameters.IsothermParams = app.isotherm_params;
            app.Modified_parameters.MTC = app.MTC;

            if strcmpi(app.MixturePrediction.Value, 'IAST')
                app.Modified_parameters.MixtureMode = 1;
            else
                app.Modified_parameters.MixtureMode = 0;
            end

            if app.T_flag
                app.Modified_parameters.CalcMode = 1;
            else
                app.Modified_parameters.CalcMode = 0;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app_caller_handle)
            app.adjust_size();
            app.adjust_uifig();
            app.caller = app_caller_handle;
            app.params_all = app.caller.parameters;
            % app.feed_flow_type = app.caller.feed_flow_type;
            app.startup_func();
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Packing the respective params on the tabs and updating the
            % Main App display

            app.collect_params();
            app.caller.parameters = app.Modified_parameters; 

            app.caller.MoreInputParametersButton.Enable = "on";
            app.caller.setting_main_params(app.caller.parameters)
            
            % Restoring caller app figure
            figure(app.caller.AIMBreakLabUIFigure);
            
            delete(app)
        end

        % Button pushed function: RestoreButton
        function RestoreButtonPushed(app, event)
            app.params_all = Default_Input_Parameters();
            % app.set_params(default_parameters);
            app.startup_func();
        end

        % Close request function: AdditionalSettingsUIFigure
        function AdditionalSettingsUIFigureCloseRequest(app, event)
            % No parameters are updated and the existing values are
            % retained.
            app.caller.MoreInputParametersButton.Enable = "on";
            app.caller.setting_main_params(app.caller.parameters)
            
            % Restoring caller app figure
            figure(app.caller.AIMBreakLabUIFigure);
            delete(app.IsothermParamsHelpApp);
            delete(app)
        end

        % Value changed function: NoofComponentsEditField
        function NoofComponentsEditFieldValueChanged(app, event)
            app.num_comp = app.NoofComponentsEditField.Value;
            
            % Appending Component names in case new component are added
            num_new_comp = app.num_comp - numel(app.name_comp);
            if num_new_comp > 0
                last_index = numel(app.name_comp);

                for i=1:num_new_comp
                    % Appending Column Names
                    current_index = i+last_index-1;
                    app.name_comp(end+1) = {sprintf('Component %d', current_index)};

                    % Appending Feed Mole Fractions
                    app.molefrac = horzcat(app.molefrac, 0);

                    % Appending Molar Masses
                    app.molarmass = horzcat(app.molarmass, 0);

                    % Appending MTC
                    app.MTC = horzcat(app.MTC, 0);

                    % Appending Init Mole Fraction
                    app.initmolefrac = horzcat(app.initmolefrac, 0);
                end
            
            elseif num_new_comp < 0
                % Removing extra entries
                app.name_comp = app.name_comp(1:app.num_comp);
                app.molefrac = app.molefrac(1:app.num_comp);
                app.molarmass = app.molarmass(1:app.num_comp);
                app.MTC = app.MTC(1:app.num_comp-1);                    % Removing Carrier gas
                app.initmolefrac = app.initmolefrac(1:app.num_comp);
            end

            % Updating the Tables
            app.update_component_table();
            app.update_comp_list_box();
            app.update_MTC_table();
            app.update_isotherm_table();
            app.update_init_MF_table();
        end

        % Button pushed function: ImportIsothermButton
        function ImportIsothermButtonPushed(app, event)
            app.import_iso_data();
        end

        % Cell edit callback: ComponentTable
        function ComponentTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            if isempty(event.Error)
                switch (indices(2))
                    case 1
                        app.name_comp(indices(1)) = {newData};
                        % Updating the Tables
                        app.update_comp_list_box();
                        app.update_MTC_table();
                        app.update_isotherm_table();
                        app.update_init_MF_table();
                    case 2
                        if ~isnan(newData) 
                            app.molefrac(indices(1)) = newData;
                        end
                    case 3
                        if ~isnan(newData)
                            app.molarmass(indices(1)) = newData;
                        end
                end
            else
                app.ComponentTable.Data(indices(1), indices(2)) = event.PreviousData;
            end
        end

        % Cell edit callback: MTCTable
        function MTCTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            if ~isnan(newData) && isempty(event.Error)
                app.MTC(indices(1)) = newData;
            else
                app.MTCTable.Data(indices(1), indices(2)) = event.PreviousData;
            end
        end

        % Cell edit callback: IsothermTable
        function IsothermTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            switch(indices(2))
                case 1      % Isotherm model changed
                    new_key = find(strcmpi(app.iso_models_avai, newData));
                    % Updating isotherm params matrix
                    app.isotherm_params(1, indices(1)) = new_key;
                    % Updating iso_model_read array
                    app.name_iso_model_read(indices(1)) = app.iso_models_avai(new_key);

                otherwise
                    if ~isnan(newData) && isempty(event.Error)
                        if app.T_flag
                            current_num_col = numel(app.IsothermTable.ColumnName);
                            offset_idx = current_num_col-indices(2);
                            if offset_idx<2      % 2 because of dH and Ref_T entries
                                app.isotherm_params(end-offset_idx, indices(1)) = newData;
                            else
                                app.isotherm_params(indices(2), indices(1)) = newData;
                            end
                        else
                            app.isotherm_params(indices(2), indices(1)) = newData;
                        end
                        
                    elseif isempty(event.EditData)
                        % app.IsothermTable.Data(indices(1), indices(2)) = {[]};
                        app.isotherm_params(indices(2), indices(1)) = NaN;
                    else
                        app.IsothermTable.Data(indices(1), indices(2)) = {event.PreviousData};
                    end
            end

            % Updating Isotherm Table
            app.update_isotherm_table();
        end

        % Value changed function: CalcTypeDropDown
        function CalcTypeDropDownValueChanged(app, event)
            if app.CalcTypeDropDown.ValueIndex == 1
                app.T_flag = 0;
            elseif app.CalcTypeDropDown.ValueIndex == 2
                app.T_flag = 1;
            end

            % Update Isotherm table
            app.update_isotherm_table();

            % Hiding not required parameters
            app.update_labels();
        end

        % Cell edit callback: InitMFTable
        function InitMFTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            if ~isnan(newData) && isempty(event.Error)
                app.initmolefrac(indices(1)) = newData;
            else
                app.InitMFTable.Data(indices(1), indices(2)) = event.PreviousData;
            end
        end

        % Value changed function: FeedFlowDropDown
        function FeedFlowDropDownValueChanged(app, event)
            value = app.FeedFlowDropDown.Value;
            
            if strcmpi(value, 'm/s')
                app.feed_flow_type = 0;
            else
                app.feed_flow_type = 1;
            end
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            app.IsothermParamsHelpApp = iso_info();
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Load the Params
            filter = {'*.json', 'AIM BreakLab Parameters Files'};
            [file, path, indx] = uigetfile(filter, 'Select file containing AIM BreakLab parameters', ...
                                            'my_BreakLab_params.json', 'MultiSelect','off');

            % Command for refocusing parent app
            figure(app.AdditionalSettingsUIFigure);
            
            if indx
                filename = fullfile(path, file);
                shape_iso_params = size(app.params_all.IsothermParams);
                
                try
                    temp_params_all = readstruct(filename, "FileType","json", "ParsingMode","lenient");
                    temp_params_all.IsothermParams = reshape(temp_params_all.IsothermParams, ...
                                                         shape_iso_params(1), shape_iso_params(2));
                    
                    % To convert component names string array into cell
                    % array
                    temp_params_all.CompNames = cellstr(temp_params_all.CompNames);

                catch ME1
                    error("Failed to load parameters...\n%s", ME1.message);
                end

                try
                    app.params_all = temp_params_all;
                    app.startup_func();
                catch ME1
                    error("Failed to set parameters...\n%s", ME1.message);
                end
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Save the Params
            filter = {'*.json', 'AIM BreakLab Parameters Files'};
            default_file_name = fullfile('my_BreakLab_params.json');
            [file, path, indx] = uiputfile(filter, 'Select file for save as', ...
                                    default_file_name);
            
            % Command for refocusing parent app
            figure(app.AdditionalSettingsUIFigure);

            if indx
                filename = fullfile(path, file);
                app.collect_params();
                temp_params_all = app.Modified_parameters;
                temp_params_all.IsothermParams = reshape(temp_params_all.IsothermParams, [], 1);

                writestruct(temp_params_all, filename, "FileType","json", ...
                            "PrettyPrint",true, "PreserveInfAndNaN",true);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AdditionalSettingsUIFigure = uifigure('Visible', 'off');
            app.AdditionalSettingsUIFigure.Name = 'Additional Settings';
            app.AdditionalSettingsUIFigure.Icon = fullfile(pathToMLAPP, 'AIM_logo.png');
            app.AdditionalSettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @AdditionalSettingsUIFigureCloseRequest, true);
            app.Panel = uipanel(app.AdditionalSettingsUIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.FeedGasTab = uitab(app.TabGroup);
            app.FeedGasTab.Title = 'Feed Gas';
            app.FeedGasParametersPanel = uipanel(app.FeedGasTab);
            app.FeedGasParametersPanel.Title = 'Feed Gas Parameters';
            app.FeedTemperatureoCLabel = uilabel(app.FeedGasParametersPanel);
            app.FeedTemperatureoCLabel.Interpreter = 'latex';
            app.FeedTemperatureoCLabel.Text = 'Feed Temperature $(\mathrm{K})$';
            app.FeedTemperaturemathrmKEditField = uieditfield(app.FeedGasParametersPanel, 'numeric');
            app.FeedTemperaturemathrmKEditField.Limits = [-273.15 Inf];
            app.FeedTemperaturemathrmKEditField.Value = 300;
            app.SpecificHeatcapacityJmolKLabel = uilabel(app.FeedGasParametersPanel);
            app.SpecificHeatcapacityJmolKLabel.Interpreter = 'latex';
            app.SpecificHeatcapacityJmolKLabel.Text = 'Specific Heat Capacity $\mathrm{(J/mol/{K)}$';
            app.SpecificHeatCapacitymathrmJmolKEditField = uieditfield(app.FeedGasParametersPanel, 'numeric');
            app.SpecificHeatCapacitymathrmJmolKEditField.Limits = [0 Inf];
            app.SpecificHeatCapacitymathrmJmolKEditField.Value = 1;
            app.ThermalConductivityWmKLabel = uilabel(app.FeedGasParametersPanel);
            app.ThermalConductivityWmKLabel.Interpreter = 'latex';
            app.ThermalConductivityWmKLabel.Text = 'Thermal Conductivity $\mathrm{(W/m/{K)}$';
            app.ThermalConductivitymathrmWmKEditField = uieditfield(app.FeedGasParametersPanel, 'numeric');
            app.ThermalConductivitymathrmWmKEditField.Limits = [0 Inf];
            app.ThermalConductivitymathrmWmKEditField.Value = 1;
            app.MolecularDiffusivitym2sLabel = uilabel(app.FeedGasParametersPanel);
            app.MolecularDiffusivitym2sLabel.Interpreter = 'latex';
            app.MolecularDiffusivitym2sLabel.Text = 'Molecular Diffusivity $\mathrm{(m^{2} /s)}$';
            app.MolecularDiffusivitymathrmm2sEditField = uieditfield(app.FeedGasParametersPanel, 'numeric');
            app.MolecularDiffusivitymathrmm2sEditField.Limits = [0 Inf];
            app.MolecularDiffusivitymathrmm2sEditField.Value = 0.01;
            app.GasViscosityPasLabel = uilabel(app.FeedGasParametersPanel);
            app.GasViscosityPasLabel.Interpreter = 'latex';
            app.GasViscosityPasLabel.Text = 'Gas Viscosity $\mathrm{(Pa.s)}$';
            app.GasViscositymathrmPasEditField = uieditfield(app.FeedGasParametersPanel, 'numeric');
            app.GasViscositymathrmPasEditField.Limits = [0 Inf];
            app.GasViscositymathrmPasEditField.Value = 0.01;
            app.FeedSuperficialvelocitymsEditField_2Label_2 = uilabel(app.FeedGasParametersPanel);
            app.FeedSuperficialvelocitymsEditField_2Label_2.Interpreter = 'latex';
            app.FeedSuperficialvelocitymsEditField_2Label_2.Text = 'Feed Flow';
            app.FeedflowEditField_2 = uieditfield(app.FeedGasParametersPanel, 'numeric');
            app.FeedflowEditField_2.Limits = [0 Inf];
            app.FeedFlowDropDown = uidropdown(app.FeedGasParametersPanel);
            app.FeedFlowDropDown.Items = {'m/s', 'SCCM'};
            app.FeedFlowDropDown.ValueChangedFcn = createCallbackFcn(app, @FeedFlowDropDownValueChanged, true);
            app.FeedFlowDropDown.Value = 'm/s';
            app.FeedCompositionPanel = uipanel(app.FeedGasTab);
            app.FeedCompositionPanel.Title = 'Feed Composition';
            app.NoofComponentsEditFieldLabel = uilabel(app.FeedCompositionPanel);
            app.NoofComponentsEditFieldLabel.Text = 'No. of Components';
            app.NoofComponentsEditField = uieditfield(app.FeedCompositionPanel, 'numeric');
            app.NoofComponentsEditField.Limits = [2 5];
            app.NoofComponentsEditField.ValueChangedFcn = createCallbackFcn(app, @NoofComponentsEditFieldValueChanged, true);
            app.NoofComponentsEditField.Value = 2;
            app.ComponentTable = uitable(app.FeedCompositionPanel);
            app.ComponentTable.ColumnName = {'Component Name'; 'Mole Fraction (-)'; 'Molar Mass (kg/mol)'};
            app.ComponentTable.RowName = {};
            app.ComponentTable.CellEditCallback = createCallbackFcn(app, @ComponentTableCellEdit, true);
            app.AdsorptionIsothermTab = uitab(app.TabGroup);
            app.AdsorptionIsothermTab.Title = 'Adsorption Isotherm';
            app.IsothermModelPanel_2 = uipanel(app.AdsorptionIsothermTab);
            app.IsothermModelPanel_2.Title = 'Isotherm Model';
            app.CalculationTypeLabel = uilabel(app.IsothermModelPanel_2);
            app.CalculationTypeLabel.Text = 'Calculation Type';
            app.CalcTypeDropDown = uidropdown(app.IsothermModelPanel_2);
            app.CalcTypeDropDown.Items = {'Isothermal', 'Non-Isothermal'};
            app.CalcTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @CalcTypeDropDownValueChanged, true);
            app.CalcTypeDropDown.Value = 'Isothermal';
            app.MixturePredictionLabel = uilabel(app.IsothermModelPanel_2);
            app.MixturePredictionLabel.Text = 'Mixture Prediction';
            app.MixturePrediction = uidropdown(app.IsothermModelPanel_2);
            app.MixturePrediction.Items = {'Extended Langmuir', 'IAST'};
            app.MixturePrediction.Value = 'Extended Langmuir';
            app.ComponentsLabel = uilabel(app.IsothermModelPanel_2);
            app.ComponentsLabel.Text = 'Components';
            app.CompListBox = uilistbox(app.IsothermModelPanel_2);
            app.ImportIsothermButton = uibutton(app.IsothermModelPanel_2, 'push');
            app.ImportIsothermButton.ButtonPushedFcn = createCallbackFcn(app, @ImportIsothermButtonPushed, true);
            app.ImportIsothermButton.Text = 'Import Isotherm';
            app.IsothermParametersLabel_3 = uilabel(app.IsothermModelPanel_2);
            app.IsothermParametersLabel_3.Text = 'Isotherm Parameters';
            app.Image = uiimage(app.IsothermModelPanel_2);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'question.png');
            app.IsothermTable = uitable(app.IsothermModelPanel_2);
            app.IsothermTable.ColumnName = '';
            app.IsothermTable.RowName = {};
            app.IsothermTable.ColumnEditable = true;
            app.IsothermTable.CellEditCallback = createCallbackFcn(app, @IsothermTableCellEdit, true);
            app.MassTransferCoefficientsLabel = uilabel(app.IsothermModelPanel_2);
            app.MassTransferCoefficientsLabel.Text = 'Mass Transfer Coefficients';
            app.MTCTable = uitable(app.IsothermModelPanel_2);
            app.MTCTable.ColumnName = {'Components'; 'k [1/s]'};
            app.MTCTable.RowName = {};
            app.MTCTable.CellEditCallback = createCallbackFcn(app, @MTCTableCellEdit, true);
            app.iso_model_text_2 = uilabel(app.IsothermModelPanel_2);
            app.iso_model_text_2.Interpreter = 'latex';
            app.iso_model_text_2.Text = '';
            app.ColumnParametersTab = uitab(app.TabGroup);
            app.ColumnParametersTab.Title = 'Column Parameters';
            app.AdsorberColumnPanel = uipanel(app.ColumnParametersTab);
            app.AdsorberColumnPanel.Title = 'Adsorber Column ';
            app.PressurekPaLabel = uilabel(app.AdsorberColumnPanel);
            app.PressurekPaLabel.Interpreter = 'latex';
            app.PressurekPaLabel.Text = 'Pressure $\mathrm{(Pa)}$';
            app.PressuremathrmPaEditField = uieditfield(app.AdsorberColumnPanel, 'numeric');
            app.PressuremathrmPaEditField.Limits = [0.001 Inf];
            app.PressuremathrmPaEditField.Value = 300;
            app.LengthmLabel = uilabel(app.AdsorberColumnPanel);
            app.LengthmLabel.Interpreter = 'latex';
            app.LengthmLabel.Text = 'Length $\mathrm{(m)}$';
            app.LengthmathrmmEditField = uieditfield(app.AdsorberColumnPanel, 'numeric');
            app.LengthmathrmmEditField.Limits = [0 Inf];
            app.LengthmathrmmEditField.Value = 0.01;
            app.DiametermLabel = uilabel(app.AdsorberColumnPanel);
            app.DiametermLabel.Interpreter = 'latex';
            app.DiametermLabel.Text = 'Inner Diameter $\mathrm{(m)}$';
            app.InnerDiametermathrmmEditField = uieditfield(app.AdsorberColumnPanel, 'numeric');
            app.InnerDiametermathrmmEditField.Limits = [0 Inf];
            app.InnerDiametermathrmmEditField.Value = 0.01;
            app.OuterDiametermathrmmEditField = uieditfield(app.AdsorberColumnPanel, 'numeric');
            app.OuterDiametermathrmmEditField.Limits = [0 Inf];
            app.OuterDiametermathrmmEditField.Value = 0.01;
            app.WallThermalConductivitymathrmWmKEditField = uieditfield(app.AdsorberColumnPanel, 'numeric');
            app.WallThermalConductivitymathrmWmKEditField.Limits = [0 Inf];
            app.WallThermalConductivitymathrmWmKEditField.Value = 1;
            app.WallDensitymathrmkgm3EditField = uieditfield(app.AdsorberColumnPanel, 'numeric');
            app.WallDensitymathrmkgm3EditField.Limits = [0 Inf];
            app.WallDensitymathrmkgm3EditField.Value = 300;
            app.WallSpecificHeatCapacitymathrmJkgKEditField = uieditfield(app.AdsorberColumnPanel, 'numeric');
            app.WallSpecificHeatCapacitymathrmJkgKEditField.Limits = [0 Inf];
            app.WallSpecificHeatCapacitymathrmJkgKEditField.Value = 300;
            app.WallTemperatureoCLabel = uilabel(app.AdsorberColumnPanel);
            app.WallTemperatureoCLabel.Enable = 'off';
            app.WallTemperatureoCLabel.Visible = 'off';
            app.WallTemperatureoCLabel.Interpreter = 'latex';
            app.WallTemperatureoCLabel.Text = 'Wall Temperature $\mathrm{(K)}$';
            app.WallHeatTransferCoeeficientWm2KLabel = uilabel(app.AdsorberColumnPanel);
            app.WallHeatTransferCoeeficientWm2KLabel.Interpreter = 'latex';
            app.WallHeatTransferCoeeficientWm2KLabel.Text = 'Inside Heat Transfer Coefficient  $\mathrm{(W/m^{2}/{K)}$';
            app.InsideHeatTransferCoefficientmathrmWm2KEditField = uieditfield(app.AdsorberColumnPanel, 'numeric');
            app.InsideHeatTransferCoefficientmathrmWm2KEditField.Limits = [0 Inf];
            app.InsideHeatTransferCoefficientmathrmWm2KEditField.Tooltip = {'Enter the HTC value for Wall to Gas heat transfer calculation or vice versa'};
            app.InsideHeatTransferCoefficientmathrmWm2KEditField.Value = 300;
            app.OutsideHeatTransferCoefficientmathrmWm2KEditFieldLabel = uilabel(app.AdsorberColumnPanel);
            app.OutsideHeatTransferCoefficientmathrmWm2KEditFieldLabel.Interpreter = 'latex';
            app.OutsideHeatTransferCoefficientmathrmWm2KEditFieldLabel.Text = 'Outside Heat Transfer Coefficient  $\mathrm{(W/m^{2}/{K)}$';
            app.OutsideHeatTransferCoefficientmathrmWm2KEditField = uieditfield(app.AdsorberColumnPanel, 'numeric');
            app.OutsideHeatTransferCoefficientmathrmWm2KEditField.Limits = [0 Inf];
            app.OutsideHeatTransferCoefficientmathrmWm2KEditField.Tooltip = {'Enter the HTC value for Wall to Gas heat transfer calculation or vice versa'};
            app.OutsideHeatTransferCoefficientmathrmWm2KEditField.Value = 300;
            app.AmbientTemperaturemathrmKEditFieldLabel = uilabel(app.AdsorberColumnPanel);
            app.AmbientTemperaturemathrmKEditFieldLabel.Interpreter = 'latex';
            app.AmbientTemperaturemathrmKEditFieldLabel.Text = 'Ambient Temperature $\mathrm{(K)}$';
            app.AmbientTemperaturemathrmKEditField = uieditfield(app.AdsorberColumnPanel, 'numeric');
            app.AmbientTemperaturemathrmKEditField.Limits = [-273.15 Inf];
            app.AmbientTemperaturemathrmKEditField.Value = 300;
            app.WallThermalConductivitymathrmWmKEditFieldLabel = uilabel(app.AdsorberColumnPanel);
            app.WallThermalConductivitymathrmWmKEditFieldLabel.Interpreter = 'latex';
            app.WallThermalConductivitymathrmWmKEditFieldLabel.Text = 'Wall Thermal Conductivity $\mathrm{(W/m/{K)}$';
            app.WallDensitymathrmkgm3EditFieldLabel = uilabel(app.AdsorberColumnPanel);
            app.WallDensitymathrmkgm3EditFieldLabel.Interpreter = 'latex';
            app.WallDensitymathrmkgm3EditFieldLabel.Text = 'Wall Density $\mathrm{(kg/m^{3})}$';
            app.WallSpecificHeatCapacitymathrmJkgKEditFieldLabel = uilabel(app.AdsorberColumnPanel);
            app.WallSpecificHeatCapacitymathrmJkgKEditFieldLabel.Interpreter = 'latex';
            app.WallSpecificHeatCapacitymathrmJkgKEditFieldLabel.Text = 'Wall Specific Heat Capacity $\mathrm{(J/kg/K)}$';
            app.WallTemperaturemathrmKEditField = uieditfield(app.AdsorberColumnPanel, 'numeric');
            app.WallTemperaturemathrmKEditField.Limits = [-273.15 Inf];
            app.WallTemperaturemathrmKEditField.Enable = 'off';
            app.WallTemperaturemathrmKEditField.Visible = 'off';
            app.WallTemperaturemathrmKEditField.Value = 300;
            app.OuterDiametermathrmmEditFieldLabel = uilabel(app.AdsorberColumnPanel);
            app.OuterDiametermathrmmEditFieldLabel.Interpreter = 'latex';
            app.OuterDiametermathrmmEditFieldLabel.Text = 'Outer Diameter $\mathrm{(m)}$';
            app.ColumnInternalsTab = uitab(app.TabGroup);
            app.ColumnInternalsTab.Title = 'Column Internals';
            app.AdsorbentPropertiesPanel = uipanel(app.ColumnInternalsTab);
            app.AdsorbentPropertiesPanel.Title = 'Adsorbent Properties';
            app.BulkDensitymathrmkgm3EditFieldLabel = uilabel(app.ColumnInternalsTab);
            app.BulkDensitymathrmkgm3EditFieldLabel.Interpreter = 'latex';
            app.BulkDensitymathrmkgm3EditFieldLabel.Text = 'Bulk Density $\mathrm{(kg/m^{3})}$';
            app.BulkDensitymathrmkgm3EditField = uieditfield(app.ColumnInternalsTab, 'numeric');
            app.BulkDensitymathrmkgm3EditField.Limits = [0 Inf];
            app.BulkDensitymathrmkgm3EditField.Value = 300;
            app.ParticleDiametermathrmmEditFieldLabel = uilabel(app.ColumnInternalsTab);
            app.ParticleDiametermathrmmEditFieldLabel.Interpreter = 'latex';
            app.ParticleDiametermathrmmEditFieldLabel.Text = 'Particle Diameter $\mathrm{(m)}$';
            app.ParticleDiametermathrmmEditField = uieditfield(app.ColumnInternalsTab, 'numeric');
            app.ParticleDiametermathrmmEditField.Limits = [0 Inf];
            app.ParticleDiametermathrmmEditField.Value = 300;
            app.ParticlePorosityLabel = uilabel(app.ColumnInternalsTab);
            app.ParticlePorosityLabel.Interpreter = 'latex';
            app.ParticlePorosityLabel.Text = 'Particle Porosity (-) ';
            app.ParticlePorosityEditField = uieditfield(app.ColumnInternalsTab, 'numeric');
            app.ParticlePorosityEditField.Limits = [0 1];
            app.ParticlePorosityEditField.Value = 1;
            app.BedPorosityLabel = uilabel(app.ColumnInternalsTab);
            app.BedPorosityLabel.Interpreter = 'latex';
            app.BedPorosityLabel.Text = 'Bed Porosity (-)';
            app.BedPorosityEditField = uieditfield(app.ColumnInternalsTab, 'numeric');
            app.BedPorosityEditField.Limits = [0 1];
            app.BedPorosityEditField.Value = 1;
            app.SolidSpecificHeatCapacitymathrmJkgKEditFieldLabel = uilabel(app.ColumnInternalsTab);
            app.SolidSpecificHeatCapacitymathrmJkgKEditFieldLabel.Interpreter = 'latex';
            app.SolidSpecificHeatCapacitymathrmJkgKEditFieldLabel.Text = 'Solid Specific Heat Capacity $\mathrm{(J/kg/K)}$';
            app.SolidSpecificHeatCapacitymathrmJkgKEditField = uieditfield(app.ColumnInternalsTab, 'numeric');
            app.SolidSpecificHeatCapacitymathrmJkgKEditField.Limits = [0 Inf];
            app.SolidSpecificHeatCapacitymathrmJkgKEditField.Value = 300;
            app.NumericalTab = uitab(app.TabGroup);
            app.NumericalTab.Title = 'Numerical';
            app.NumericalSimulationParametersPanel = uipanel(app.NumericalTab);
            app.NumericalSimulationParametersPanel.Title = 'Numerical Simulation Parameters';
            app.NoofNodesDropDownLabel = uilabel(app.NumericalSimulationParametersPanel);
            app.NoofNodesDropDownLabel.Text = 'No. of Nodes';
            app.NoofNodesDropDown = uidropdown(app.NumericalSimulationParametersPanel);
            app.NoofNodesDropDown.Items = {'20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.NoofNodesDropDown.Value = '20';
            app.TimeofSorptionReactionstepsLabel = uilabel(app.NumericalSimulationParametersPanel);
            app.TimeofSorptionReactionstepsLabel.Text = 'Simulation Time (s)';
            app.SimulationTimesEditField = uieditfield(app.NumericalSimulationParametersPanel, 'numeric');
            app.SimulationTimesEditField.Limits = [1 Inf];
            app.SimulationTimesEditField.Value = 1;
            app.AbsoluteToleranceEditFieldLabel = uilabel(app.NumericalSimulationParametersPanel);
            app.AbsoluteToleranceEditFieldLabel.Text = 'Absolute Tolerance';
            app.AbsoluteToleranceEditField = uieditfield(app.NumericalSimulationParametersPanel, 'numeric');
            app.AbsoluteToleranceEditField.Limits = [0 1];
            app.AbsoluteToleranceEditField.ValueDisplayFormat = '%e';
            app.RelativeToleranceEditFieldLabel = uilabel(app.NumericalSimulationParametersPanel);
            app.RelativeToleranceEditFieldLabel.Text = 'Relative Tolerance';
            app.RelativeToleranceEditField = uieditfield(app.NumericalSimulationParametersPanel, 'numeric');
            app.RelativeToleranceEditField.Limits = [0 1];
            app.RelativeToleranceEditField.ValueDisplayFormat = '%e';
            app.InitMFTable = uitable(app.NumericalSimulationParametersPanel);
            app.InitMFTable.ColumnName = {'Components'; 'Initial Mole Fraction'};
            app.InitMFTable.RowName = {};
            app.InitMFTable.CellEditCallback = createCallbackFcn(app, @InitMFTableCellEdit, true);
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.LoadButton = uibutton(app.GridLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'Load BREAKLAB properties and parameters from json file'};
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'Save BREAKLAB properties and parameters to json file'};
            app.SaveButton.Text = 'Save';
            app.RestoreButton = uibutton(app.GridLayout, 'push');
            app.RestoreButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreButtonPushed, true);
            app.RestoreButton.Tooltip = {'Restore default BREAKLAB properties and parameters'};
            app.RestoreButton.Text = 'Restore';
            % Show the figure after all components are created
            app.AdditionalSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Input_Params(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AdditionalSettingsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.AdditionalSettingsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdditionalSettingsUIFigure)
        end
    end
end