classdef Isotherm_fit_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AIMIsoFitUIFigure              matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        DataInputPanel                 matlab.ui.container.Panel
        FitIsothermButton              matlab.ui.control.Button
        ExpressionLabel                matlab.ui.control.Label
        SetWorkspaceButton             matlab.ui.control.Button
        SatPressureLabel               matlab.ui.control.Label
        QuestionImage                  matlab.ui.control.Image
        SaturationPressureEditField    matlab.ui.control.NumericEditField
        ResetButton                    matlab.ui.control.Button
        ExportParametersasblisoButton  matlab.ui.control.Button
        IsothermDataTable              matlab.ui.control.Table
        IsothermFittingSettingsButton  matlab.ui.control.Button
        IsothermModelDropDown          matlab.ui.control.DropDown
        IsothermModelDropDownLabel     matlab.ui.control.Label
        ImportDataButton               matlab.ui.control.Button
        PlotPanel                      matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        Panel_2                        matlab.ui.container.Panel
        yaxisCheckBox                  matlab.ui.control.CheckBox
        xaxisCheckBox                  matlab.ui.control.CheckBox
        LogarithmicaxisLabel           matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        OutputPanel                    matlab.ui.container.Panel
        OutputText                     matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        caller                  % Main app handle
        settings_app            % App handle for viewing and saving fitting parameters
        iso_fit_finish_flag     % Whether isotherm fit has been successfully finished or not
        iso_fit_options         % Isotherm fitting options
        isotherm_struc          % Isotherm structure conatining information about fitting
        unit_pressure           % Pressure units in case they are specified in the aif data file
        unit_loading            % Loading units in case they are specified in the aif data file
        P_saturation            % Saturation Pressure required for Klotz, Dubinin-Astakhov, and Do-Do model
        p_sat_info              % app for displaying p_sat_info
        

        % All the isotherm models available in the app
        iso_models_av = {'SS-Langmuir', 'DS-Langmuir',...
                         'SS-Langmuir-Freundlich', 'DS-Langmuir-Freundlich',...
                         'Quadratic', 'Temkin', 'BET',...
                         'Sips', 'Toth', 'Structural-Transition-Adsorption', 'Dubinin-Astakhov', 'Klotz', 'Do-Do'};
    end
    
    properties (Access = public)
        T_flag                  % Temeprature dependence flag, always zero for single temperature isotherm fitting 
        iso_data                % Isotherm data from table
        iso_model               % Chosen isotherm model
        auto_iso_mode           % If auto mode is used for isotherm
        iso_data_import_flag    % Whether data is imported or not

        % Properties related to settings dialogue box
        initial_guess               % Inital guess matrix
        upper_bounds                % Upper bound matrix
        lower_bounds                % Lower bound matrix
        num_par_all_iso             % Number of parameters in isotherm model
        work_dir                    % Working directory
        use_multi                   % Flag for fitting using multistart option 
    end
    
    methods (Access = private)
        %% Function for startup and reset
        function []  = startup_func(app)
            app.iso_data_import_flag = 0;
            app.iso_data = [];
            app.IsothermDataTable.Data = cell(2, 2);
            app.IsothermDataTable.ColumnEditable = false;
            app.iso_fit_options.Weights = 'Uniform';
            app.iso_fit_options.algorithm_id = 'auto';
            app.iso_model = 'SS-Langmuir';
            app.update_iso_expression();
            app.iso_fit_finish_flag = 0;
            app.isotherm_struc = struct([]);
            app.T_flag = 0;
            
            % Setting P_saturation value;
            app.P_saturation = 1.0;
            app.update_sat_p();

            % setting the plot log axis values off
            app.xaxisCheckBox.Value = 0;
            app.yaxisCheckBox.Value = 0;
            
            % By default no use of multistart option while fitting
            app.use_multi = 0;

            % The dropdown contains all the model availablae in app and
            % default value is SS-Langmuir
            app.IsothermModelDropDown.Items = [{'Auto'}, app.iso_models_av];
            app.IsothermModelDropDown.Value = app.iso_model;
            
            % Updating the isotherm setting matrices
            app.update_iso_setting();
            
            % Clearing the message panel
            app.OutputText.Value = '';
            
            % Initializong units
            app.unit_pressure = 'Pa';
            app.unit_loading = 'mol/kg';
            
            % Clearing all plots on axis
            cla(app.UIAxes);
        end
        %% Function for updating text box and data logging
        function [] = update_textbox_func(~, app_comp, message)
            if strcmpi(app_comp.Value, '')
                updated_message = message;
            else
                updated_message = [app_comp.Value(:)', {''}, {message}];
            end
            app_comp.Value = updated_message;
            scroll(app_comp, 'bottom');
        end
        %
        %% Function for updating and keep track of Saturation Pressure
        function [] = update_sat_p(app)
            % The models which require Saturation Pressure values
            if any(strcmpi({'Auto', 'Dubinin-Astakhov', 'Klotz', 'Do-Do'}, app.iso_model))
                app.SaturationPressureEditField.Visible = "on"; 
                app.QuestionImage.Visible = "on";
                app.SaturationPressureEditField.Value = app.P_saturation;
                app.SatPressureLabel.Text = sprintf('$$P_{0}\\ $$');
                app.SatPressureLabel.Visible = "on";
                % if ~isempty(app.unit_pressure)
                %     % app.SatPressureLabel.Text = sprintf('$$P_{0}\\ (%s)$$', app.unit_pressure);
                % else
                %     app.SatPressureLabel.Text = sprintf('$$P_{0}\\ $$');
                % end
                % app.update_textbox_func(app.OutputText, sprintf('Please speci'))
            else
                app.SaturationPressureEditField.Visible = "off";
                app.SatPressureLabel.Visible = "off";
                app.QuestionImage.Visible = "off";
            end
        end
        %% Function for updating isotherm expression
        function [] = update_iso_expression(app, fit_params)
            if nargin<2
                fit_params = [];
            end
            chosen_model = app.iso_model;
            if strcmpi(chosen_model, 'Auto')
                app.ExpressionLabel.Text = {''};      
            else
                opt = iso_params_parsing(chosen_model, fit_params);
                app.ExpressionLabel.Text = opt.express;
            end
        end
        %
        %% Function to handle AIF file
        function isotherm_data_read = read_AIF(app, filename)
            
            % Calling AIF file parsing function
            aif_data = parse_aif(filename);
            
            idx_pressure = find(strcmpi(aif_data.loop_keys, '_adsorp_pressure'));
            idx_loading = find(strcmpi(aif_data.loop_keys, '_adsorp_amount'));
            idx_sat_p = find(strcmpi(aif_data.loop_keys, '_adsorp_p0'));

            % Specifying isotherm data
            if ~isempty(idx_pressure) && ~isempty(idx_loading)
                isotherm_data_read = aif_data.loop_data(:, [idx_pressure, idx_loading]);
            elseif isempty(idx_pressure)
                error('Failed to locate pressure data in %s file', filename);
            elseif isempty(idx_loading)
                error('Failed to locate adsorption loading data in %s file', filename);
            end

            % Specifying Saturation pressure
            if ~isempty(idx_sat_p)
                app.P_saturation = aif_data.loop_data(1, idx_sat_p);
            end

            % Specfying isotherm units
            if isConfigured(aif_data.meta_data)
                app.unit_pressure = lookup(aif_data.meta_data, "_units_pressure", "FallbackValue", '');
                app.unit_loading = lookup(aif_data.meta_data, "_units_loading", "FallbackValue", '');
            end
        end
        %
        %% Function to handle other isotherm data files
        function isotherm_data_read = read_file(app, filename)
            
            % Calling File parsing function
            file_data = parse_file(filename);
            
            % Specifying isotherm data
            isotherm_data_read = file_data.data;
            
            % Specfying isotherm units
            if isConfigured(file_data.meta_data)
                app.unit_pressure = lookup(file_data.meta_data, "#units_pressure", "FallbackValue", '');
                app.unit_loading = lookup(file_data.meta_data, "#units_loading", "FallbackValue", '');
                p_sat_read = lookup(file_data.meta_data, "#sat_pressure", "FallbackValue", '');
                if ~isempty(p_sat_read)
                    p_sat_read = double(string(p_sat_read));
                    if ~isnan(p_sat_read)
                        app.P_saturation = p_sat_read;
                    end
                end
            end
        end
        %% Function for importing isotherm data
        function [] = import_iso_data(app)
        % filter = {'*.csv; *.txt; *.xlsx; .dat', 'Data files'};
            filter = {'*.csv'; '*.txt'; '*.xlsx'; '.dat'; '*.aif'};
            default_file_name = fullfile(app.work_dir, 'my_isotherm_data.csv');
            [file, path, indx] = uigetfile(filter, 'Select isotherm data file', ...
                                           default_file_name, 'MultiSelect','off');
            % Command for refocusing parent app
            figure(app.AIMIsoFitUIFigure);

            try
                if indx
                    filename = fullfile(path, file);
                    if endsWith(filename, '.aif')
                        isotherm_data = app.read_AIF(filename);
                    else
                        isotherm_data = app.read_file(filename);
                        % isotherm_data = readmatrix(filename);
                        if size(isotherm_data, 2) > 2
                            app.update_textbox_func(app.OutputText, sprintf(['Data file contains more than two columns.' ...
                                                             'Only first two columns were loaded...']));
                        end
                    end
                    
                    app.IsothermDataTable.Data = isotherm_data(:, 1:2);
                    app.update_textbox_func(app.OutputText, sprintf('Data imported successfully...!'));
                    app.IsothermDataTable.ColumnEditable = true;
    
                    % Plotting the isotherm data
                    app.iso_data = app.IsothermDataTable.Data;
                    pressure_data = app.iso_data(:, 1);
                    loading_data = app.iso_data(:, 2);
                    
                    % Resetting the isotherm fitting state and isotherm struc only if the data is loaded successfully
                    app.iso_fit_finish_flag = 0; 
                    app.isotherm_struc = struct([]);
                    
                    % Updating the plot with loaded data
                    app.update_plot_func(pressure_data, loading_data, app.T_flag);
                    
                    % Updating the isotherm setting matrices
                    app.update_iso_setting();

                    % Setting the data import state on after successfull data upload 
                    app.iso_data_import_flag = 1;

                    % Setting the P_saturation value based on loaded data
                    app.update_sat_p();
                end
            catch ME1
                app.update_textbox_func(app.OutputText, ...
                                        sprintf('Error occured while reading the file...!\nError message: %s', ME1.message))
            end
        end
        %
        %% Function for printing isotherm params on message panel
        function [] = print_params(app, fileID)
            
            fitted_params = app.isotherm_struc.fitted_params;
            RMSE = app.isotherm_struc.RMSE;
            r2 = app.isotherm_struc.r2;
            std_err = app.isotherm_struc.std_error;

            if app.auto_iso_mode
                RMSE_all = app.isotherm_struc.RMSE_all;
                r2_all = app.isotherm_struc.r2_all;
                stats_all = [RMSE_all', r2_all'];

                isotherm_models = string(app.IsothermModelDropDown.Items);
                idx_to_remove = strcmpi(isotherm_models, 'Auto');
                isotherm_models(idx_to_remove) = [];
                % idx_to_remove = strcmpi(isotherm_models, 'Dubinin-Astakhov');
                % isotherm_models(idx_to_remove) = [];
                % idx_to_remove = strcmpi(isotherm_models, 'Klotz');
                % isotherm_models(idx_to_remove) = [];
                % idx_to_remove = strcmpi(isotherm_models, 'Do-Do');
                % isotherm_models(idx_to_remove) = [];

                % isotherm_models = isotherm_models(idx_to_remove)';
                isotherm_models = isotherm_models';
                maxLength = max(strlength(isotherm_models));

                initial_string = [sprintf('Fitting using different isotherm\n'),...
                                  sprintf('%-*s\t\tRMSE\t\tr2\n', maxLength, 'Isotherm Model')];
                for j=1:length(isotherm_models)
                      initial_string = [initial_string, sprintf('%-*s\t%f\t%f\n', maxLength, isotherm_models(j), stats_all(j, :))];
                end
            end

            heading_string = [sprintf('%s\n', repmat('*', 1, 74)),...
                            sprintf('%sIsoFit ISOTHERM FITTING RESULTS%s\n', repmat('*', 1, 21), repmat('*', 1, 21)),...
                            sprintf('%s\n', repmat('*', 1, 74))];
            
            iso_model_string = sprintf('Isotherm Model: %s\n', app.iso_model);
            
            stats_string = sprintf('RMSE:\t%f\nr^2:\t%f\n', RMSE, r2); 
                      
            % Calling pars function to get params name
            pars_params = iso_params_parsing(app.iso_model, fitted_params);
            name_params = pars_params.name_params;

            params_string = [sprintf('Parameters\n')];
            for i=1:length(fitted_params)
                if fitted_params(i)<0.1         % For scientific notation
                    if std_err(i)<0.0001
                        params_string = [params_string, sprintf('%s\t\t%e \x00b1 %e\n', char(name_params(i)), fitted_params(i), std_err(i))];
                    else
                        params_string = [params_string, sprintf('%s\t\t%e \x00b1 %f\n', char(name_params(i)), fitted_params(i), std_err(i))];
                    end
                else
                    if std_err(i)<0.0001
                        params_string = [params_string, sprintf('%s\t\t%f \x00b1 %e\n', char(name_params(i)), fitted_params(i), std_err(i))];
                    else
                        params_string = [params_string, sprintf('%s\t\t%f \x00b1 %f\n', char(name_params(i)), fitted_params(i), std_err(i))];
                    end
                    % params_string = [params_string, sprintf('%s\t\t%f \x00b1 %f\n', char(name_params(i)), fitted_params(i), std_err(i))];
                end
            end

            finish_string = sprintf('%s\n', repmat('*', 1, 74));
            
            if nargin<2
                % Logging the RMSE and r2 values for all models if auto
                % mode is chosen
                if app.auto_iso_mode
                   app.update_textbox_func(app.OutputText, initial_string);
                end
                % Logging the starting string
                app.update_textbox_func(app.OutputText, heading_string);
                
                % Logging the isotherm model used for fitting
                app.update_textbox_func(app.OutputText, ...
                    iso_model_string);
                
                % Logging the fitting statistics       
                app.update_textbox_func(app.OutputText, stats_string);
                
                % Logging the parameters string
                app.update_textbox_func(app.OutputText, ...
                                        params_string);
                 
                % Logging the finishing string
                app.update_textbox_func(app.OutputText, finish_string);
            
            else
                % Logging the starting string
                fprintf(fileID, '%s\n',heading_string);       
                
                % Logging the isotherm model used for fitting
                fprintf(fileID, '%s\n', iso_model_string);
                
                % Logging the fitting statistics
                fprintf(fileID, '%s\n', stats_string);
                
                % Logging the parameters string
                fprintf(fileID, '%s\n', params_string);
                 
                % Logging the finishing string
                fprintf(fileID, '%s\n', finish_string);           
            end
        end
        %
        %% Function for plotting isotherm data
        function [] = update_plot_func(app, pressure_data, loading_data, T_flag)
                isotherm_plots(app.UIAxes, pressure_data, loading_data, T_flag, [], app.isotherm_struc, ...
                               app.xaxisCheckBox.Value, app.yaxisCheckBox.Value, app.unit_pressure, app.unit_loading, '', app.P_saturation);
                % app.UIAxes.Toolbar.HandleVisibility = 'off';
                app.UIAxes.Toolbar.HandleVisibility = 'on';
        end
        %
        %% Function to set the working directory
        function [] = set_work_dir(app)
            new_path = uigetdir(app.work_dir, 'Select working directory');
            
            % Command for refocusing parent app
            figure(app.AIMIsoFitUIFigure);
            
            if new_path
                app.work_dir = new_path;
            end
        end
        %
    end
    
    methods (Access = public)
        %% Updating the settings dialogue box properties
        function varargout = update_iso_setting(app)
            % Updating the initial guess, upper and lower bounds matrices
            num_max_iso_params = 6;         % Correspsonds to number of params in DS-Langmuir-Freundlich and DD model
            num_max_iso_models = 13;        % Correspsonds to number of isotherm models availbale in BREAKLAB
            
            app.initial_guess = zeros(num_max_iso_params, num_max_iso_models);
            app.upper_bounds = zeros(size(app.initial_guess));
            app.lower_bounds = zeros(size(app.initial_guess));
            app.num_par_all_iso = zeros(1, num_max_iso_models);
            
            % Removing Auto model from dropdown entries for looping purpose
            isotherm_models = app.IsothermModelDropDown.Items;
            idx_to_retain = ~strcmpi(isotherm_models, 'Auto');
            isotherm_models = isotherm_models(idx_to_retain);
            
            for i=1:numel(isotherm_models)
                current_model = isotherm_models(i); 
                if ~isempty(app.iso_data)
                    iso_fit_opts = isotherm_fit_opt(char(current_model), app.iso_data(:, 2), app.iso_data(:, 1));
                else
                    % Isotherm data does not exist and hence the saturation
                    % loading value guess would be initialized as '1' in isotherm_fit_options 
                    iso_fit_opts = isotherm_fit_opt(char(current_model), 1);
                end
                
                app.initial_guess(1:iso_fit_opts.num_params, i) = iso_fit_opts.guess';
                app.lower_bounds(1:iso_fit_opts.num_params, i) = iso_fit_opts.lb';
                app.upper_bounds(1:iso_fit_opts.num_params, i) = iso_fit_opts.ub';
                
                app.num_par_all_iso(i) = iso_fit_opts.num_params;                
            end
            % Passing output arguments
            if nargout == 3
                varargout{1} = app.initial_guess;
                varargout{2} = app.lower_bounds;
                varargout{3} = app.upper_bounds;
            end       
        end
        %
        %% Progress Bar Function
        function dialogue = progress(app)
            dialogue = uiprogressdlg(app.AIMIsoFitUIFigure, "Title", "Please Wait",...
                  "Message","Fitting Isotherm",...
                  "Cancelable","off", "Indeterminate","on");
                drawnow   
        end
        %
        %% Running the fitting function
        function [] = run_fit_function(app)
            % To run the isotherm fitting function
            if app.iso_data_import_flag
                app.iso_model = app.IsothermModelDropDown.Value;
                
                if strcmpi(app.iso_model, 'Auto')
                    app.auto_iso_mode = 1;
                    % Never use multistart option for Auto mode to avoid excessive computation time
                    app.use_multi = 0;
                else
                    app.auto_iso_mode = 0;
                end

                % app.iso_data = app.IsothermDataTable.Data;
                pressure_data = app.iso_data(:, 1);
                loading_data = app.iso_data(:, 2);
                
                % Initializing progress bar
                progress_bar = app.progress();
                
                try
                    app.isotherm_struc = Isotherm_fitting(app.iso_model, pressure_data, loading_data, app.initial_guess, ...
                                                        app.lower_bounds, app.upper_bounds, app.iso_fit_options.Weights, 'false', ...
                                                        app.iso_fit_options.algorithm_id, app.iso_models_av, app.P_saturation, app.use_multi);
                    fitted_isotherm_model = app.isotherm_struc.name;
                    fitted_params = app.isotherm_struc.fitted_params;
                    
                    progress_bar.Message = "Fitting Finished...";
                    % Updating isotherm model and isotherm model expression (useful in case in case
                    % user chooses auto model)
                    app.iso_model = fitted_isotherm_model;
                    app.IsothermModelDropDown.Value = app.iso_model;            
                    app.update_iso_expression(fitted_params);  
                    
                    % Writing isotherm params on message panel
                    app.print_params()
                    
                    % Setting the fitting state on after successful fitting
                    app.iso_fit_finish_flag = 1;
    
                    % Plotting the fitted isotherm
                    app.update_plot_func(pressure_data, loading_data, app.T_flag);
    
                catch ME1
                    progress_bar.Message = "Error Occured. Check the Message Panel for detailed information.";
                    pause(2);
                    close(progress_bar);

                    app.update_textbox_func(app.OutputText, ...
                                            sprintf('Error occured in isotherm fitting...!\nError message: %s', ME1.message));
                end

                if ~isempty(progress_bar)
                    close(progress_bar);
                end
            else
                app.update_textbox_func(app.OutputText, ...
                                        sprintf('Please import the isotherm data first ...!'));
            end
        end
        %
        %% Function to adjust the size of figure
        function [] = adjust_size(app)
            app.AIMIsoFitUIFigure.Units = "normalized";
            app.AIMIsoFitUIFigure.Position(1) = 0.1;
            app.AIMIsoFitUIFigure.Position(2) = 0.05;
            app.AIMIsoFitUIFigure.Units = "pixels";
        end
        %
        %% Function to update the uifigure for different devices
        function [] = adjust_uifig(app)
            % Get the device resolution
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            
            default_position = app.AIMIsoFitUIFigure.Position;
            
            if default_position(3) > screenWidth || default_position(4) > screenHeight
                left = screenWidth*0.1;
                bottom = screenHeight*0.1;
                width = screenWidth*0.85;
                height = screenHeight*0.85;
                drawnow;
                app.AIMIsoFitUIFigure.Position = [left bottom width height];
            end            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.adjust_size();
            app.adjust_uifig();
            % The default working directory of the app will be set to
            % current working directory 
            app.work_dir = pwd;
            app.startup_func();

            % Printing welcome message
            intro_string = get_intro_string("IsoFit");
            app.update_textbox_func(app.OutputText, intro_string);
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            app.unit_pressure = '';
            app.unit_loading = '';
            app.import_iso_data();
        end

        % Button pushed function: FitIsothermButton
        function FitIsothermButtonPushed(app, event)
            app.run_fit_function();
        end

        % Value changed function: IsothermModelDropDown
        function IsothermModelDropDownValueChanged(app, event)
            app.iso_model = app.IsothermModelDropDown.Value;
            app.update_iso_expression();
            app.update_sat_p();
        end

        % Button pushed function: ExportParametersasblisoButton
        function ExportParametersasblisoButtonPushed(app, event)
            % Check if the fitting has been completed successfully
            if app.iso_fit_finish_flag
                filter = {'*.bliso', 'AIM Isotherm Files'};
                default_file_name = fullfile(app.work_dir, 'my_isotherm_data.bliso');
                [file, path, indx] = uiputfile(filter, 'Select file for save as', ...
                                        default_file_name);
                
                % Command for refocusing parent app
                figure(app.AIMIsoFitUIFigure);

                if indx
                    filename = fullfile(path, file);
                    
                    fileID = fopen(filename, "w");

                    app.print_params(fileID);
                    fclose(fileID);
                end
            end
        end

        % Value changed function: xaxisCheckBox
        function xaxisCheckBoxValueChanged(app, event)
            if app.iso_data_import_flag
                pressure_data = app.iso_data(:, 1);
                loading_data = app.iso_data(:, 2);   
                app.update_plot_func(pressure_data, loading_data, app.T_flag);
            end
        end

        % Value changed function: yaxisCheckBox
        function yaxisCheckBoxValueChanged(app, event)
            if app.iso_data_import_flag
                pressure_data = app.iso_data(:, 1);
                loading_data = app.iso_data(:, 2);
                app.update_plot_func(pressure_data, loading_data, app.T_flag);
            end
        end

        % Button pushed function: IsothermFittingSettingsButton
        function IsothermFittingSettingsButtonPushed(app, event)
            app.settings_app = iso_fit_settings(app);
        end

        % Close request function: AIMIsoFitUIFigure
        function AIMIsoFitUIFigureCloseRequest(app, event)
            delete(app.settings_app);
            delete(app.p_sat_info);
            
            YN = uiconfirm(app.AIMIsoFitUIFigure,'Do you want to close the app?', 'Close request', 'Icon','warning');
            if strcmpi(YN,'OK')
                delete(app)
            end
        end

        % Cell edit callback: IsothermDataTable
        function IsothermDataTableCellEdit(app, event)
            indices = event.Indices;   

            if ~isempty(event.Error) || ~isnumeric(event.NewData) || isnan(event.NewData)
                % Retaining the old data 
                app.IsothermDataTable.Data(indices(1), indices(2)) = event.PreviousData;
            else
                % Updating the isotherm data table with the new data
                app.IsothermDataTable.Data(indices(1), indices(2)) = event.NewData;
                app.iso_data = app.IsothermDataTable.Data;
                
                % Updating the isotherm plot
                pressure_data = app.iso_data(:, 1);
                loading_data = app.iso_data(:, 2);
                
                app.update_plot_func(pressure_data, loading_data, app.T_flag);
            end  
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.startup_func();
        end

        % Value changed function: SaturationPressureEditField
        function SaturationPressureEditFieldValueChanged(app, event)
            app.P_saturation = app.SaturationPressureEditField.Value;
            app.update_sat_p();
        end

        % Image clicked function: QuestionImage
        function QuestionImageClicked(app, event)
            app.p_sat_info = p_sat_info();
        end

        % Button pushed function: SetWorkspaceButton
        function SetWorkspaceButtonPushed(app, event)
            app.set_work_dir();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AIMIsoFitUIFigure = uifigure('Visible', 'off');
            app.AIMIsoFitUIFigure.Name = 'AIM IsoFit';
            app.AIMIsoFitUIFigure.Icon = fullfile(pathToMLAPP, 'AIM_logo.png');
            app.AIMIsoFitUIFigure.CloseRequestFcn = createCallbackFcn(app, @AIMIsoFitUIFigureCloseRequest, true);
            app.Panel = uipanel(app.AIMIsoFitUIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.OutputPanel = uipanel(app.GridLayout);
            app.OutputPanel.Title = 'Output';
            app.OutputText = uitextarea(app.OutputPanel);
            app.OutputText.Editable = 'off';
            app.PlotPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.PlotPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes, 'Pressure')
            ylabel(app.UIAxes, 'Gas Uptake')
            zlabel(app.UIAxes, 'Z')
            app.Panel_2 = uipanel(app.GridLayout2);
            app.LogarithmicaxisLabel = uilabel(app.Panel_2);
            app.LogarithmicaxisLabel.Text = 'Logarithmic axis';
            app.xaxisCheckBox = uicheckbox(app.Panel_2);
            app.xaxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @xaxisCheckBoxValueChanged, true);
            app.xaxisCheckBox.Text = 'x axis';
            app.yaxisCheckBox = uicheckbox(app.Panel_2);
            app.yaxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @yaxisCheckBoxValueChanged, true);
            app.yaxisCheckBox.Text = 'y axis';
            app.DataInputPanel = uipanel(app.GridLayout);
            app.DataInputPanel.Title = 'Data Input';
            app.ImportDataButton = uibutton(app.DataInputPanel, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.IsothermModelDropDownLabel = uilabel(app.DataInputPanel);
            app.IsothermModelDropDownLabel.Text = 'Isotherm Model ';
            app.IsothermModelDropDown = uidropdown(app.DataInputPanel);
            app.IsothermModelDropDown.Items = {'Auto', 'SS-Langmuir', 'DS-Langmuir', 'SS-Langmuir-Freundlich', 'DS-Langmuir-Freundlich', 'Quadratic', 'Temkin', 'BET', 'Sips', 'Toth', 'Dubinin-Astakhov', 'Klotz', 'Do-Do'};
            app.IsothermModelDropDown.ValueChangedFcn = createCallbackFcn(app, @IsothermModelDropDownValueChanged, true);
            app.IsothermModelDropDown.Placeholder = 'Choose the Isotherm model';
            app.IsothermModelDropDown.Value = 'Auto';
            app.IsothermFittingSettingsButton = uibutton(app.DataInputPanel, 'push');
            app.IsothermFittingSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @IsothermFittingSettingsButtonPushed, true);
            app.IsothermFittingSettingsButton.Text = 'Isotherm Fitting Settings';
            app.IsothermDataTable = uitable(app.DataInputPanel);
            app.IsothermDataTable.ColumnName = {'Pressure'; 'Gas Uptake'};
            app.IsothermDataTable.RowName = {};
            app.IsothermDataTable.ColumnEditable = true;
            app.IsothermDataTable.CellEditCallback = createCallbackFcn(app, @IsothermDataTableCellEdit, true);
            app.ExportParametersasblisoButton = uibutton(app.DataInputPanel, 'push');
            app.ExportParametersasblisoButton.ButtonPushedFcn = createCallbackFcn(app, @ExportParametersasblisoButtonPushed, true);
            app.ExportParametersasblisoButton.Text = 'Export Parameters as *.bliso';
            app.ResetButton = uibutton(app.DataInputPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SaturationPressureEditField = uieditfield(app.DataInputPanel, 'numeric');
            app.SaturationPressureEditField.Limits = [0 Inf];
            app.SaturationPressureEditField.ValueChangedFcn = createCallbackFcn(app, @SaturationPressureEditFieldValueChanged, true);
            app.QuestionImage = uiimage(app.DataInputPanel);
            app.QuestionImage.ImageClickedFcn = createCallbackFcn(app, @QuestionImageClicked, true);
            app.QuestionImage.Tooltip = {'Help'};
            app.QuestionImage.ImageSource = fullfile(pathToMLAPP, 'question.png');
            app.SatPressureLabel = uilabel(app.DataInputPanel);
            app.SatPressureLabel.Interpreter = 'latex';
            app.SatPressureLabel.Text = {'Saturation'; 'Pressure'};
            app.SetWorkspaceButton = uibutton(app.DataInputPanel, 'push');
            app.SetWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SetWorkspaceButtonPushed, true);
            app.SetWorkspaceButton.Text = 'Set Workspace';
            app.ExpressionLabel = uilabel(app.DataInputPanel);
            app.ExpressionLabel.Interpreter = 'latex';
            app.ExpressionLabel.Text = 'Expression';
            app.FitIsothermButton = uibutton(app.DataInputPanel, 'push');
            app.FitIsothermButton.ButtonPushedFcn = createCallbackFcn(app, @FitIsothermButtonPushed, true);
            app.FitIsothermButton.Text = 'Fit Isotherm';
            % Show the figure after all components are created
            app.AIMIsoFitUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Isotherm_fit_GUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AIMIsoFitUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.AIMIsoFitUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AIMIsoFitUIFigure)
        end
    end
end