classdef Isotherm_fit_GUI_diffT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AIMHeatFitUIFigure             matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        DataInputPanel                 matlab.ui.container.Panel
        SetWorkspaceButton             matlab.ui.control.Button
        heatExp                        matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        TempTable                      matlab.ui.control.Table
        T_refEditField                 matlab.ui.control.NumericEditField
        T_refEditFieldLabel            matlab.ui.control.Label
        ExportParametersasblisoButton  matlab.ui.control.Button
        ExpressionLabel                matlab.ui.control.Label
        IsothermDataTable              matlab.ui.control.Table
        IsothermFittingSettingsButton  matlab.ui.control.Button
        FitIsothermButton              matlab.ui.control.Button
        IsothermModelDropDown          matlab.ui.control.DropDown
        IsothermModelDropDownLabel     matlab.ui.control.Label
        ImportDataButton               matlab.ui.control.Button
        PlotPanel                      matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        IsothermTab                    matlab.ui.container.Tab
        IsothermAxes                   matlab.ui.control.UIAxes
        HeatofAdsorptionTab            matlab.ui.container.Tab
        HeatAxes                       matlab.ui.control.UIAxes
        Panel_2                        matlab.ui.container.Panel
        yaxisCheckBox                  matlab.ui.control.CheckBox
        xaxisCheckBox                  matlab.ui.control.CheckBox
        LogarithmicaxisLabel           matlab.ui.control.Label
        OutputPanel                    matlab.ui.container.Panel
        OutputText                     matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        caller                  % Main app handle
        settings_app      % App handle for viewing and saving fitting parameters
        iso_fit_finish_flag     % Whether isotherm fit has been successfully finished or not
        iso_fit_options         % Isotherm fitting options
        isotherm_struc          % Isotherm structure conatining information about fitting
        auto_iso_mode           % If auto mode is used for isotherm
        unit_pressure           % Pressure units in case they are specified in the data file
        unit_loading            % Loading units in case they are specified in the data file
        unit_temperature        % Temperature units in case they are specified in the aif data file
        unit_dH                 % Unit for Heat of Adsorption

        % All the isotherm models available in the app except Virial
        iso_models_av = {'SS-Langmuir', 'DS-Langmuir',...
                         'SS-Langmuir-Freundlich', 'DS-Langmuir-Freundlich',...
                         'Quadratic', 'Temkin', 'BET',...
                         'Sips', 'Toth'};

        % Temperature based fitting flags
        T_array                 % Array containing temperature values
        num_T                   % Number of different temperatures values used in the fitting  
        T_ref                   % Reference temperature for fitting
        iso_T_import_flag       % Whether temepratures for isotherms are imported successfully
        heat_exp = ['$$P_{norm} = P \times \Theta,'...
                    '\quad\Theta=e^{\left(\frac{-\Delta H}{R}\left(\frac{1}{T}-\frac{1}{T_{ref}}\right)\right)}$$'];

    end
    
    properties (Access = public)
        T_flag                  % Temeprature dependence flag, always one for multiple temperature isotherm fitting
        iso_data                % Isotherm data from table
        iso_model               % Chosen isotherm model
        iso_data_import_flag    % Whether data is imported or not
        
        % Properties related to settings dialogue box
        initial_guess               % Inital guess matrix
        upper_bounds                % Upper bound matrix
        lower_bounds                % Lower bound matrix
        num_par_all_iso             % Number of parameters in isotherm model
        def_dH_theta_guess_bounds   % default dH and theta guess and bounds
        use_multi                   % Flag for fitting using multistart option 

        % Virial Equation specific variables
        vir_flag             % Virial Flag
        vir_num_a            % Number of a parameters
        vir_num_b            % Number of b parameters
        vir_init_guess       % Initial guess vector
        vir_ub               % Upper bound vector
        vir_lb               % lower bound vector

        work_dir             % Working directory 
    end
    
    methods (Access = private)
        %% Function for startup and reset
        function [] = startup_func(app)
            app.iso_data_import_flag = 0;
            app.IsothermDataTable.Data = cell(2, 2);
            app.IsothermDataTable.ColumnEditable = false;
            app.iso_fit_options.Weights = 'Uniform';
            app.iso_fit_options.algorithm_id = 'auto';
            app.iso_model = 'SS-Langmuir';
            
            app.iso_fit_finish_flag = 0;
            app.isotherm_struc = struct([]);
            app.iso_data = [];
            
            % Temperature based fitting
            app.T_flag = 1;
            app.num_T = 0;
            app.T_array = [];
            app.T_ref = [];
            app.iso_T_import_flag = 0;

            % Setting the Virial flag
            app.vir_flag = 0;

            % Specifying reference temperature in refrence temperature
            % field
            app.T_refEditField.Value = 0;

            % setting the plot log axis values off
            app.xaxisCheckBox.Value = 0;
            app.yaxisCheckBox.Value = 0;
            
            % Setting the default values of theta and dH guesses and bounds
            app.def_dH_theta_guess_bounds = [0, -10e04, 0;...
                                             1, 0, 1000];

            % Clearing the message panel
            app.OutputText.Value = '';

            % Initializong units
            app.unit_pressure = 'Pa';
            app.unit_loading = 'mol/kg';
            app.unit_temperature = 'K';
            app.unit_dH = 'kJ/mol';

            app.TempTable.ColumnName = {'Temperature Values'};
            app.TempTable.Data = 0;
            app.IsothermDataTable.ColumnName = {'Pressure', 'Gas Uptake'};
            app.IsothermDataTable.Data = zeros(3, 2);
            
            % The dropdown contains all the model availablae in app and
            % default value is SS-Langmuir
            app.IsothermModelDropDown.Items = [{'Auto'}, app.iso_models_av, {'Virial'}];
            app.IsothermModelDropDown.Value = app.iso_model;
            
            % Updating Isotherm Expression
            app.update_iso_expression();

            % Updating the isotherm setting matrices
            app.update_iso_setting();

            % Clearing all plots on axis
            cla(app.IsothermAxes);
            cla(app.HeatAxes);

            % Formatting Heat Axes ylabel
            app.HeatAxes.YLabel.String = strcat(char(8722), '\DeltaH_{ads} (kJ/mol)');
            app.HeatAxes.YLabel.Interpreter = 'tex';

            % By default no use of multistart option while fitting
            app.use_multi = 0;
        end
        %% Function for updating text box and data logging
        function [] = update_textbox_func(~, app_comp, message)
            if strcmpi(app_comp.Value, '')
                updated_message = message;
            else
                updated_message = [app_comp.Value(:)', {''}, {message}];
            end
            app_comp.Value = updated_message;
            scroll(app_comp, 'bottom');
        end
        %
        %% Function for updating isotherm expression
        function [] = update_iso_expression(app, fit_params)
            if nargin<2
                fit_params = [];
            end
            chosen_model = app.iso_model;
            if strcmpi(chosen_model, 'Auto')
                app.ExpressionLabel.Text = {''};
            elseif app.vir_flag
                opt = iso_params_parsing(chosen_model, fit_params);
                app.ExpressionLabel.Text = opt.express;
                app.heatExp.Visible = "off";
            else
                opt = iso_params_parsing(chosen_model, fit_params);
                app.ExpressionLabel.Text = strrep(opt.express, 'P', 'P_{norm}');
                app.heatExp.Visible = "on";
                app.heatExp.Text = app.heat_exp;
                if strcmpi(app.iso_model, "Temkin")
                    app.ExpressionLabel.FontSize = 11;
                    app.heatExp.FontSize = 11;
                else
                    app.ExpressionLabel.FontSize = 14;
                    app.heatExp.FontSize = 14;
                end
            end
        end
        %
        %% Function for updating Table formatting
        function [] = update_table(app)
            % Naming columns of temperature table
            col_names = cell(1, app.num_T);
            
            for i=1:app.num_T
                col_names(:, i) = {sprintf('T%d', i)};
            end
    
            app.TempTable.ColumnName = col_names;
    
            % Populating Temperature table with temperature values
            app.TempTable.Data = app.T_array;
            
            % Temperature import flag 
            if all(app.T_array>0)
                app.iso_T_import_flag = 1;
            else
                app.iso_T_import_flag = 0;
            end

            % Specifying reference temperature in refrence temperature
            % field
            app.T_refEditField.Value = min(app.T_array);
    
            % Assigning column names of isotherm data table based on temeprature values read.
            col_names = cell(2, app.num_T);
            
            for i=1:app.num_T
                col_names(:, i) = {sprintf('Pressure %d', i); sprintf('Gas Uptake %d', i)};
            end
            
            app.IsothermDataTable.ColumnName = reshape(col_names, 1, []);
            
            % Assigning read data to data table after masking NaN values
            display_data = num2cell(app.iso_data);
            display_data(isnan(app.iso_data)) = {[]};
            app.IsothermDataTable.Data = display_data;
        end
        %% Function to parse and read isotherm data file with AIF format
        function [isotherm_data_read, temperature] = read_AIF(app, filename)
       
            % Calling AIF file parsing function
            aif_data = parse_aif(filename);
            
            idx_pressure = find(strcmpi(aif_data.loop_keys, '_adsorp_pressure'));
            idx_loading = find(strcmpi(aif_data.loop_keys, '_adsorp_amount'));
            
            % Specifying isotherm data
            isotherm_data_read = aif_data.loop_data(:, [idx_pressure, idx_loading]);
            
            if isConfigured(aif_data.meta_data)
                % Specfying isotherm units
                app.unit_pressure = lookup(aif_data.meta_data, "_units_pressure", "FallbackValue", '');
                app.unit_loading = lookup(aif_data.meta_data, "_units_loading", "FallbackValue", '');
                app.unit_temperature = lookup(aif_data.meta_data, "_units_temperature", "FallbackValue", '');
                % Checking for temperature tag return 0 if no key value exist
                % or temperature value is not specified in AIF file
                temperature = lookup(aif_data.meta_data, "_exptl_temperature",  "FallbackValue", 0);
                temperature = double(string(temperature));
    
                if ~temperature
                    app.update_textbox_func(app.OutputText, (['Temperature values are not found in the data file. ' ...
                                                                'Please provide the temperature values.']))
                else
                    if isnan(temperature)
                        app.update_textbox_func(app.OutputText, (['Invalid temperature value found in the data file. ' ...
                                                                'Please provide the temperature values in the table.']))
                        temperature = 0;
                    end
                end
            else
                temperature = 0;
            end
        end
        %
        %% Function to parse and read isotherm data files for other formats except AIF.
        function [isotherm_data_read, temperature] = read_file(app, filename)
            
            % Calling File parsing function
            file_data = parse_file(filename);
            
            % Specifying isotherm data
            isotherm_data_read = file_data.data;
            
            if isConfigured(file_data.meta_data)
                % Specfying isotherm units
                app.unit_pressure = lookup(file_data.meta_data, "#units_pressure", "FallbackValue", '');
                app.unit_loading = lookup(file_data.meta_data, "#units_loading", "FallbackValue", '');
                app.unit_temperature = lookup(file_data.meta_data, "#units_temperature", "FallbackValue", '');
                       
                % Checking for temperature tag return 0 if no key value exist
                % or temperature value is not specified in AIF file
                temperature = lookup(file_data.meta_data, "#temperature",  "FallbackValue", 0);
                temperature = double(string(temperature));
    
                if ~temperature
                    app.update_textbox_func(app.OutputText, (['Temperature values are not found in the data file. ' ...
                                                                'Please provide the temperature values.']))
                else
                    if isnan(temperature)
                        app.update_textbox_func(app.OutputText, (['Invalid temperature value found in the data file. ' ...
                                                                'Please provide the temperature values in the table.']))
                        temperature = 0;
                    end
                end
            else
                temperature = 0;
            end
        end
        %
        %% Function for handling isotherm data file
        function [] = handle_iso_data_file(app, data_filename)
            try 
                % Check the extension for aif
                if endsWith(data_filename, '.aif')
                    [isotherm_data, temperature_read] = app.read_AIF(data_filename);
                else
                    [isotherm_data, temperature_read] = app.read_file(data_filename);
                    if size(isotherm_data, 2) > 2
                            app.update_textbox_func(app.OutputText, sprintf(['Data file contains more than two columns.' ...
                                                             'Only first two columns were loaded...']));
                        isotherm_data = isotherm_data(:, 1:2);
                    end
                end
            
            catch ME1
                error("Failed to read isotherm data file.\nDetailed error message: %s", ME1.message);
            end
            
            % NaN values will be inserted to tackle inconsisitency in
            % number of pressure values in different files.
            if  ~isempty(app.iso_data) && ~(size(app.iso_data, 1) == size(isotherm_data, 1))
                num_row_diff = size(app.iso_data, 1) - size(isotherm_data, 1);
                if num_row_diff>0
                    isotherm_data = vertcat(isotherm_data, NaN(num_row_diff, size(isotherm_data, 2)));
                elseif num_row_diff<0
                    app.iso_data = vertcat(app.iso_data, NaN(abs(num_row_diff), size(app.iso_data, 2)));
                end
            end

            % Appending the temperature array and number of temperature
            app.num_T = 1 + app.num_T;
            app.T_array = horzcat(app.T_array, temperature_read);
            
            % Appending the read data to isotherm data property
            app.iso_data = horzcat(app.iso_data, isotherm_data);     
            
            % Updating tables
            app.update_table()   
        end
        %
        %% Function for preparing isotherm data file for reading
        function [] = prepare_iso_file(app, path, file)
            try
                filename = fullfile(path, file);
    
                % app.read_iso_data_table(filename);
                app.handle_iso_data_file(filename);
    
                app.update_textbox_func(app.OutputText, sprintf('Data imported successfully...!'));
                app.IsothermDataTable.ColumnEditable = true;
    
                % Plotting the isotherm isotherm data
                pressure_data = app.iso_data(:, 1:2:end);
                loading_data = app.iso_data(:, 2:2:end);
                app.update_plot_func(pressure_data, loading_data, app.T_flag);
                
                % Resetting the isotherm fitting state and isotherm struc only if the data is loaded successfully
                app.iso_fit_finish_flag = 0; 
                app.isotherm_struc = struct([]);
                
                % Setting the data import state on after successfull data upload 
                app.iso_data_import_flag = 1;

            catch ME1
                app.update_textbox_func(app.OutputText, ...
                                    sprintf('Error occured while reading the file: %s...!\nError message: %s', file, ME1.message))
            end
        end
        %% Function for importing isotherm data
        function [] = import_iso_data(app)
        % filter = {'*.csv; *.txt; *.xlsx; .dat', 'Data files'};
            filter = {'*.csv'; '*.aif'; '*.txt'; '*.xlsx'; '.dat'};
            default_file_name = fullfile(app.work_dir, 'my_isotherm_data.csv');
            [files, path, indx] = uigetfile(filter, 'Select atleast two isotherm data files', ...
                                            default_file_name, 'MultiSelect','on');
            
            % Command for refocusing parent app
            figure(app.AIMHeatFitUIFigure);

            if indx
                if iscell(files)
                    % If user selects multiple files
                    for i=1:numel(files)
                        current_file = char(files(i));
                        app.prepare_iso_file(path, current_file)
                    end
                elseif isempty(app.iso_data)
                    % If user selects single files and isotherm data is
                    % empty
                    app.update_textbox_func(app.OutputText, ...
                                    sprintf('Please select atleast two isotherm data file'))
                else
                    app.prepare_iso_file(path, files)
                end

                % Updating the guess, lb and ub matrices
                app.update_iso_setting();
            end
        end
        %% Function for printing isotherm params on message panel/to file for all models except Virial
        function [] = print_params(app, fileID)
            
            fitted_params = app.isotherm_struc.fitted_params;
            RMSE = app.isotherm_struc.RMSE;
            r2 = app.isotherm_struc.r2;
            dH = app.isotherm_struc.dH ./ 1e03;     % Conversion from J/mol to kJ/mol
            % dH = -1.*dH;                            % For reporting purpose
            iso_std_err = app.isotherm_struc.std_error;
 
            if app.auto_iso_mode
                RMSE_all = app.isotherm_struc.RMSE_all;
                r2_all = app.isotherm_struc.r2_all;
                stats_all = [RMSE_all', r2_all'];

                isotherm_models = string(app.IsothermModelDropDown.Items);
                idx_to_retain = ~strcmpi(isotherm_models, 'Auto');
                isotherm_models = isotherm_models(idx_to_retain)';
                maxLength = max(strlength(isotherm_models));

                initial_string = [sprintf('Fitting using different isotherm\n'),...
                                  sprintf('%-*s\t\tRMSE\t\tr2\n', maxLength, 'Isotherm Model')];
                for j=1:length(isotherm_models)
                      initial_string = [initial_string, sprintf('%-*s\t%f\t%f\n', maxLength, isotherm_models(j), stats_all(j, :))];
                end
            end

            heading_string = [sprintf('%s\n', repmat('*', 1, 73)),...
                              sprintf('%sHeatFit ISOTHERM FITTING RESULTS%s\n', repmat('*', 1, 20), repmat('*', 1, 20)),...
                              sprintf('%s\n', repmat('*', 1, 73))];
            
            iso_model_string = sprintf('Isotherm Model: %s\n', app.iso_model);
            
            % Defining strings which differs for Virial equation
            % and other isotherm model
            if ~app.vir_flag
                dH_std_err = app.isotherm_struc.dH_std_error./ 1e03; % Conversion from J/mol to kJ/mol
                ref_temp_string = sprintf('Reference Temperature: %.2f\n', app.T_ref);
                dH_RMSE = app.isotherm_struc.dH_RMSE;
                stats_string = sprintf(['Isotherm fit RMSE:\t%f\n' ...
                                        'Isotherm fit r^2:\t%f\n' ...
                                        'dH fit RMSE:\t%f\n'], RMSE, r2, dH_RMSE);

                % Calling pars function to get params name
                pars_params = iso_params_parsing(app.iso_model, fitted_params);
                name_params = pars_params.name_params;
                params_string = [sprintf('Parameters\n')];
                for i=1:length(fitted_params)
                    if fitted_params(i)<0.1         % For scientific notation
                        if iso_std_err(i)<0.0001
                            params_string = [params_string, sprintf('%s\t\t%e \x00b1 %e\n', char(name_params(i)), fitted_params(i), iso_std_err(i))];
                        else
                            params_string = [params_string, sprintf('%s\t\t%e \x00b1 %f\n', char(name_params(i)), fitted_params(i), iso_std_err(i))];
                        end
                        % params_string = [params_string, sprintf('%s\t\t%e \x00b1 %f\n', char(name_params(i)), fitted_params(i), iso_std_err(i))];
                    else
                        if iso_std_err(i)<0.0001
                            params_string = [params_string, sprintf('%s\t\t%f \x00b1 %e\n', char(name_params(i)), fitted_params(i), iso_std_err(i))];
                        else
                            params_string = [params_string, sprintf('%s\t\t%f \x00b1 %f\n', char(name_params(i)), fitted_params(i), iso_std_err(i))];
                        end
                        % params_string = [params_string, sprintf('%s\t\t%f \x00b1 %f\n', char(name_params(i)), fitted_params(i), iso_std_err(i))];
                    end
                end
                if dH_std_err < 0.0001
                    params_string = [params_string, sprintf('dH\t\t%e \x00b1 %e', dH, dH_std_err)];
                else
                    params_string = [params_string, sprintf('dH\t\t%e \x00b1 %f', dH, dH_std_err)];
                end
                % params_string = [params_string, sprintf('dH\t\t%e \x00b1 %f', dH, dH_std_err)];
            else
                % Strings specific for Virial equation
                dH_inf = app.isotherm_struc.dH_inf./1e03;                 % Conversion from J/mol to kJ/mol
                % dH_inf = -1.*dH_inf;                                      % For reporting purpose
                dH_std_err = app.isotherm_struc.dH_inf_std_error./1e03;   % Conversion from J/mol to kJ/mol
                % dH_std_err = -1.*dH_std_err;                              % For reporting purpose

                ref_temp_string = sprintf('Reference Temperature: %s\n', 'None');
                stats_string = sprintf('Isotherm fit RMSE:\t%f\nIsotherm fit r^2:\t%f\n', RMSE, r2);
                params_string = [sprintf('Parameters\n')];

                % "a" parameters
                for i=1:app.vir_num_a
                    if abs(fitted_params(i)) < 0.1 || abs(fitted_params(i)) > 1e02
                        if iso_std_err(i) < 0.0001
                            params_string = [params_string, sprintf('a_%d\t\t%e \x00b1 %e\n', i-1, fitted_params(i), iso_std_err(i))];
                        else
                            params_string = [params_string, sprintf('a_%d\t\t%e \x00b1 %f\n', i-1, fitted_params(i), iso_std_err(i))];
                        end
                        % params_string = [params_string, sprintf('a_%d\t\t%e \x00b1 %f\n', i-1, fitted_params(i), iso_std_err(i))];
                    else
                        if iso_std_err(i) < 0.0001
                            params_string = [params_string, sprintf('a_%d\t\t%f \x00b1 %e\n', i-1, fitted_params(i), iso_std_err(i))];
                        else
                            params_string = [params_string, sprintf('a_%d\t\t%f \x00b1 %f\n', i-1, fitted_params(i), iso_std_err(i))];
                        end
                        % params_string = [params_string, sprintf('a_%d\t\t%f \x00b1 %f\n', i-1, fitted_params(i), iso_std_err(i))];
                    end
                end
                
                % "b" parameters
                for j=1:app.vir_num_b
                    idx_b = app.vir_num_a+j;
                    if abs(fitted_params(idx_b)) < 0.1 || abs(fitted_params(idx_b)) > 1e02
                        if iso_std_err(idx_b) < 0.0001
                            params_string = [params_string, sprintf('b_%d\t\t%e \x00b1 %e\n', j-1, fitted_params(idx_b), iso_std_err(idx_b))];
                        else
                            params_string = [params_string, sprintf('b_%d\t\t%e \x00b1 %f\n', j-1, fitted_params(idx_b), iso_std_err(idx_b))];
                        end
                        % params_string = [params_string, sprintf('b_%d\t\t%e \x00b1 %f\n', j-1, fitted_params(idx_b), iso_std_err(idx_b))];
                    else
                        if iso_std_err(idx_b) < 0.0001
                            params_string = [params_string, sprintf('b_%d\t\t%f \x00b1 %e\n', j-1, fitted_params(idx_b), iso_std_err(idx_b))];
                        else
                            params_string = [params_string, sprintf('b_%d\t\t%f \x00b1 %f\n', j-1, fitted_params(idx_b), iso_std_err(idx_b))];
                        end
                        % params_string = [params_string, sprintf('b_%d\t\t%f \x00b1 %f\n', j-1, fitted_params(idx_b), iso_std_err(idx_b))];
                    end
                end
                % params_string = [params_string, sprintf('dH\t\t%e\n', mean(dH))];
                % params_string = [params_string, sprintf('dH_inf\t\t%e \x00b1 %f', dH_inf, dH_std_err)];
                if dH_std_err < 0.0001
                    params_string = [params_string, sprintf('dH_inf\t\t%e \x00b1 %e', dH_inf, dH_std_err)];
                else
                    params_string = [params_string, sprintf('dH_inf\t\t%e \x00b1 %f', dH_inf, dH_std_err)];
                end
            end
            
            finish_string = sprintf('%s\n', repmat('*', 1, 73));
            
            if nargin<2
                % Logging the RMSE and r2 values for all models if auto
                % mode is chosen
                if app.auto_iso_mode
                   app.update_textbox_func(app.OutputText, initial_string);
                end

                % Logging the starting string
                app.update_textbox_func(app.OutputText, heading_string);
                
                % Logging the isotherm model used for fitting
                app.update_textbox_func(app.OutputText, ...
                    iso_model_string);
                
                % Logging the Reference Temperature
                app.update_textbox_func(app.OutputText, ...
                    ref_temp_string);

                % Logging the fitting statistics       
                app.update_textbox_func(app.OutputText, stats_string);
                
                % Logging the parameters string
                app.update_textbox_func(app.OutputText, ...
                                        params_string);
                 
                % Logging the finishing string
                app.update_textbox_func(app.OutputText, finish_string);
            
            else
                % Logging the starting string
                fprintf(fileID, '%s\n',heading_string);
                
                % Logging the isotherm model used for fitting
                fprintf(fileID, '%s\n', iso_model_string);
                
                % Logging the Reference Temperature
                fprintf(fileID, '%s\n', ref_temp_string);

                % Logging the fitting statistics
                fprintf(fileID, '%s\n', stats_string);
                
                % Logging the parameters string
                fprintf(fileID, '%s\n', params_string);
                 
                % Logging the finishing string
                fprintf(fileID, '%s\n', finish_string);        
            end
        end
        %
        %% Function for plotting isotherm data
        function [] = update_plot_func(app, pressure_data, loading_data, T_flag)
            P_saturation = 1.0;    % Not useful for diffT isotherm fitting
            isotherm_plots(app.IsothermAxes, pressure_data, loading_data, T_flag, app.T_array, app.isotherm_struc, ...
                            app.xaxisCheckBox.Value, app.yaxisCheckBox.Value, app.unit_pressure, app.unit_loading,...
                            app.unit_temperature, P_saturation, app.vir_flag, app.vir_num_a);
            heat_plot(app.HeatAxes, pressure_data, loading_data, T_flag, app.T_array, app.isotherm_struc, app.xaxisCheckBox.Value, app.yaxisCheckBox.Value, app.unit_loading);
        end
        %
    end
    %
    methods (Access = public)
        %% Updating the settings dialogue box properties
        function varargout = update_iso_setting(app)
            %% For all isotherm models except Virial equation
            % Updating the initial guess, upper and lower bounds matrices
            num_max_iso_params = 8;        % Correspsonds to number of default params in DSLF + dH and theta
            num_max_iso_models = 9;        % Correspsonds to number of default isotherm models + Virial equation in AIM
            
            app.initial_guess = zeros(num_max_iso_params, num_max_iso_models);
            app.upper_bounds = zeros(size(app.initial_guess));
            app.lower_bounds = zeros(size(app.initial_guess));
            app.num_par_all_iso = zeros(1, num_max_iso_models);
            
            % Removing Auto and Virial model from dropdown entries for looping purpose
            % isotherm_models = app.IsothermModelDropDown.Items;
            % idx_to_retain = ~strcmpi(isotherm_models, 'Auto');
            % isotherm_models = isotherm_models(idx_to_retain);
            % idx_to_retain = ~strcmpi(isotherm_models, 'Virial');
            % isotherm_models = isotherm_models(idx_to_retain);
            isotherm_models = app.iso_models_av;

            for i=1:numel(isotherm_models)
                current_model = isotherm_models(i); 
                if ~isempty(app.iso_data)
                    iso_fit_opts = isotherm_fit_opt(char(current_model), app.iso_data(:, 2:2:end));
                else
                    % Isotherm data does not exist and hence the saturation
                    % loading value guess would be initialized as '1' in isotherm_fit_options 
                    iso_fit_opts = isotherm_fit_opt(char(current_model), 1);
                end
                
                app.initial_guess(1:iso_fit_opts.num_params, i) = iso_fit_opts.guess';

                app.lower_bounds(1:iso_fit_opts.num_params, i) = iso_fit_opts.lb';

                app.upper_bounds(1:iso_fit_opts.num_params, i) = iso_fit_opts.ub';
                
                app.num_par_all_iso(i) = iso_fit_opts.num_params;                
            end
            
            % Appending dH and theta guess and bounds to initial guess and
            % bounds matrices in the first column and last two lines
            app.initial_guess(end-1:end, 1) = app.def_dH_theta_guess_bounds(:, 1);

            app.lower_bounds(end-1:end, 1) = app.def_dH_theta_guess_bounds(:, 2);

            app.upper_bounds(end-1:end, 1) = app.def_dH_theta_guess_bounds(:, 3);
            %
            %% For Virial equation
            % Calling default options for Virial
            if ~isempty(app.iso_data)
                vir_fit_opts = isotherm_fit_opt('Virial', app.iso_data(:, 2:2:end));
            else
                % Isotherm data does not exist and hence the saturation
                % loading value guess would be initialized as '1' in isotherm_fit_options 
                vir_fit_opts = isotherm_fit_opt('Virial', 1);
            end

            app.vir_init_guess = vir_fit_opts.guess';
            app.vir_lb = vir_fit_opts.lb';
            app.vir_ub = vir_fit_opts.ub';
            app.vir_num_a = vir_fit_opts.num_a;
            app.vir_num_b = vir_fit_opts.num_b;
            %
            %% Passing output arguments
            if nargout == 3         % For all models except Virial
                varargout{1} = app.initial_guess;
                varargout{2} = app.lower_bounds;
                varargout{3} = app.upper_bounds;
            
            elseif nargout > 3      % For Virial equation
                varargout{1} = app.vir_num_a;
                varargout{2} = app.vir_num_b;
                varargout{3} = app.vir_init_guess;
                varargout{4} = app.vir_lb;
                varargout{5} = app.vir_ub;            
            end
        end
        %
        %% Progress Bar Function
        function dialogue = progress(app)
            dialogue = uiprogressdlg(app.AIMHeatFitUIFigure, "Title", "Please Wait",...
                  "Message","Fitting Isotherm",...
                  "Cancelable","off", "Indeterminate","on");
                drawnow   
        end
        %
        %% Running the fitting function
        function [] = run_fit_function(app)
            if app.iso_data_import_flag && app.iso_T_import_flag
                app.iso_model = app.IsothermModelDropDown.Value;
                
                if strcmpi(app.iso_model, 'Auto')
                    app.auto_iso_mode = 1;
                    % Never use multistart option for Auto mode to avoid excessive computation time
                    app.use_multi = 0;
                else
                    app.auto_iso_mode = 0;
                end

                if strcmpi(app.iso_model, 'Virial') || app.auto_iso_mode
                    app.vir_flag = 1;
                else
                    app.vir_flag = 0;
                end
                
                % Isotherm data
                pressure_data = app.iso_data(:, 1:2:end);
                loading_data = app.iso_data(:, 2:2:end);
                
                % Isotherm temperatures and reference temperature index
                app.T_ref = app.T_refEditField.Value;
                ID_T_ref = find(app.T_array == app.T_ref, 1);     % Finding only one element in case if temperature values are same
                
                % Initializing progress bar
                progress_bar = app.progress();

                try
                    if app.auto_iso_mode || ~app.vir_flag
                        %  Fitting using models except Virial
                        [~, ~, ~, ~, isotherm_struc_def_models] = Isotherm_fitting_diffT(app.iso_model, pressure_data, loading_data,...
                                                                               app.T_array, ID_T_ref, app.initial_guess, app.lower_bounds, app.upper_bounds, ...
                                                                               app.iso_fit_options.algorithm_id, app.iso_fit_options.Weights, false, false, app.iso_models_av,...
                                                                               app.use_multi);
                    end
                    
                    % Fitting using Virial
                    if app.vir_flag
                        [~, ~, isotherm_struc_vir] = Virial_fitting(pressure_data, loading_data, app.T_array,...
                                                                    app.vir_init_guess, app.vir_lb, app.vir_ub, app.vir_num_a,...
                                                                    'Uniform', 'False', 0, app.use_multi);
                        %  Comparing Virial with other models
                        if app.auto_iso_mode
                            RMSE_diff = isotherm_struc_vir.RMSE < isotherm_struc_def_models.RMSE;

                            if RMSE_diff
                                % Virial equation will be selected
                                app.isotherm_struc = isotherm_struc_vir;
                            else
                                % Isotherm model suggested by CC-Equation
                                % fitting will be retained.
                                app.isotherm_struc = isotherm_struc_def_models;
                                app.vir_flag = 0;
                            end
                            
                            % RMSE and r2 values for all isotherm models + Virial equation 
                            RMSE_all = [isotherm_struc_def_models.RMSE_all, isotherm_struc_vir.RMSE];
                            r2_all = [isotherm_struc_def_models.r2_all, isotherm_struc_vir.r2];
                            app.isotherm_struc.RMSE_all = RMSE_all;
                            app.isotherm_struc.r2_all = r2_all;
                        else
                            app.isotherm_struc = isotherm_struc_vir;
                        end
                    else
                        app.isotherm_struc = isotherm_struc_def_models;
                    end

                    fitted_isotherm_model = app.isotherm_struc.name;
                    fitted_params = app.isotherm_struc.fitted_params;

                    progress_bar.Message = "Fitting Finished...";
                    % % Getting parsed params with the final fitting result
                    % parsed_params = iso_params_parsing(fitted_isotherm_model, fitted_params);
                    
                    % Updating isotherm model and isotherm model expression (useful in case in case
                    % user chooses auto model)
                    app.iso_model = fitted_isotherm_model;
                    app.IsothermModelDropDown.Value = app.iso_model;            
                    app.update_iso_expression(fitted_params);
                                        
                    % Writing isotherm params on message panel
                    app.print_params()
                    
                    % Setting the fitting state on after successful fitting
                    app.iso_fit_finish_flag = 1;
    
                    % Plotting the fitted isotherm
                    app.update_plot_func(pressure_data, loading_data, app.T_flag);
    
                catch ME1
                    progress_bar.Message = "Error Occured. Check the Message Panel for detailed information.";
                    pause(2);
                    close(progress_bar);

                    app.update_textbox_func(app.OutputText, ...
                                            sprintf('Error occured in isotherm fitting...!\nError message: %s', ME1.message));
                end

                if ~isempty(progress_bar)
                    close(progress_bar);
                end
            
            elseif (~app.iso_data_import_flag)
                app.update_textbox_func(app.OutputText, ...
                                        sprintf('Please import the isotherm data first ...!'));
            elseif (~app.iso_T_import_flag)
                app.update_textbox_func(app.OutputText, ...
                                        sprintf('Please specify all the temperature first ...!'));
            end
        end
        %% Function to adjust the size of figure
        function [] = adjust_size(app)
            app.AIMHeatFitUIFigure.Units = "normalized";
            app.AIMHeatFitUIFigure.Position(1) = 0.1;
            app.AIMHeatFitUIFigure.Position(2) = 0.05;
            app.AIMHeatFitUIFigure.Units = "pixels";
        end
        %
        %% Function to set the working directory
        function [] = set_work_dir(app)
            new_path = uigetdir(app.work_dir, 'Select working directory');
            
            % Command for refocusing parent app
            figure(app.AIMHeatFitUIFigure);

            if new_path
                app.work_dir = new_path;
            end
        end
        %
        %% Function to update the uifigure for different devices
        function [] = adjust_uifig(app)
            % Get the device resolution
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            
            default_position = app.AIMHeatFitUIFigure.Position;
            
            if default_position(3) > screenWidth || default_position(4) > screenHeight
                left = screenWidth*0.1;
                bottom = screenHeight*0.1;
                width = screenWidth*0.85;
                height = screenHeight*0.85;
                drawnow;
                app.AIMHeatFitUIFigure.Position = [left bottom width height];
            end          
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.adjust_size();
            app.adjust_uifig();
            app.work_dir = pwd;
            app.startup_func();

            % Printing welcome message
            intro_string = get_intro_string("HeatFit");
            app.update_textbox_func(app.OutputText, intro_string);
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            app.unit_pressure = '';
            app.unit_loading = '';
            app.unit_temperature = '';
            app.import_iso_data();
        end

        % Button pushed function: FitIsothermButton
        function FitIsothermButtonPushed(app, event)
            app.run_fit_function()
        end

        % Value changed function: IsothermModelDropDown
        function IsothermModelDropDownValueChanged(app, event)
            app.iso_model = app.IsothermModelDropDown.Value;
             if strcmpi(app.iso_model, 'Virial')
                app.vir_flag = 1;
            else
                app.vir_flag = 0;
            end
            app.update_iso_expression();
        end

        % Button pushed function: ExportParametersasblisoButton
        function ExportParametersasblisoButtonPushed(app, event)
            % Check if the fitting has been completed successfully
            if app.iso_fit_finish_flag
                filter = {'*.bliso', 'AIM Isotherm Files'};
                default_file_name = fullfile(app.work_dir, 'my_isotherm_data.bliso');
                [file, path, indx] = uiputfile(filter, 'Select file for save as', ...
                    default_file_name);
                
                % Command for refocusing parent app
                figure(app.AIMHeatFitUIFigure);

                if indx
                    filename = fullfile(path, file);
                    
                    fileID = fopen(filename, "w");
                    app.print_params(fileID);
                    fclose(fileID);
                end
            end
        end

        % Value changed function: xaxisCheckBox
        function xaxisCheckBoxValueChanged(app, event)
            if app.iso_data_import_flag
                pressure_data = app.iso_data(:, 1:2:end);
                loading_data = app.iso_data(:, 2:2:end);
    
                app.update_plot_func(pressure_data, loading_data, app.T_flag);
            end
        end

        % Value changed function: yaxisCheckBox
        function yaxisCheckBoxValueChanged(app, event)
            if app.iso_data_import_flag
                pressure_data = app.iso_data(:, 1:2:end);
                loading_data = app.iso_data(:, 2:2:end);
    
                app.update_plot_func(pressure_data, loading_data, app.T_flag);
            end
        end

        % Cell edit callback: TempTable
        function TempTableCellEdit(app, event)
            indices = event.Indices;
            error_flag = 0;

            if isempty(event.Error) && isnumeric(event.NewData) && ~isnan(event.NewData)
                if event.NewData >= 0
                    % Updating Temperature values array based on edited value
                    app.T_array = app.TempTable.Data;
    
                    % Specifying reference temperature in refrence temperature
                    % field
                    app.T_refEditField.Value = min(app.T_array);
    
                    % Updating the plot
                    pressure_data = app.iso_data(:, 1:2:end);
                    loading_data = app.iso_data(:, 2:2:end);
        
                    app.update_plot_func(pressure_data, loading_data, app.T_flag);
                else
                    error_flag = 1;
                end
            else
                error_flag = 1;
            end

            if error_flag
                app.update_textbox_func(app.OutputText, sprintf('Enter valid temperature values.'));
                app.TempTable.Data(indices(1), indices(2)) = event.PreviousData;
            end

            if all(app.TempTable.Data>0)
                app.iso_T_import_flag = 1;
            end
        end

        % Button pushed function: IsothermFittingSettingsButton
        function IsothermFittingSettingsButtonPushed(app, event)
             app.settings_app = iso_fit_settings(app);
        end

        % Close request function: AIMHeatFitUIFigure
        function AIMHeatFitUIFigureCloseRequest(app, event)
            delete(app.settings_app)          
            YN = uiconfirm(app.AIMHeatFitUIFigure,'Do you want to close the app?', 'Close request', 'Icon','warning');
            if strcmpi(YN,'OK')
                delete(app)
            end
        end

        % Cell edit callback: IsothermDataTable
        function IsothermDataTableCellEdit(app, event)
            indices = event.Indices;
            if ~isempty(event.Error) || ~isnumeric(event.NewData) || isnan(event.NewData)
                % Retaining the old data 
                app.IsothermDataTable.Data(indices(1), indices(2)) = event.PreviousData;
            else
                % Updating the isotherm data table with the new data
                app.iso_data(indices(1), indices(2)) = event.NewData;
                
                % Updating the plots
                if app.iso_data_import_flag
                    pressure_data = app.iso_data(:, 1:2:end);
                    loading_data = app.iso_data(:, 2:2:end);
        
                    app.update_plot_func(pressure_data, loading_data, app.T_flag);
                end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.startup_func();
        end

        % Button pushed function: SetWorkspaceButton
        function SetWorkspaceButtonPushed(app, event)
            app.set_work_dir();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AIMHeatFitUIFigure = uifigure('Visible', 'off');
            app.AIMHeatFitUIFigure.Name = 'AIM HeatFit';
            app.AIMHeatFitUIFigure.Icon = fullfile(pathToMLAPP, 'AIM_logo.png');
            app.AIMHeatFitUIFigure.CloseRequestFcn = createCallbackFcn(app, @AIMHeatFitUIFigureCloseRequest, true);
            app.Panel = uipanel(app.AIMHeatFitUIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.OutputPanel = uipanel(app.GridLayout);
            app.OutputPanel.Title = 'Output';
            app.OutputText = uitextarea(app.OutputPanel);
            app.OutputText.Editable = 'off';
            app.PlotPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.PlotPanel);
            app.Panel_2 = uipanel(app.GridLayout2);
            app.LogarithmicaxisLabel = uilabel(app.Panel_2);
            app.LogarithmicaxisLabel.Text = 'Logarithmic axis';
            app.xaxisCheckBox = uicheckbox(app.Panel_2);
            app.xaxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @xaxisCheckBoxValueChanged, true);
            app.xaxisCheckBox.Text = 'x axis';
            app.yaxisCheckBox = uicheckbox(app.Panel_2);
            app.yaxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @yaxisCheckBoxValueChanged, true);
            app.yaxisCheckBox.Text = 'y axis';
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.IsothermTab = uitab(app.TabGroup);
            app.IsothermTab.Title = 'Isotherm';
            app.IsothermAxes = uiaxes(app.IsothermTab);
            xlabel(app.IsothermAxes, 'Pressure')
            ylabel(app.IsothermAxes, 'Gas Uptake')
            zlabel(app.IsothermAxes, 'Z')
            app.HeatofAdsorptionTab = uitab(app.TabGroup);
            app.HeatofAdsorptionTab.Title = 'Heat of Adsorption';
            app.HeatAxes = uiaxes(app.HeatofAdsorptionTab);
            xlabel(app.HeatAxes, 'Gas Uptake')
            ylabel(app.HeatAxes, 'Heat of Adsorption')
            zlabel(app.HeatAxes, 'Z')
            app.DataInputPanel = uipanel(app.GridLayout);
            app.DataInputPanel.Title = 'Data Input';
            app.ImportDataButton = uibutton(app.DataInputPanel, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.IsothermModelDropDownLabel = uilabel(app.DataInputPanel);
            app.IsothermModelDropDownLabel.Text = 'Isotherm Model ';
            app.IsothermModelDropDown = uidropdown(app.DataInputPanel);
            app.IsothermModelDropDown.Items = {'Auto', 'SS-Langmuir', 'DS-Langmuir', 'SS-Langmuir-Freundlich', 'DS-Langmuir-Freundlich', 'Quadratic', 'Temkin', 'BET', 'Sips', 'Toth'};
            app.IsothermModelDropDown.ValueChangedFcn = createCallbackFcn(app, @IsothermModelDropDownValueChanged, true);
            app.IsothermModelDropDown.Placeholder = 'Choose the Isotherm model';
            app.IsothermModelDropDown.Value = 'Auto';
            app.FitIsothermButton = uibutton(app.DataInputPanel, 'push');
            app.FitIsothermButton.ButtonPushedFcn = createCallbackFcn(app, @FitIsothermButtonPushed, true);
            app.FitIsothermButton.Text = 'Fit Isotherm';
            app.IsothermFittingSettingsButton = uibutton(app.DataInputPanel, 'push');
            app.IsothermFittingSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @IsothermFittingSettingsButtonPushed, true);
            app.IsothermFittingSettingsButton.Text = 'Isotherm Fitting Settings';
            app.IsothermDataTable = uitable(app.DataInputPanel);
            app.IsothermDataTable.ColumnName = {'Pressure'; 'Gas Uptake'};
            app.IsothermDataTable.RowName = {};
            app.IsothermDataTable.ColumnEditable = true;
            app.IsothermDataTable.CellEditCallback = createCallbackFcn(app, @IsothermDataTableCellEdit, true);
            app.ExpressionLabel = uilabel(app.DataInputPanel);
            app.ExpressionLabel.Interpreter = 'latex';
            app.ExpressionLabel.Text = 'Expression';
            app.ExportParametersasblisoButton = uibutton(app.DataInputPanel, 'push');
            app.ExportParametersasblisoButton.ButtonPushedFcn = createCallbackFcn(app, @ExportParametersasblisoButtonPushed, true);
            app.ExportParametersasblisoButton.Text = 'Export Parameters as *.bliso';
            app.T_refEditFieldLabel = uilabel(app.DataInputPanel);
            app.T_refEditFieldLabel.Interpreter = 'latex';
            app.T_refEditFieldLabel.Text = '$$T_{ref}$$';
            app.T_refEditField = uieditfield(app.DataInputPanel, 'numeric');
            app.T_refEditField.Limits = [0 Inf];
            app.TempTable = uitable(app.DataInputPanel);
            app.TempTable.ColumnName = {'Temeprature Values'};
            app.TempTable.RowName = {};
            app.TempTable.ColumnEditable = true;
            app.TempTable.CellEditCallback = createCallbackFcn(app, @TempTableCellEdit, true);
            app.ResetButton = uibutton(app.DataInputPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.heatExp = uilabel(app.DataInputPanel);
            app.heatExp.Interpreter = 'latex';
            app.heatExp.Text = 'Expression';
            app.SetWorkspaceButton = uibutton(app.DataInputPanel, 'push');
            app.SetWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SetWorkspaceButtonPushed, true);
            app.SetWorkspaceButton.Text = 'Set Workspace';
            % Show the figure after all components are created
            app.AIMHeatFitUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Isotherm_fit_GUI_diffT

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AIMHeatFitUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.AIMHeatFitUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AIMHeatFitUIFigure)
        end
    end
end