classdef data_saving_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SaveDataUIFigure              matlab.ui.Figure
        ChooseVariabletoSavePanel     matlab.ui.container.Panel
        SaveDataButton                matlab.ui.control.Button
        SelectComponentDropDown       matlab.ui.control.DropDown
        SelectComponentDropDownLabel  matlab.ui.control.Label
        SelectStateVariableDropDown   matlab.ui.control.DropDown
        SelectStateVariableDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        caller % Main App Handle
        N   % Number of Nodes
        comp_name
        comp_num
    end
    
    methods (Access = private)
        
        function results = Data_processing(app, solution_matrix, variable, component, N)
            
            time_vector = solution_matrix.t;    % Time vector for the simulation

            if (component == "")
                if strcmpi(variable, 'Pressure')
                    data = [time_vector, solution_matrix.P];
                elseif strcmpi(variable, 'Temperature')
                    data = [time_vector, solution_matrix.T];
                elseif strcmpi(variable, 'Wall Temperature')
                    data = [time_vector, solution_matrix.Tw];
                end
            end
            
            if app.comp_num == 2
                if strcmpi(variable, 'Mole Fractions') 
                    if strcmpi(component, app.comp_name(1))
                        data = [time_vector, solution_matrix.C5];       
                    elseif strcmpi(component, app.comp_name(2))
                        data = [time_vector, solution_matrix.C1];
                    end
                end

                if strcmpi(variable, 'Molar Loadings')
                    if strcmpi(component, app.comp_name(1))
                        data = [time_vector, solution_matrix.x5C5];                   
                    elseif strcmpi(component, app.comp_name(2))
                        data = [time_vector, solution_matrix.x1C1];
                    end
                end

            elseif app.comp_num == 3
                if strcmpi(variable, 'Mole Fractions') 
                    if strcmpi(component, app.comp_name(1))
                        data = [time_vector, solution_matrix.C5];
                    elseif strcmpi(component, app.comp_name(2))
                        data = [time_vector, solution_matrix.C1];
                    elseif strcmpi(component, app.comp_name(3))
                        data = [time_vector, solution_matrix.C2];
                    end
                end

                if strcmpi(variable, 'Molar Loadings')
                    if strcmpi(component, app.comp_name(1))
                        data = [time_vector, solution_matrix.x5C5];
                    elseif strcmpi(component, app.comp_name(2))
                        data = [time_vector, solution_matrix.x1C1];
                    elseif strcmpi(component, app.comp_name(3))
                        data = [time_vector, solution_matrix.x2C2];
                    end
                end

             elseif app.comp_num == 4
                if strcmpi(variable, 'Mole Fractions') 
                    if strcmpi(component, app.comp_name(1))
                        data = [time_vector, solution_matrix.C5];
                    elseif strcmpi(component, app.comp_name(2))
                        data = [time_vector, solution_matrix.C1];
                    elseif strcmpi(component, app.comp_name(3))
                        data = [time_vector, solution_matrix.C2];
                    elseif strcmpi(component, app.comp_name(4))
                        data = [time_vector, solution_matrix.C3];
                    end
                end

                if strcmpi(variable, 'Molar Loadings')
                    if strcmpi(component, app.comp_name(1))
                        data = [time_vector, solution_matrix.x5C5];
                    elseif strcmpi(component, app.comp_name(2))
                        data = [time_vector, solution_matrix.x1C1];
                    elseif strcmpi(component, app.comp_name(3))
                        data = [time_vector, solution_matrix.x2C2];
                    elseif strcmpi(component, app.comp_name(4))
                        data = [time_vector, solution_matrix.x3C3];
                    end
                end

            elseif app.comp_num == 5
                if strcmpi(variable, 'Mole Fractions') 
                    if strcmpi(component, app.comp_name(1))
                        data = [time_vector, solution_matrix.C5];
                    elseif strcmpi(component, app.comp_name(2))
                        data = [time_vector, solution_matrix.C1];
                    elseif strcmpi(component, app.comp_name(3))
                        data = [time_vector, solution_matrix.C2];
                    elseif strcmpi(component, app.comp_name(4))
                        data = [time_vector, solution_matrix.C3];
                    elseif strcmpi(component, app.comp_name(5))
                        data = [time_vector, solution_matrix.C4];
                    end
                end

                if strcmpi(variable, 'Molar Loadings')
                    if strcmpi(component, app.comp_name(1))
                        data = [time_vector, solution_matrix.x5C5];
                    elseif strcmpi(component, app.comp_name(2))
                        data = [time_vector, solution_matrix.x1C1];
                    elseif strcmpi(component, app.comp_name(3))
                        data = [time_vector, solution_matrix.x2C2];
                    elseif strcmpi(component, app.comp_name(4))
                        data = [time_vector, solution_matrix.x3C3];
                    elseif strcmpi(component, app.comp_name(5))
                        data = [time_vector, solution_matrix.x4C4];
                    end
                end
            end

            header = cellstr(["Time (s)", "N"+string(1:N+2)]);
            results = array2table(data);
            results.Properties.VariableNames = header;
        end
        %
        %% Function to update the uifigure for different devices
        function [] = adjust_uifig(app)
            % Get the device resolution
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            
            default_position = app.SaveDataUIFigure.Position;
            
            if default_position(3) > screenWidth || default_position(4) > screenHeight
                left = screenWidth*0.1;
                bottom = screenHeight*0.1;
                width = screenWidth*0.85;
                height = screenHeight*0.85;
                drawnow;
                app.SaveDataUIFigure.Position = [left bottom width height];
            end            
        end
    end
    %
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller_app_handle)
            app.caller = caller_app_handle;
            app.N      = app.caller.N;
            app.comp_name = app.caller.parameters.CompNames;
            app.comp_num = app.caller.parameters.CompNum;
            items = convertStringsToChars(app.comp_name);
%             for i=1:app.comp_num
%                 items(i) = app.comp_name(i);
%             end
            app.SelectComponentDropDown.Items = items;
            app.adjust_uifig();

        end

        % Value changed function: SelectStateVariableDropDown
        function SelectStateVariableDropDownValueChanged(app, event)
            value = app.SelectStateVariableDropDown.Value;
            if ~(strcmpi(value, 'Pressure') || strcmpi(value, 'Temperature') || strcmpi(value, 'Wall Temperature'))
                app.SelectComponentDropDown.Enable = "on";
                app.SelectComponentDropDownLabel.Enable = "on";
            else
                app.SelectComponentDropDown.Enable = "off";
                app.SelectComponentDropDownLabel.Enable = "off";
            end
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            variable_value = app.SelectStateVariableDropDown.Value;
            component_value = "";
           
            filter = {'*.csv'; '*.txt'; '*.xlsx'};
            [file, path, indx] = uiputfile(filter, 'Select file for save as', ...
                'my_data.csv');

            % Command for refocusing parent app
            figure(app.SaveDataUIFigure);

            if indx
                filename = fullfile(path, file);
                if ~(strcmpi(variable_value, 'Pressure') || strcmpi(variable_value, 'Temperature') || strcmpi(variable_value, 'Wall Temperature'))
                    component_value = app.SelectComponentDropDown.Value;
                end

                % Getting the Data:
                data_to_be_saved = Data_processing(app, app.caller.solution, variable_value, component_value, app.N);
                writetable(data_to_be_saved, filename)
            end
        end

        % Close request function: SaveDataUIFigure
        function SaveDataUIFigureCloseRequest(app, event)
            app.caller.ExportDataButton.Enable = "on";
            figure(app.caller.AIMBreakLabUIFigure);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SaveDataUIFigure = uifigure('Visible', 'off');
            app.SaveDataUIFigure.Name = 'Save Data';
            app.SaveDataUIFigure.Icon = fullfile(pathToMLAPP, 'AIM_logo.png');
            app.SaveDataUIFigure.CloseRequestFcn = createCallbackFcn(app, @SaveDataUIFigureCloseRequest, true);
            app.ChooseVariabletoSavePanel = uipanel(app.SaveDataUIFigure);
            app.ChooseVariabletoSavePanel.Title = 'Choose Variable to Save';
            app.SelectStateVariableDropDownLabel = uilabel(app.ChooseVariabletoSavePanel);
            app.SelectStateVariableDropDownLabel.Text = 'Select State Variable';
            app.SelectStateVariableDropDown = uidropdown(app.ChooseVariabletoSavePanel);
            app.SelectStateVariableDropDown.Items = {'Pressure', 'Temperature', 'Wall Temperature', 'Mole Fractions', 'Molar Loadings'};
            app.SelectStateVariableDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectStateVariableDropDownValueChanged, true);
            app.SelectStateVariableDropDown.Value = 'Pressure';
            app.SelectComponentDropDownLabel = uilabel(app.ChooseVariabletoSavePanel);
            app.SelectComponentDropDownLabel.Enable = 'off';
            app.SelectComponentDropDownLabel.Text = 'Select Component';
            app.SelectComponentDropDown = uidropdown(app.ChooseVariabletoSavePanel);
            app.SelectComponentDropDown.Items = {'CH4', 'H2O', 'H2', 'CO2', 'CO'};
            app.SelectComponentDropDown.Enable = 'off';
            app.SelectComponentDropDown.Value = 'CH4';
            app.SaveDataButton = uibutton(app.ChooseVariabletoSavePanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            % Show the figure after all components are created
            app.SaveDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = data_saving_app(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SaveDataUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SaveDataUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SaveDataUIFigure)
        end
    end
end