classdef iso_fit_settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IsothermFittingSettingUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        IsothermParamTable              matlab.ui.control.Table
        IsothermModelPanel              matlab.ui.container.Panel
        UseMultiStartCheckBox           matlab.ui.control.CheckBox
        Num_b_params                    matlab.ui.control.NumericEditField
        Num_b_Label                     matlab.ui.control.Label
        Num_a_params                    matlab.ui.control.NumericEditField
        Num_a_Label                     matlab.ui.control.Label
        heatExp                         matlab.ui.control.Label
        RestoreDefaultsButton           matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        ExpressionLabel                 matlab.ui.control.Label
        IsothermModelDropDown           matlab.ui.control.DropDown
        IsothermModelDropDownLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        caller_app              % Isotherm fitting app
        iso_data                % Isotherm data from caller app
        iso_model               % Isotherm model chosen
        data_flag               % If the isotherm data read or not
        parameters              % Isotherm fitting numerical settings params
        initial_guess           % Inital guess matrix
        upper_bounds            % Upper bound matrix
        lower_bounds            % Lowere bound matrix
        num_par_all_iso         % Number of parameters in isotherm model
        dH_theta_guess_bounds   % dh and theta guess and bounds matrix
        T_flag                  % for single or multiple temeprature fittings
        use_multi               % Flag for fitting using multistart option

        % heat_exp = '$$P_{norm} = P e^{\left(\frac{\Delta H}{R}\left(\frac{1}{T}-\frac{1}{T_{ref}}\right)\right)}$$';
        heat_exp = ['$$P_{norm} = P \times \Theta,'...
                    '\quad\Theta=e^{\left(\frac{-\Delta H}{R}\left(\frac{1}{T}-\frac{1}{T_{ref}}\right)\right)}$$'];

        % Virial Equation specific variables
        vir_flag             % Virial Flag
        vir_num_a            % Number of a parameters
        vir_num_b            % Number of b parameters
        vir_init_guess       % Initial guess vector
        vir_ub               % Upper bound vector
        vir_lb               % lower bound vector
    end
    %
    properties (Access = public)
        
    end
    %
    methods (Access = private) 
        %% Function for updating isotherm expression
        function [] = update_iso_expression(app, fit_params)
            if nargin<2
                fit_params = [];
            end
            chosen_model = app.iso_model;
            if strcmpi(chosen_model, 'Auto')
                app.ExpressionLabel.Text = {''};
            else
                opt = iso_params_parsing(chosen_model, fit_params);
                if app.T_flag && ~app.vir_flag
                    app.heatExp.Text = app.heat_exp;
                    app.ExpressionLabel.Text = strrep(opt.express, 'P', 'P_{norm}');
                else
                    app.heatExp.Visible = "off";
                    app.ExpressionLabel.Text = opt.express;
                end        
            end           
        end
        %
        %% Function for updating isotherm params guess, ub, and lb table
        function [] = update_iso_table(app)

            if ~app.vir_flag
                % For all the isotherm models except Virial
                % Get the names of isotherm parameters for row names
                parsed_params = iso_params_parsing(app.iso_model, []);
                idx = find(strcmpi(app.IsothermModelDropDown.Items, app.iso_model));
    
                % Organizing Isotherm Param Table
                data_to_display = [app.initial_guess(1:app.num_par_all_iso(idx), idx), ...
                                                   app.lower_bounds(1:app.num_par_all_iso(idx), idx), ...
                                                   app.upper_bounds(1:app.num_par_all_iso(idx), idx)];
                if app.T_flag
                    app.IsothermParamTable.RowName = [parsed_params.name_params, {'dH', 'Theta'}];
           
                    data_to_display = [data_to_display;...
                                       app.initial_guess(end-1:end, 1),...
                                       app.lower_bounds(end-1:end, 1),...
                                       app.upper_bounds(end-1:end, 1)];
                else
                    app.IsothermParamTable.RowName = parsed_params.name_params; 
                end

            elseif app.vir_flag && app.T_flag
                % If the user chooses Virial equation 
                vir_name_params = {};
                for a = 1:app.vir_num_a
                    vir_name_params = [vir_name_params, {sprintf('a_%d', a-1)}];
                end

                for b = 1:app.vir_num_b
                    vir_name_params = [vir_name_params, {sprintf('b_%d', b-1)}];
                end

                % Organizing Isotherm Param Table
                data_to_display = [app.vir_init_guess, ...
                                   app.vir_lb, ...
                                   app.vir_ub];

                app.IsothermParamTable.RowName = vir_name_params;
            end

            % Populating data in table
            app.IsothermParamTable.Data = data_to_display;
        end
        %
        %% Function for checking whether Virial equation is selected or not
        function [] = check_virial(app)
            if ~app.T_flag || ~app.vir_flag
                app.Num_a_params.Visible = "off";
                app.Num_b_params.Visible = "off";
                app.Num_a_Label.Visible = "off";
                app.Num_b_Label.Visible = "off";

            elseif app.vir_flag
                app.Num_a_params.Visible = "on";
                app.Num_b_params.Visible = "on";
                app.Num_a_Label.Visible = "on";
                app.Num_b_Label.Visible = "on";

                app.Num_a_params.Value = app.vir_num_a;
                app.Num_b_params.Value = app.vir_num_b;
            end
        end
        %
        %% Function for updating Virial equation parameters based on new values of m and n
        function [] = update_virial_params(app)
            new_a = app.Num_a_params.Value;
            new_b = app.Num_b_params.Value;
            
            % New placeholder variables
            new_init_guess = zeros(max(new_a, new_b), 2);
            new_lb = -Inf(max(new_a, new_b), 2);
            new_ub = Inf(max(new_a, new_b), 2);

            %% Updating "a" parameters
            diff = new_a - app.vir_num_a;
            if diff > 0
                new_init_guess(1:new_a, 1) = [app.vir_init_guess(1:app.vir_num_a); zeros(diff, 1)];
                new_lb(1:new_a, 1) = [app.vir_lb(1:app.vir_num_a); -Inf(diff, 1)];
                new_ub(1:new_a, 1) = [app.vir_ub(1:app.vir_num_a); Inf(diff, 1)];
            else
                new_init_guess(1:new_a, 1) = app.vir_init_guess(1:new_a);
                new_lb(1:new_a, 1) = app.vir_lb(1:new_a);
                new_ub(1:new_a, 1) = app.vir_ub(1:new_a);
            end

            %% Updating "b" parameters
            diff = new_b - app.vir_num_b;
            b_start_idx = app.vir_num_a+1;
            if diff > 0
                new_init_guess(1:new_b, 2) = [app.vir_init_guess(b_start_idx:end); zeros(diff, 1)];
                new_lb(1:new_b, 2) = [app.vir_lb(b_start_idx:end); -Inf(diff, 1)];
                new_ub(1:new_b, 2) = [app.vir_ub(b_start_idx:end); Inf(diff, 1)];
            else
                % -1 because of inclusive indexing in MATLAB
                new_init_guess(1:new_b, 2) = app.vir_init_guess(b_start_idx:b_start_idx+new_b-1); 
                new_lb(1:new_b, 2) = app.vir_lb(b_start_idx:b_start_idx+new_b-1);
                new_ub(1:new_b, 2) = app.vir_ub(b_start_idx:b_start_idx+new_b-1);
            end
            
            %% Updating Virial Settings variables
            app.vir_num_a = new_a;
            app.vir_num_b = new_b;
            app.vir_init_guess = [new_init_guess(1:new_a, 1); new_init_guess(1:new_b, 2)];
            app.vir_lb = [new_lb(1:new_a, 1); new_lb(1:new_b, 2)];
            app.vir_ub = [new_ub(1:new_a, 1); new_ub(1:new_b, 2)];

            %% Updating table
            app.update_iso_table();
        end
        %% Function to adjust the size of figure
        function [] = adjust_size(app)
            app.IsothermFittingSettingUIFigure.Units = "normalized";
            app.IsothermFittingSettingUIFigure.Position(1) = 0.1;
            app.IsothermFittingSettingUIFigure.Position(2) = 0.05;
            app.IsothermFittingSettingUIFigure.Units = "pixels";
        end
        %
        %% Function to update the uifigure for different devices
        function [] = adjust_uifig(app)
            % Get the device resolution
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            
            default_position = app.IsothermFittingSettingUIFigure.Position;
            
            if default_position(3) > screenWidth || default_position(4) > screenHeight
                left = screenWidth*0.1;
                bottom = screenHeight*0.1;
                width = screenWidth*0.85;
                height = screenHeight*0.85;
                drawnow;
                app.IsothermFittingSettingUIFigure.Position = [left bottom width height];
            end            
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller_app_handle)
            app.adjust_size();
            app.adjust_uifig();
            
            app.caller_app = caller_app_handle;                 % Caller app
            app.iso_data = app.caller_app.iso_data;             % Isotherm data from caller app
            app.iso_model = app.caller_app.iso_model;           % Isotherm model chosen in caller app
            app.T_flag = app.caller_app.T_flag;
            
            % Isotherm settings matrices from caller app
            app.initial_guess = app.caller_app.initial_guess;   
            app.lower_bounds = app.caller_app.lower_bounds;
            app.upper_bounds = app.caller_app.upper_bounds;
            app.num_par_all_iso = app.caller_app.num_par_all_iso;
            
            % The isotherm model will be inherited from parent app
            isotherm_models = app.caller_app.IsothermModelDropDown.Items;
            idx_to_retain = ~strcmpi(isotherm_models, 'Auto');
            isotherm_models = isotherm_models(idx_to_retain);
            app.IsothermModelDropDown.Items = isotherm_models;
            
            % The settings for Virial equations will be inherited from
            % caller app only when T_flag is active
            if app.T_flag
            % Virial Equation specific variables
                app.vir_flag = app.caller_app.vir_flag;             
                app.vir_num_a = app.caller_app.vir_num_a;            
                app.vir_num_b = app.caller_app.vir_num_b;            
                app.vir_init_guess = app.caller_app.vir_init_guess;       
                app.vir_ub = app.caller_app.vir_ub;               
                app.vir_lb = app.caller_app.vir_lb;
            else
                app.vir_flag = 0;             
                app.vir_num_a = 0;            
                app.vir_num_b = 0;            
                app.vir_init_guess = 0;       
                app.vir_ub = 0;               
                app.vir_lb = 0;
            end
            
            % The isotherm model dropdown should be same as chosen isotherm
            % model in the caller app, except when the model chosen is auto
            if ~strcmpi(app.iso_model, 'Auto')
                app.IsothermModelDropDown.Value = app.iso_model;
            else
                app.iso_model = 'SS-Langmuir';
                app.IsothermModelDropDown.Value = 'SS-Langmuir';
            end
            
            % Virial equation related settings
            app.check_virial();

            % Updating the isotherm expression
            app.update_iso_expression();

            % Populating Isotherm Param Table
            app.update_iso_table();

            % By default no use of multistart option while fitting
            app.use_multi = 0;
            app.UseMultiStartCheckBox.Value = app.use_multi;
        end

        % Value changed function: IsothermModelDropDown
        function IsothermModelDropDownValueChanged(app, event)
            app.iso_model = app.IsothermModelDropDown.Value;
            
            if strcmpi(app.iso_model, 'Virial')
                app.vir_flag = 1;
            else
                app.vir_flag = 0;
            end
            
            % Virial equation related settings
            app.check_virial();

            % Updating the isotherm expression
            app.update_iso_expression();

            % Populating Isotherm Param Table
            app.update_iso_table();
        end

        % Cell edit callback: IsothermParamTable
        function IsothermParamTableCellEdit(app, event)
            indices = event.Indices;
            %% Data Validation
            if ~isempty(event.Error) || ~isnumeric(event.NewData) || isnan(event.NewData)
                % Retaining the old data 
                app.IsothermParamTable.Data(indices(1), indices(2)) = event.PreviousData;
            
            %% Update for all isotherm model except Virial equation
            elseif ~app.vir_flag
                % find the index of current model
                idx_to_update = find(strcmpi(app.IsothermModelDropDown.Items, app.iso_model));

                % find the number of params in curent model
                num_params = app.num_par_all_iso(idx_to_update);
                
                % Check for dH or theta entry
                dH_or_Theta = indices(1) - num_params;
                
                switch (dH_or_Theta)
                    case 1
                        indices(1) = size(app.initial_guess, 1)-1;
                        idx_to_update = 1;
                    case 2
                        indices(1) = size(app.initial_guess, 1);
                        idx_to_update = 1;
                end

                switch(indices(2))
                    case 1
                        % Update initial guess matrix
                        app.initial_guess(indices(1), idx_to_update) = event.NewData;
                    case 2
                        % Update lower bound matrix
                        app.lower_bounds(indices(1), idx_to_update) = event.NewData;
                    case 3
                        % Update upper bound matrix
                        app.upper_bounds(indices(1), idx_to_update) = event.NewData;
                end
            
            %% Update for Virial equation
            elseif app.vir_flag                
                switch(indices(2))
                    case 1
                        % Update initial guess matrix
                        app.vir_init_guess(indices(1)) = event.NewData;
                    case 2
                        % Update lower bound matrix
                        app.vir_lb(indices(1)) = event.NewData;
                    case 3
                        % Update upper bound matrix
                        app.vir_ub(indices(1)) = event.NewData;
                end                
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Pass the isotherm settings matrices back to caller app
            app.caller_app.initial_guess = app.initial_guess;
            app.caller_app.lower_bounds = app.lower_bounds;
            app.caller_app.upper_bounds = app.upper_bounds; 
            
            if app.T_flag
                % Pass the Virial settings to caller app
                app.caller_app.vir_flag = app.vir_flag;
                app.caller_app.vir_num_a = app.vir_num_a;
                app.caller_app.vir_num_b = app.vir_num_b;
                app.caller_app.vir_init_guess = app.vir_init_guess;
                app.caller_app.vir_lb = app.vir_lb;
                app.caller_app.vir_ub = app.vir_ub;
            end

            % Setting the isotherm model in the caller app based on chosen
            % isotherm model in the settings app 
            app.caller_app.IsothermModelDropDown.Value = app.IsothermModelDropDown.Value;
            
            % Passing the multistart option state to the caller app
            app.caller_app.use_multi = app.use_multi;
            
            % Finally calling the fit isotherm function from caller app
            % methods
            app.caller_app.run_fit_function();
        end

        % Button pushed function: RestoreDefaultsButton
        function RestoreDefaultsButtonPushed(app, event)
            % Calling the caller app method to get default values
            [init_guess, lb, ub] = app.caller_app.update_iso_setting();
            
            app.initial_guess = init_guess;
            app.upper_bounds = ub;
            app.lower_bounds = lb;
            
            % Calling the caller app method to get default values for
            % Virial equation
            if app.T_flag
                [num_a, num_b, init_guess, lb, ub] = app.caller_app.update_iso_setting();
                app.vir_num_a = num_a;
                app.vir_num_b = num_b;
                app.vir_init_guess = init_guess;
                app.vir_lb = lb;
                app.vir_ub = ub;
            end
            
            % Virial equation related settings
            app.check_virial();

            % Updating isotable
            app.update_iso_table();
            
            % Restoring the default state of multistart option
            app.use_multi = 0;
            app.UseMultiStartCheckBox.Value = app.use_multi;
        end

        % Value changed function: Num_a_params, Num_b_params
        function Num_a_paramsValueChanged(app, event)
            app.update_virial_params();
        end

        % Value changed function: UseMultiStartCheckBox
        function UseMultiStartCheckBoxValueChanged(app, event)
            value = app.UseMultiStartCheckBox.Value;
            if value
                app.use_multi = 1;
            else
                app.use_multi = 0;
            end
        end

        % Close request function: IsothermFittingSettingUIFigure
        function IsothermFittingSettingUIFigureCloseRequest(app, event)
            % Restoring the default state of multistart option in the
            % caller app
            app.caller_app.use_multi = 0;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.IsothermFittingSettingUIFigure = uifigure('Visible', 'off');
            app.IsothermFittingSettingUIFigure.Name = 'Isotherm Fitting Setting';
            app.IsothermFittingSettingUIFigure.Icon = fullfile(pathToMLAPP, 'AIM_logo.png');
            app.IsothermFittingSettingUIFigure.CloseRequestFcn = createCallbackFcn(app, @IsothermFittingSettingUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.IsothermFittingSettingUIFigure);
            app.IsothermModelPanel = uipanel(app.GridLayout);
            app.IsothermModelPanel.Title = 'Isotherm Model';
            app.IsothermModelDropDownLabel = uilabel(app.IsothermModelPanel);
            app.IsothermModelDropDownLabel.Text = 'Isotherm Model ';
            app.IsothermModelDropDown = uidropdown(app.IsothermModelPanel);
            app.IsothermModelDropDown.Items = {'SS-Langmuir', 'DS-Langmuir', 'SS-Langmuir-Freundlich', 'DS-Langmuir-Freundlich', 'Quadratic', 'Temkin', 'BET', 'Sips', 'Toth', 'Dubinin-Astakhov', 'Klotz', 'Do-Do'};
            app.IsothermModelDropDown.ValueChangedFcn = createCallbackFcn(app, @IsothermModelDropDownValueChanged, true);
            app.IsothermModelDropDown.Placeholder = 'Choose the Isotherm model';
            app.IsothermModelDropDown.Value = 'SS-Langmuir';
            app.ExpressionLabel = uilabel(app.IsothermModelPanel);
            app.ExpressionLabel.Interpreter = 'latex';
            app.ExpressionLabel.Text = 'Expression';
            app.OKButton = uibutton(app.IsothermModelPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.RestoreDefaultsButton = uibutton(app.IsothermModelPanel, 'push');
            app.RestoreDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreDefaultsButtonPushed, true);
            app.RestoreDefaultsButton.Text = 'Restore Defaults';
            app.heatExp = uilabel(app.IsothermModelPanel);
            app.heatExp.Interpreter = 'latex';
            app.heatExp.Text = 'Expression';
            app.Num_a_Label = uilabel(app.IsothermModelPanel);
            app.Num_a_Label.Interpreter = 'latex';
            app.Num_a_Label.Text = '\textit{m}';
            app.Num_a_params = uieditfield(app.IsothermModelPanel, 'numeric');
            app.Num_a_params.Limits = [0 20];
            app.Num_a_params.ValueChangedFcn = createCallbackFcn(app, @Num_a_paramsValueChanged, true);
            app.Num_b_Label = uilabel(app.IsothermModelPanel);
            app.Num_b_Label.Interpreter = 'latex';
            app.Num_b_Label.Text = '\textit{n}';
            app.Num_b_params = uieditfield(app.IsothermModelPanel, 'numeric');
            app.Num_b_params.Limits = [0 20];
            app.Num_b_params.ValueDisplayFormat = '%.0f';
            app.Num_b_params.ValueChangedFcn = createCallbackFcn(app, @Num_a_paramsValueChanged, true);
            app.UseMultiStartCheckBox = uicheckbox(app.IsothermModelPanel);
            app.UseMultiStartCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseMultiStartCheckBoxValueChanged, true);
            app.UseMultiStartCheckBox.Tooltip = {'"Check this to fit the isotherm using multiple initial guesses values"'};
            app.UseMultiStartCheckBox.Text = 'Use MultiStart';
            app.IsothermParamTable = uitable(app.GridLayout);
            app.IsothermParamTable.ColumnName = {'Initial Guess'; 'Lower Bound'; 'Upper Bound'};
            app.IsothermParamTable.RowName = {};
            app.IsothermParamTable.ColumnEditable = true;
            app.IsothermParamTable.CellEditCallback = createCallbackFcn(app, @IsothermParamTableCellEdit, true);
            % Show the figure after all components are created
            app.IsothermFittingSettingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = iso_fit_settings(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.IsothermFittingSettingUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.IsothermFittingSettingUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IsothermFittingSettingUIFigure)
        end
    end
end