classdef iso_info < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IsothermModelsUIFigure      matlab.ui.Figure
        IsothermModelParameterandUnitInformationPanel  matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        Panel                       matlab.ui.container.Panel
        SideLabel                   matlab.ui.control.Label
        HeatExp                     matlab.ui.control.Label
        ParamsTable                 matlab.ui.control.Table
        ExpLabel                    matlab.ui.control.Label
        IsothermModelDropDown       matlab.ui.control.DropDown
        IsothermModelDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Types and names of isotherm model available
        iso_models_avai = {'SS-Langmuir', 'DS-Langmuir',...
                           'SS-Langmuir-Freundlich', 'DS-Langmuir-Freundlich',...
                           'Quadratic', 'Temkin', 'BET', 'Sips', 'Toth'};
        iso_models_info;
        iso_model;

        heat_exp = '$$P_{norm} = P e^{\left(\frac{-\Delta H}{R}\left(\frac{1}{T}-\frac{1}{T_{ref}}\right)\right)}$$';
        % '$b(T) = b_{0}\left(e^{\frac{-\Delta U_{b}}{RT}}\right)^{n_{b}}$'
        %\ln K = -\frac{\Delta H^\circ}{R} \left(\frac{1}{T}\right) + \frac{\Delta S^\circ}{R};
    end
    
    methods (Access = private)
        function [] = update_table_exp(app)
            temp_row_names = {};

            % Getting the number of columns in the updated isotherm table
            current_iso_key = find(strcmpi(app.iso_models_avai, app.iso_model));
            
            current_iso_info = app.iso_models_info{current_iso_key}; 
            current_iso_num_par = current_iso_info.num_params;
   
            % Parameter row names
            for j = 1:current_iso_num_par
                temp_row_names(end+1) = {sprintf('Parameter %d', j)};
            end

            % temp_row_names = [temp_row_names, {'Delta H', 'Reference Temperature'}];
            
            % Final Column Names
            final_row_names = [temp_row_names, {'Delta H', 'T_ref'}];
            % table_length = numel(final_row_names);
            
            table_data = cell(current_iso_num_par+2, 2);
            table_data(:, 1) = [current_iso_info.name_params'; {'Delta H', 'T_ref'}'];
            table_data(:, 2) = [current_iso_info.unit_params'; {'(kJ/mol)', '(K)'}'];
            
            app.ParamsTable.Data = table_data;
            app.ParamsTable.RowName = final_row_names;

            % Replacing P with P_{norm} in isotherm expression
            iso_express_label = strrep(char(current_iso_info.express), 'P', 'P_{norm}');
            app.ExpLabel.Text = iso_express_label;
            app.HeatExp.Text = app.heat_exp;
        end
        %
        %% Function to update the uifigure for different devices
        function [] = adjust_uifig(app)
            % Get the device resolution
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            
            default_position = app.IsothermModelsUIFigure.Position;
            
            if default_position(3) > screenWidth || default_position(4) > screenHeight
                left = screenWidth*0.1;
                bottom = screenHeight*0.1;
                width = screenWidth*0.85;
                height = screenHeight*0.85;
                drawnow;
                app.IsothermModelsUIFigure.Position = [left bottom width height];
            end            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.adjust_uifig();
            
            % Setting the format of Paramsw Table
            app.ParamsTable.ColumnFormat = {[], []};
            app.ParamsTable.ColumnEditable = false;
            
            app.IsothermModelDropDown.Items = app.iso_models_avai;
            app.IsothermModelDropDown.Value = app.IsothermModelDropDown.Items(1);
            app.iso_model = app.IsothermModelDropDown.Items(1);

            % Initializing the dictionary to store struct containing default number of
            % parameters and name of params corresponding to isotherm model key
            keys = zeros(1, numel(app.iso_models_avai));
            values = repmat({struct}, 1, numel(app.iso_models_avai));           
            for k=1:numel(app.iso_models_avai)
                % Assigning isotherm index keys
                keys(k) = k;
                % Calling isotherm params parsing function for structure
                values(k) = {iso_params_parsing(char(app.iso_models_avai(k)), [])};
            end
            app.iso_models_info = dictionary(keys, values);

            app.update_table_exp();

            % app.SideLabel.Text = '$$*For\:isothermal\:calculations,\:BREAKLAB\:assumes\:\Delta H\:is\:0.$$.';
            app.SideLabel.Text = '*For isothermal calculations, BREAKLAB uses Delta H = 0.';
        %
        end

        % Value changed function: IsothermModelDropDown
        function IsothermModelDropDownValueChanged(app, event)
            value = app.IsothermModelDropDown.Value;
            app.iso_model = value;

            app.update_table_exp();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.IsothermModelsUIFigure = uifigure('Visible', 'off');
            app.IsothermModelsUIFigure.Name = 'Isotherm Models';
            app.IsothermModelsUIFigure.Icon = fullfile(pathToMLAPP, 'AIM_logo.png');
            app.IsothermModelParameterandUnitInformationPanel = uipanel(app.IsothermModelsUIFigure);
            app.IsothermModelParameterandUnitInformationPanel.Title = 'Isotherm Model Parameter and Unit Information';
            app.GridLayout = uigridlayout(app.IsothermModelParameterandUnitInformationPanel);
            app.Panel = uipanel(app.GridLayout);
            app.IsothermModelDropDownLabel = uilabel(app.Panel);
            app.IsothermModelDropDownLabel.Text = 'Isotherm Model';
            app.IsothermModelDropDown = uidropdown(app.Panel);
            app.IsothermModelDropDown.ValueChangedFcn = createCallbackFcn(app, @IsothermModelDropDownValueChanged, true);
            app.ExpLabel = uilabel(app.Panel);
            app.ExpLabel.Interpreter = 'latex';
            app.ParamsTable = uitable(app.Panel);
            app.ParamsTable.ColumnName = {'Parameter Label'; 'Units'};
            app.ParamsTable.RowName = {'Parameter 1'; 'Parameter 2'};
            app.HeatExp = uilabel(app.Panel);
            app.HeatExp.Interpreter = 'latex';
            app.SideLabel = uilabel(app.Panel);
            app.SideLabel.Text = 'SideLabel';
            % Show the figure after all components are created
            app.IsothermModelsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = iso_info

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.IsothermModelsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.IsothermModelsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IsothermModelsUIFigure)
        end
    end
end