classdef mixture_prediction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AIMMixPredUIFigure             matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        Panel_4                        matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        Panel_3                        matlab.ui.container.Panel
        yaxisCheckBox                  matlab.ui.control.CheckBox
        xaxisCheckBox                  matlab.ui.control.CheckBox
        LogarithmicaxisLabel           matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        MixtureUptakesTab              matlab.ui.container.Tab
        AbsoluteUptakeAxes             matlab.ui.control.UIAxes
        AdsorbedMoleFractionTab        matlab.ui.container.Tab
        AdsorbedMFAxes                 matlab.ui.control.UIAxes
        OutputPanel                    matlab.ui.container.Panel
        OutputText                     matlab.ui.control.TextArea
        InputPanel                     matlab.ui.container.Panel
        ExportDataButton               matlab.ui.control.Button
        PredictButton                  matlab.ui.control.Button
        PressurePaLabel                matlab.ui.control.Label
        PressureTable                  matlab.ui.control.Table
        ResetButton                    matlab.ui.control.Button
        MixturePredictionModeDropDown  matlab.ui.control.DropDown
        MixturePredictionModeDropDownLabel  matlab.ui.control.Label
        MFTable                        matlab.ui.control.Table
        IsothermsPanel                 matlab.ui.container.Panel
        SetWorkspaceButton             matlab.ui.control.Button
        ReadIsothermTable              matlab.ui.control.Table
        ImportIsothermButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        pressure            % Pressure vector
        molefrac            % Molefraction vector for components
        num_comp            % Number of components
        name_comp           % Names of components for table
        name_isomodel_read  % Names of model read for table
        
        % Types and names of isotherm model available
        iso_models_avai = {'SS-Langmuir', 'DS-Langmuir',...
                           'SS-Langmuir-Freundlich', 'DS-Langmuir-Freundlich',...
                           'Quadratic', 'Temkin', 'BET', 'Sips', 'Toth'};
        isotherm_params     % Main matrix containing isotherm parasm of loaded components
        % num max params is based on max number of params in available isotherm models model
        % + isotherm model key
        num_max_params = 9;
        mixture_mode        % Mixture prediction model (IAST or EDSL) 
        mixture_loadings    % Matrix to hold mixture loadings

        iso_import_flag     % Whether more than one isotherm data is loaded or not
        unit_pressure           % Pressure units in case they are specified in the data file
        unit_loading            % Loading units in case they are specified in the data file

        work_dir            % Loading units in case they are specified in the data file
    end
    
    methods (Access = private)
         %% Function for updating text box and data logging
        function [] = update_textbox_func(~, app_comp, message)
            if strcmpi(app_comp.Value, '')
                updated_message = message;
            else
                updated_message = [app_comp.Value(:)', {''}, {message}];
            end
            app_comp.Value = updated_message;
            scroll(app_comp, 'bottom');
        end
        %
        %% Function for starting or resetting the app
        function [] = startup_function(app)
            % Initializing the number, name, and mole fractions of components
            app.num_comp = 0;
            app.name_comp = {};
            app.name_isomodel_read = {};

            app.molefrac = [];
            app.mixture_loadings = [];

            % Default mixture mode is IAST
            app.MixturePredictionModeDropDown.Value = 'IAST';
            app.mixture_mode = 1;

            % Empty matrix to hold the isotherm params for all the loaded
            % components. This is passed to IAST function
            app.isotherm_params = [];

            % Setting the format of columns in Data tables
            app.ReadIsothermTable.ColumnFormat = {[] 'char'};
            app.MFTable.ColumnFormat = {[], 'numeric'};
            app.PressureTable.ColumnFormat = {'numeric', 'numeric', 'numeric', {'linear', 'log'}};

            % Setting the default values in Pressure Table
            app.PressureTable.ColumnEditable = true;
            app.PressureTable.Data = {1, 1000, 100, 'linear'};

            % Updating the ReadIsotherm and MF tables
            app.update_isotherm_table();
            app.update_MF_table();

            % Resetting the axes
            cla(app.AbsoluteUptakeAxes);
            cla(app.AdsorbedMFAxes);
            
            % Clearing the message panel
            app.OutputText.Value = '';

            % Initializong units
            app.unit_pressure = 'Pa';
            app.unit_loading = 'mol/kg';
        end
        %
        %% Function to import isotherms from .bliso files
        function [] = import_isotherm(app, data_filename)
            
            % Check the file extension
            extension_pat = ".bliso";

            if ~(endsWith(data_filename, extension_pat, "IgnoreCase",false))
                error('Please import valid file with *.bliso extension...')
            end

            % Read the file
            fileID = fopen(data_filename, 'r');
            
            model_read = 'None';
            params_lines = 0;
            params_read = {};
            match_exp = '(?<=\s+)\-?\d*\.?\d+([eE][+\-]?\d+)?';

            while ~feof(fileID)
                current_line = fgetl(fileID);
                
                % Ignoring lines starting with * 
                if ~isempty(regexp(current_line, '^\*', "match")) || isempty(current_line)
                    % skip the empty or lines starting with *
                    continue
                else                  
                    % Search for isotherm model
                    if contains(current_line, 'Isotherm Model')
                        model_read = strip(extractAfter(current_line, ':'));
                        continue
                    end
                    
                    % Search for isotherm parameters
                    if contains(current_line, 'Parameters')
                        % Setting the params line flag on and passing control
                        % to next line
                        params_lines = 1;
                        continue
                    end
                    
                    % Finding numerical values in the line
                    if params_lines
                        % Only first match is recorded and standard errors
                        % associated with parameters values are ignored.
                        current_param = regexp(current_line, match_exp, "match", "once"); 
                        if ~isempty(current_param)
                            params_read(end+1) = {current_param};
                        end
                    end
                end
            end
            
            % Closing the file
            fclose(fileID);
            
            % Checking whether the read isotherm model is valid or not.
            if strcmpi(model_read, 'None') || isempty(model_read)
                error(sprintf('No isotherm model found in the given file. Choose valid input file'));
            elseif isempty(params_read)
                error(sprintf('No isotherm model parameters found in the given file. Choose valid input file'));
            end
            
            % Checking whether the read isotherm model is valid or not. 
            model_key = find(strcmpi(app.iso_models_avai, model_read));
            if isempty(model_key)
                error(sprintf('Invalid isotherm model. Please import isotherm parameters with valid isotherm models...'));
            end

            % Converting params read to double type and transporsing to get
            % column vector
            try
                params_read = double(string((params_read)))';
            catch ME1
                error(sprintf('Non-numeric parameters values found in the data file'));
            end

            % Calling pars function to get params name check params read
            % array for consistency
            pars_params = iso_params_parsing(model_read, []);
            num_params = pars_params.num_params;
            
            if (num_params > size(params_read, 1))
                error(sprintf(['Some of the parameter values for the isotherm model read, ' ...
                                'are not found in the file.\n']));
            
            elseif (num_params < size(params_read, 1))
                params_read = params_read(1:num_params);
                % error("Mismatch in number of parameters read...")
            end
            
            % Temporary matrix to hold read isotherm params
            temp_params = zeros(app.num_max_params, 1);
            
            % Appending the isotherm model key to main isotherm params
            % matrix
            temp_params(1) = model_key;

            % Appending the read isotherm params to main isotherm params
            % matrix
            temp_params(2:length(params_read)+1) = params_read;
            app.isotherm_params = horzcat(app.isotherm_params, temp_params);
        
            % Counting number of components
            app.num_comp =  app.num_comp + 1;

            % Naming the component and appending it to name of components
            % By default the name of the component is same as filename for
            % identification. In case of error, the name will be
            % initialized as Component No., where No. refers to given
            % component's index
            try 
                [~, name, ~] = fileparts(data_filename);
                app.name_comp(end+1) =  {sprintf('%s', name)};
            catch
                app.name_comp(end+1) =  {sprintf('Component %d', app.num_comp)};
            end
            

            % Appending the molefraction vector and making initializing equimolar mixture 
            app.molefrac = horzcat(app.molefrac, 0);
            app.molefrac(:) = 1/length(app.molefrac);

            % Saving the name of isotherms model for table
            app.name_isomodel_read(end+1) = {model_read};
        end
        %
        %% Function to update Isotherm Params table
        function [] = update_isotherm_table(app)
            
            table_data = cell(app.num_comp, 2);
            table_data(:, 1) = app.name_comp';
            table_data(:, 2) = app.name_isomodel_read';
            
            app.ReadIsothermTable.Data = table_data;
        end
        %
        %% Function to update MF table
        function [] = update_MF_table(app)
            table_data = cell(app.num_comp, 2);
            table_data(:, 1) = app.name_comp';
            table_data(:, 2) = num2cell(app.molefrac');         % Conversion from double to cell
            app.MFTable.Data = table_data;
        end
        %
        %% Funtion to read isotherm params file
        function [] = prepare_isotherm_data(app, path, file)
            % Getfilename from path and user-selected file
            filename = fullfile(path, file);

            % Importing Isotherm Params
            app.import_isotherm(filename);
            
            % Updating the Read Isotherm Table
            app.update_isotherm_table();

            % Updating the MF Table
            app.update_MF_table();
            
            % Log informartion
            app.update_textbox_func(app.OutputText, sprintf('Parameters imported successfully from %s', file));
        
        end
        %
        %% Function to import isotherm params data
        function [] = import_iso_data(app)
            filter = {'*.bliso', 'AIM Isotherm Parameters Files'};
            % filter = {'*.csv'; '*.txt'; '*.xlsx'; '.dat'};
            default_file_name = fullfile(app.work_dir, 'my_iso_params.bliso');
            [file, path, indx] = uigetfile(filter, 'Select file containing isotherm parameters', ...
                                            default_file_name, 'MultiSelect','on');
            
            % Command for refocusing parent app
            figure(app.AIMMixPredUIFigure);

            try
                if indx
                    if iscell(file)                    
                        % If user selects multiple files, loop all
                        for i=1:numel(file)
                            current_file = char(file(i));
                            app.prepare_isotherm_data(path, current_file)
                        end
                    else
                       % If user selects single file
                       current_file = file;
                       app.prepare_isotherm_data(path, current_file);
                    end 
                end
            catch ME1
                app.update_textbox_func(app.OutputText, ...
                                        sprintf('Error occured while reading %s...!\nError message: %s',...
                                                    current_file, ME1.message));
            end
        end
        %% Function to noramlize molefraction
        function [] = normalize_molefraction(app)
            mf_sum = sum(app.molefrac, 2);

            if ~(mf_sum == 1.00)
                app.update_textbox_func(app.OutputText, sprintf('Normalizing molefraction...'));
                % Normalizing MF
                app.molefrac = app.molefrac ./ mf_sum;
                % Updating MF table
                app.update_MF_table();
            end
        end
        %
        %% Function for plotting mixture isotherm data
        function [] = update_plot_func(app, plot_type)
            % plot_type = 0 for pressure vs mixture loadings plot
            % plot_type = 1 for presure vs adsorbed mole fraction plot
            
            if ~plot_type
                mixture_plots(app.AbsoluteUptakeAxes, app.num_comp, app.name_comp, app.pressure, app.mixture_loadings, ...
                                app.xaxisCheckBox.Value, app.yaxisCheckBox.Value, plot_type, app.unit_pressure, app.unit_loading);
            else
                mixture_plots(app.AdsorbedMFAxes, app.num_comp, app.name_comp, app.pressure, app.mixture_loadings, ...
                                app.xaxisCheckBox.Value, app.yaxisCheckBox.Value, plot_type, app.unit_pressure, app.unit_loading);
            end
        end
        %
        %% Function to print/write the mixture loading
        function [] = print_params(app, fileID)
            
            intro_string = [sprintf('%s\n', repmat('*', 1, 74)),...
                            sprintf('%sMixPred MIXTURE UPTAKES%s\n', repmat('*', 1, 20), repmat('*', 1, 20)),...
                            sprintf('%s\n', repmat('*', 1, 74))];
            
            % comp_name_string = [];
            % iso_model_string = [];

            % label_string = [sprintf('Pressure\t')];
            % for k=1:app.num_comp
            %     % comp_name_string(end+1) = sprintf('Component %d: %s\n', k, app.name_comp(k));
            %     % iso_model_string(end+1) = sprintf('Isotherm Model: %s\n', k,  app.name_comp);            
            %     label_string(end+1) = sprintf('%s\t', app.name_comp(k));
            % end
            
            format_spec = ['Pressure (Pa)\t\t', repmat('%s (mol/kg)\t\t', 1, app.num_comp-1), '%s (mol/kg)\n'];
            label_string = sprintf(format_spec, string(app.name_comp));
            % stats_string = sprintf('Pressure\t%s\t%s\n', RMSE, r2); 
            % 
            % % Calling pars function to get params name
            % pars_params = iso_params_parsing(app.iso_model, fitted_params);
            % name_params = pars_params.name_params;
            % 
            % params_string = [sprintf('Parameters\n')];
            % for i=1:length(fitted_params)
            %     if fitted_params(i)<0.1         % For scientific notation
            %         params_string = [params_string, sprintf('%s\t\t%e\n', char(name_params(i)), fitted_params(i))];
            %     else
            %         params_string = [params_string, sprintf('%s\t\t%f\n', char(name_params(i)), fitted_params(i))];
            %     end
            % end
            % ['Pressure\t\t', repmat('%s\t\t', 1, app.num_comp-1), '%s\n'];
            % format_spec = ['%.2f\t\t', repmat('%.6f\t\t%.6f\n', 1, size(app.pressure, 1))];

            format_spec = ['%.3e\t\t', repmat('%.6e\t\t', 1, app.num_comp-1), '%.6e\n'];
            format_spec = [repmat(format_spec, 1, size(app.pressure, 1))];
            array_to_print = horzcat(app.pressure, app.mixture_loadings);
            mixture_loading_string = sprintf(format_spec, array_to_print');

            finish_string = sprintf('%s\n', repmat('*', 1, 74));
            
            if nargin<2
                % Logging the starting string
                app.update_textbox_func(app.OutputText, intro_string);
                
                % Logging the label string
                app.update_textbox_func(app.OutputText, ...
                    label_string);
                
                % Logging the mixture loadings      
                app.update_textbox_func(app.OutputText, mixture_loading_string);
                
                
                % Logging the finishing string
                app.update_textbox_func(app.OutputText, finish_string);
            
            else
                % Logging the starting string
                fprintf(fileID, '%s\n',intro_string);       
                
                % Logging the data labels
                fprintf(fileID, '%s\n', label_string);
                
                % Logging the mixture loadings
                fprintf(fileID, '%s\n', mixture_loading_string);
                    
                % Logging the finishing string
                fprintf(fileID, '%s\n', finish_string);           
            end
        end
        %
        %% Function to generate Pressure array from given range
        function [] = get_pressures(app)
            % Unpack the values
            values = double(string(app.PressureTable.Data(1, 1:3)));
            scale = string(app.PressureTable.Data(1, 4));

            start_val = values(1);
            end_val   = values(2);
            n         = ceil(values(3)); 
            
            % Check the difference
            diff = end_val - start_val;
            if diff<0
                error("Error in pressure array generation: End value should be greater than start value");
            end
            
            % Linear scale
            if strcmp(scale, "linear")
                if ~(start_val>=1) || ~(end_val>1)
                    error("Error in pressure array generation: In the case of linear scale, start and end values should be positive and greater than 1.")
                end

                % Linear pressure array initialization
                app.pressure = linspace(start_val, end_val, n)';
            end
            
            % Log scale
            if strcmp(scale, "log")
                if end_val > 10
                    error("The specified End value for pressure is too high ....")
                end
                % Log pressure array initialization
                app.pressure = logspace(start_val, end_val, n)';
            end
        end
        %
         %% Progress Bar Function
        function dialogue = progress(app)
            dialogue = uiprogressdlg(app.AIMMixPredUIFigure, "Title", "Please Wait",...
                  "Message","Calculating mixture uptakes",...
                  "Cancelable","off", "Indeterminate","on");
                drawnow   
        end
        %
        %% Function to get mixture prediction
        function [] = get_mixture_loadings(app)
            try
                % Initializing progress bar
                progress_bar = app.progress();

                if app.num_comp >=2
                    app.update_textbox_func(app.OutputText, sprintf('Starting mixture prediction calculation'));
                    app.normalize_molefraction()
                    app.get_pressures();
                    
                    % Dummy temperature change later on
                    T = 298;
                    tic;
                    if app.mixture_mode
                        % IAST mixture loadings
                        
                        % app.mixture_loadings = IAST_func_mix_pred_app(app.num_comp, app.isotherm_params, app.pressure, app.molefrac, T);
                        app.mixture_loadings = IAST_func_mix_pred_app_NR(app.num_comp, app.isotherm_params, app.pressure, app.molefrac, T);
                    else
                        % EDSL mixture loadings
                        % Checking that either the model is SS or DS
                        % Langmuir
                        if all(app.isotherm_params(1, :) <= 2)
                            app.mixture_loadings  = EDSL_func_mix_pred_app(app.num_comp, app.isotherm_params, app.pressure, app.molefrac, T);
                        else
                            error("For Extended model, isotherm models for all the components should be either SS-Lagnmuir or DS-Langmuir.")
                        end
                    end
                    timeTaken = toc;
                    
                    progress_bar.Message = "Finishing calculation...";

                    % Update Plots
                    app.update_plot_func(0);
                    app.update_plot_func(1);

                    % Logging the data
                    app.print_params();
                else
                    error("Please import at least two isotherms...")
                end
            catch ME1
                    timeTaken = toc;
                    progress_bar.Message = "Error occured. Check the Message Panel for detailed information.";
                    pause(2);
                    close(progress_bar);
                    app.update_textbox_func(app.OutputText, sprintf('%s', ME1.message));
            end

            if ~isempty(progress_bar)
                 close(progress_bar);
            end

            % Logging time taken on message panel
            app.update_textbox_func(app.OutputText, sprintf('Total elapsed time: %.4f seconds', timeTaken));
        end
        %
        %% Function to adjust the size of figure
        function [] = adjust_size(app)
            app.AIMMixPredUIFigure.Units = "normalized";
            app.AIMMixPredUIFigure.Position(1) = 0.1;
            app.AIMMixPredUIFigure.Position(2) = 0.05;
            app.AIMMixPredUIFigure.Units = "pixels";
        end
        %
        %% Function to set the working directory
        function [] = set_work_dir(app)
            new_path = uigetdir(app.work_dir, 'Select working directory');
            
            % Command for refocusing parent app
            figure(app.AIMMixPredUIFigure);

            if new_path
                app.work_dir = new_path;
            end
        end
        %
        %% Function to update the uifigure for different devices
        function [] = adjust_uifig(app)
            % Get the device resolution
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            
            default_position = app.AIMMixPredUIFigure.Position;
            
            if default_position(3) > screenWidth || default_position(4) > screenHeight
                left = screenWidth*0.1;
                bottom = screenHeight*0.1;
                width = screenWidth*0.85;
                height = screenHeight*0.85;
                drawnow;
                app.AIMMixPredUIFigure.Position = [left bottom width height];
            end            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.adjust_size();
            app.adjust_uifig();
            app.work_dir = pwd;
            app.startup_function();

            % Printing welcome message
            intro_string = get_intro_string("MixPred");
            app.update_textbox_func(app.OutputText, intro_string);
        end

        % Button pushed function: ImportIsothermButton
        function ImportIsothermButtonPushed(app, event)
            app.import_iso_data()
        end

        % Cell edit callback: ReadIsothermTable
        function ReadIsothermTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            if isempty(event.Error) && ~isempty(newData)
                % Updating component names
                app.name_comp(1, indices(1)) = {newData};
                
                % Updating the MF table based on new component names
                app.update_MF_table();
            else
                app.update_textbox_func(app.OutputText, sprintf('Enter valid Component names.'));
                app.ReadIsothermTable.Data(indices(1), indices(2)) = {event.PreviousData};
            end
        end

        % Cell edit callback: MFTable
        function MFTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            retain_previous = 0;

            if isempty(event.Error)
                switch (indices(2))
                    case 1
                        if ~isempty(newData)
                            app.name_comp(1, indices(1)) = {newData};
                            
                            % Updating Isotherm params table
                            app.update_isotherm_table();                        
                        else
                            retain_previous = 1;
                        end

                    case 2
                        if ~isnan(newData)
                            app.molefrac(1, indices(1)) = newData;  
                        else
                            retain_previous = 1;
                        end                   
                end
            else
                retain_previous = 1;
            end

            if retain_previous
                app.MFTable.Data(indices(1), indices(2)) = {event.PreviousData};
            end
        end

        % Cell edit callback: PressureTable
        function PressureTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            retain_old = 0;

            % In case the data is not numeric then reverting back to
            % previous value
            if isnan(newData)
                retain_old = 1;

            elseif indices(2) == 3
                if newData <= 0
                    retain_old = 1;
                else
                    % Rounding off the # Points in case its float
                    app.PressureTable.Data(indices(1), indices(2)) = {ceil(newData)}; 
                end
            end

            if retain_old
                app.PressureTable.Data(indices(1), indices(2)) = {event.PreviousData};
            end
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            app.get_mixture_loadings()
        end

        % Close request function: AIMMixPredUIFigure
        function AIMMixPredUIFigureCloseRequest(app, event)
            YN = uiconfirm(app.AIMMixPredUIFigure,'Do you want to close the app?', 'Close request', 'Icon','warning');
            if strcmpi(YN,'OK')
                delete(app)
            end
        end

        % Value changed function: MixturePredictionModeDropDown
        function MixturePredictionModeDropDownValueChanged(app, event)
            value = app.MixturePredictionModeDropDown.Value;          
            if strcmpi(value, 'IAST')
                app.mixture_mode = 1;
            else
                app.mixture_mode = 0;
            end
        end

        % Value changed function: xaxisCheckBox
        function xaxisCheckBoxValueChanged(app, event)
            if ~isempty(app.mixture_loadings)
                app.update_plot_func(0);
                app.update_plot_func(1);
            end
        end

        % Value changed function: yaxisCheckBox
        function yaxisCheckBoxValueChanged(app, event)
            if ~isempty(app.mixture_loadings)
                app.update_plot_func(0);
                app.update_plot_func(1);
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.update_textbox_func(app.OutputText, sprintf('Resetting the application...'));

            app.startup_function();
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            % Check if the fitting has been completed successfully
            if ~isempty(app.mixture_loadings)
                % filter = {'*.blmix'};
                filter = {'*.blmix', 'BREAKLAB Mixture Files'};
                default_file_name = fullfile(app.work_dir, 'my_mixture_data.blmix');
                
                [file, path, indx] = uiputfile(filter, 'Select file for save as', ...
                    default_file_name);
                
                % Command for refocusing parent app
                figure(app.AIMMixPredUIFigure);

                if indx
                    filename = fullfile(path, file);
                    
                    fileID = fopen(filename, "w");

                    app.print_params(fileID);
                    fclose(fileID);
                end
            end
        end

        % Button pushed function: SetWorkspaceButton
        function SetWorkspaceButtonPushed(app, event)
            app.set_work_dir();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AIMMixPredUIFigure = uifigure('Visible', 'off');
            app.AIMMixPredUIFigure.Name = 'AIM MixPred';
            app.AIMMixPredUIFigure.Icon = fullfile(pathToMLAPP, 'AIM_logo.png');
            app.AIMMixPredUIFigure.CloseRequestFcn = createCallbackFcn(app, @AIMMixPredUIFigureCloseRequest, true);
            app.Panel = uipanel(app.AIMMixPredUIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.IsothermsPanel = uipanel(app.GridLayout);
            app.IsothermsPanel.Title = 'Isotherms';
            app.ImportIsothermButton = uibutton(app.IsothermsPanel, 'push');
            app.ImportIsothermButton.ButtonPushedFcn = createCallbackFcn(app, @ImportIsothermButtonPushed, true);
            app.ImportIsothermButton.Text = 'Import Isotherm';
            app.ReadIsothermTable = uitable(app.IsothermsPanel);
            app.ReadIsothermTable.ColumnName = {'Component'; 'Isotherm Model'};
            app.ReadIsothermTable.RowName = {};
            app.ReadIsothermTable.ColumnEditable = [true false];
            app.ReadIsothermTable.CellEditCallback = createCallbackFcn(app, @ReadIsothermTableCellEdit, true);
            app.SetWorkspaceButton = uibutton(app.IsothermsPanel, 'push');
            app.SetWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SetWorkspaceButtonPushed, true);
            app.SetWorkspaceButton.Text = 'Set Workspace';
            app.InputPanel = uipanel(app.GridLayout);
            app.InputPanel.Title = 'Input';
            app.MFTable = uitable(app.InputPanel);
            app.MFTable.ColumnName = {'Components'; 'Mole Fraction'};
            app.MFTable.RowName = {};
            app.MFTable.ColumnEditable = [true true];
            app.MFTable.CellEditCallback = createCallbackFcn(app, @MFTableCellEdit, true);
            app.MixturePredictionModeDropDownLabel = uilabel(app.InputPanel);
            app.MixturePredictionModeDropDownLabel.Text = 'Mixture Prediction Mode';
            app.MixturePredictionModeDropDown = uidropdown(app.InputPanel);
            app.MixturePredictionModeDropDown.Items = {'IAST', 'Extended Langmuir Model'};
            app.MixturePredictionModeDropDown.ValueChangedFcn = createCallbackFcn(app, @MixturePredictionModeDropDownValueChanged, true);
            app.MixturePredictionModeDropDown.Placeholder = 'Choose the Isotherm model';
            app.MixturePredictionModeDropDown.Value = 'IAST';
            app.ResetButton = uibutton(app.InputPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PressureTable = uitable(app.InputPanel);
            app.PressureTable.ColumnName = {'Start'; 'End'; '# Points'; 'Scale'};
            app.PressureTable.RowName = {};
            app.PressureTable.CellEditCallback = createCallbackFcn(app, @PressureTableCellEdit, true);
            app.PressurePaLabel = uilabel(app.InputPanel);
            app.PressurePaLabel.Text = 'Pressure (Pa)';
            app.PredictButton = uibutton(app.InputPanel, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.ExportDataButton = uibutton(app.InputPanel, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.OutputPanel = uipanel(app.GridLayout);
            app.OutputPanel.Title = 'Output';
            app.OutputText = uitextarea(app.OutputPanel);
            app.OutputText.Editable = 'off';
            app.Panel_4 = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel_4);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.MixtureUptakesTab = uitab(app.TabGroup);
            app.MixtureUptakesTab.Title = 'Mixture Uptakes';
            app.AbsoluteUptakeAxes = uiaxes(app.MixtureUptakesTab);
            xlabel(app.AbsoluteUptakeAxes, 'Pressure')
            ylabel(app.AbsoluteUptakeAxes, 'Gas Uptake')
            zlabel(app.AbsoluteUptakeAxes, 'Z')
            app.AdsorbedMoleFractionTab = uitab(app.TabGroup);
            app.AdsorbedMoleFractionTab.Title = 'Adsorbed Mole Fraction';
            app.AdsorbedMFAxes = uiaxes(app.AdsorbedMoleFractionTab);
            xlabel(app.AdsorbedMFAxes, 'Pressure')
            ylabel(app.AdsorbedMFAxes, 'Gas Uptake')
            zlabel(app.AdsorbedMFAxes, 'Z')
            app.Panel_3 = uipanel(app.GridLayout2);
            app.LogarithmicaxisLabel = uilabel(app.Panel_3);
            app.LogarithmicaxisLabel.Text = 'Logarithmic axis';
            app.xaxisCheckBox = uicheckbox(app.Panel_3);
            app.xaxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @xaxisCheckBoxValueChanged, true);
            app.xaxisCheckBox.Text = 'x axis';
            app.yaxisCheckBox = uicheckbox(app.Panel_3);
            app.yaxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @yaxisCheckBoxValueChanged, true);
            app.yaxisCheckBox.Text = 'y axis';
            % Show the figure after all components are created
            app.AIMMixPredUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mixture_prediction

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AIMMixPredUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.AIMMixPredUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AIMMixPredUIFigure)
        end
    end
end